classdef Add_Component < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AddElementLabel        matlab.ui.control.Label
        ImportElementButton    matlab.ui.control.Button
        AddemptyelementButton  matlab.ui.control.Button
    end

    
    % Cette application est exécutée lorsque le sous-élement en bas à gauche de l'interface est cliqué (voir Main_App.mlapp/subelement_selected)

    properties (Access = private)
        MainApp % L'application principale
        ClassElementObject % L'objet de classe "classelement"
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.MainApp = main_app;
        end

        % Button pushed function: ImportElementButton
        function ImportElementButtonPushed(app, event)
            
            % Ce Callback est exécuté lorsque l'utilisateur souhaite importer un élément sous la forme d'une structure
            % afin qu'elle puisse être stockée dans app.MainApp.ElementsDatas

            % On récupère la structure depuis le gestionnaire de fichier
            [file, path] = uigetfile('*.mat');
            selectedfile = fullfile(path, file);
            classelementobject = importdata(selectedfile);

            % On récupère et on incrémente le nombre d'élements compris dans la structure app.MainApp.ElementsDatas
            number_of_elements = length(fieldnames(app.MainApp.ElementsDatas)) + 1;
            app.MainApp.ElementsDatas("Element_" + number_of_elements).ClassElementObject = classelementobject;

            % On ajoute les sous-élements constituant l'élement à la structure app.MainApp.SubelementsDatas

            % On récupère le nombre de sous-élements contenus dans la structure de l'application et dans l'élement importé
            number_of_subelements_structure = length(fieldnames(app.MainApp.SubelementsDatas));
            number_of_subelements_element = length(classelementobject.ListOfSubelements);

            % On ajoute un par un les sous-élements à la structure de l'application principale
            for i =  1 : number_of_subelements_element
                app.MainApp.SubelementsDatas.("Subelement_" + (number_of_subelements_structure + i)) = classelementobject.ListOfSubelements{i};
            end
            
            % On définie la surface d'entrée du nouvel élement
            InputSurface(app.MainApp);

            % On ferme la fenêtre
            delete(app);


        end

        % Button pushed function: AddemptyelementButton
        function AddemptyelementButtonPushed(app, event)
            
            % Ce Callback est exécuté lorsque l'utilisateur souhaite ajouter un nouvel élement vierge

            % On appelle la fonction externe add_empty_element
            add_empty_element(app.MainApp) % app.ElementsDatas.élément_ajouté.ClassElementObject.ListOfSubelements = {}
                                           %                                                    .EndStatus = 'closed'
                                           %                                 .SubelementsList = {}
                                              
            % On ferme la fenêtre secondaire
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddemptyelementButton = uibutton(app.UIFigure, 'push');
            app.AddemptyelementButton.ButtonPushedFcn = createCallbackFcn(app, @AddemptyelementButtonPushed, true);
            app.AddemptyelementButton.Text = 'Add empty element';
            app.ImportElementButton = uibutton(app.UIFigure, 'push');
            app.ImportElementButton.ButtonPushedFcn = createCallbackFcn(app, @ImportElementButtonPushed, true);
            app.ImportElementButton.Text = 'Import Element';
            app.AddElementLabel = uilabel(app.UIFigure);
            app.AddElementLabel.Text = 'Add Element';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Add_Component(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end