classdef Algorythm_Parameters_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AlgorithmSpecificationTable  matlab.ui.control.Table
        CommonParametersTable        matlab.ui.control.Table
    end

    
    properties (Access = private)
        OptimisationApp
    end
    
    methods (Access = private)
        
        function update_algorithm_table(app)
            % Récupérer l'algorithme sélectionné dans le menu
            selectedAlgo = app.AlgorithmDropDown.Value;
        
            % Définir les options en fonction du type d'algorithme
            switch selectedAlgo
                case 'Genetic Algorithm (ga)'
                    algoData = {
                        'Crossover Fraction', '0.8';
                        'Mutation Function', 'mutationadaptfeasible';
                        'Elite Count', '2';
                        'Function Tolerance', '1e-4'
                    };
        
                case 'Multi-objective GA (gamultiobj)'
                    algoData = {
                        'Pareto Fraction', '0.35';
                        'Distance Measure Fcn', 'distancecrowding';
                        'Plot Function', 'gaplotpareto';
                        'Display', 'iter'
                    };
        
                case 'Constrained fmincon'
                    algoData = {
                        'Algorithm', 'sqp';
                        'Step Tolerance', '1e-6';
                        'Max Function Evaluations', '500';
                        'Constraint Tolerance', '1e-6'
                    };
        
                case 'Pattern Search'
                    algoData = {
                        'Mesh Tolerance', '1e-3';
                        'Poll Method', 'GPSPositiveBasis2N';
                        'Use Parallel', 'false'
                    };
        
                otherwise
                    algoData = {};
            end
        
            % Mettre à jour l’affichage du tableau
            app.AlgorithmSpecificationTable.Data = algoData;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, optimisation_app)
            app.OptimisationApp = optimisation_app;

            % Affichage centré sur l'écran
            disp_centered_app(app);

            % Initialiser les paramètres communs (valeurs par défaut)
            app.CommonParametersTable.ColumnName = {'Parameter', 'Value'};
            app.CommonParametersTable.ColumnFormat = {'char', 'char'};
            app.CommonParametersTable.Data = {
                'Population Size', '50';
                'Max Generations', '100';
                'Display', 'iter'
            };
        
            % Initialisation du tableau des paramètres spécifiques à l’algorithme
            app.AlgorithmSpecificationTable.ColumnName = {'Option', 'Value'};
            app.AlgorithmSpecificationTable.ColumnFormat = {'char', 'char'};
            app.AlgorithmSpecificationTable.Data = {}; % Commence vide
        
            % Lier le callback au menu déroulant des algorithmes
            app.AlgorithmDropDown.ValueChangedFcn = @(src, event) updateAlgorithmTable(app);
        
            % Initialiser l'affichage selon l'algorithme par défaut
            update_algorithm_table(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CommonParametersTable = uitable(app.UIFigure);
            app.CommonParametersTable.ColumnName = '';
            app.CommonParametersTable.RowName = {};
            app.AlgorithmSpecificationTable = uitable(app.UIFigure);
            app.AlgorithmSpecificationTable.ColumnName = '';
            app.AlgorithmSpecificationTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Algorythm_Parameters_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end