classdef Main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        ClearMenu                  matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        ModifyenvironnementparametersMenu  matlab.ui.container.Menu
        fonctiontemporaireinit_typesMenu  matlab.ui.container.Menu
        ConfigurationMenu          matlab.ui.container.Menu
        NewElementMenu             matlab.ui.container.Menu
        ImportElementMenu          matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        ParametersPanel            matlab.ui.container.Panel
        ParametersGrid             matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        ButtonsPanel               matlab.ui.container.GridLayout
        ComputeButton              matlab.ui.control.Button
        ParametricStudyButton      matlab.ui.control.Button
        OptimiseButton             matlab.ui.control.Button
        ParametersViewPanel        matlab.ui.container.Panel
        TypeDropDown               matlab.ui.control.DropDown
        TypeDropDownLabel          matlab.ui.control.Label
        ParametersSubPanel         matlab.ui.container.Panel
        UpdateConfigurationButton  matlab.ui.control.Button
        ViewGrid                   matlab.ui.container.GridLayout
        VariablesGrid              matlab.ui.container.GridLayout
        VariablesButtonsGrid       matlab.ui.container.GridLayout
        deletevariableButton       matlab.ui.control.Button
        addvariableButton          matlab.ui.control.Button
        HandleVariablesTable       matlab.ui.control.Table
        GraphPanel                 matlab.ui.container.Panel
        GraphGrid                  matlab.ui.container.GridLayout
        GridView                   matlab.ui.container.GridLayout
        Navigator                  matlab.ui.control.UIAxes
        ElementsGraph              matlab.ui.control.UIAxes
        SubelementsGraph           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        EnvApp
        ParametricStudyApp
        Graph 
        ParametersView
        Elements 
        Types
        HandleVariables
    end

    events
        ElementsUpdated
    end
    
    
    methods (Access = public)
     
        function init_types(app)
        
            % Voir app.SubelementsTypes

            % On sélectionne le menu déroulant associé au type de
            % sous-élements
            dropdown = app.TypeDropDown;

            %% None

            if ~isfield(app.Types, 'None')
                app.Types.None.TypeName = 'None';
                app.Types.None.Color = 'k';
                app.Types.None.Marker = '+';

                dropdown.Items{end + 1} = char();
                dropdown.ItemsData{end + 1} = 'None';
            end

            %% Undefined

            if ~isfield(app.Types, 'Undefined')
                app.Types.Undefined.TypeName = 'Undefined';
                app.Types.Undefined.Color = 'k';
                app.Types.Undefined.Marker = 'diamond';
                app.Types.Undefined.ParametersPanelStruct = ...
                add_parameters_subpanel_structure(app, 'Undefined', {}, {});

                app.Types.Undefined.HandleAppObject = @(app) AppObject(app);

                dropdown.Items{end + 1} = 'Undefined (Choose a type)';
                dropdown.ItemsData{end + 1} = 'Undefined';
            end

            %% Imported Element

            % if ~isfield(app.Types, 'classelement_imported')
                app.Types.classelement_imported.TypeName = 'classelement_imported';
                app.Types.classelement_imported.Color = 'k';
                app.Types.classelement_imported.Marker = 'diamond';
                app.Types.classelement_imported.ParametersPanelStruct = ...
                add_parameters_subpanel_structure(app, 'classelement_imported', {}, {});

                app.Types.classelement_imported.HandleAppObject = @(app) AppObject(app);

                app.Types.classelement_imported.HandleAppConfig = @(class_config_double) ...
                feval(@(class_config_str) struct('FrequencySupport', class_config_str.FrequencySupport, ...
                                                  'SurfaceImpedance', class_config_str.SurfaceImpedance, ...
                                                  'Surface', class_config_str.Surface), ...
                      perso_struct_num2str(class_config_double)); 

                app.Types.classelement_imported.HandleClassObject = @(class_config_double) ...
                classelement_imported(classelement_imported.create_config(class_config_double.FrequencySupport, ...
                                                                          class_config_double.SurfaceImpedance, ...
                                                                          class_config_double.Surface));

                app.Types.classelement_imported.HandleClassConfig = @(app_config_str) ...
                feval(@(app_config_double) struct('FrequencySupport', app_config_double.FrequencySupport, ...
                                                  'SurfaceImpedance', app_config_double.SurfaceImpedance, ...
                                                  'Surface', app_config_double.Surface), ...
                perso_struct_str2double(app_config_str)); 

                dropdown.Items{end + 1} = 'Subelement with Imported Data';
                dropdown.ItemsData{end + 1} = 'classelement_imported';
            % end

            %% Element

            % if~isfield(app.Types, 'classelement')
                app.Types.classelement.ParametersPanelStruct = add_parameters_subpanel_structure ...
                (app, 'classelement', {'Surface'}, {'m^2'});

                app.Types.classelement.TypeName = 'classelement';
                app.Types.classelement.Color = 'k';
                app.Types.classelement.Marker = 's';

                app.Types.classelement.HandleAppObject = @(app) AppElement(app);

                app.Types.classelement.HandleClassObject = @(list_of_subelements, app_config_double) ...
                classelement(classelement.create_config(list_of_subelements, 'closed', app_config_double.Surface));
    
                app.Types.classelement.HandleAppConfig = @(class_config_double) ...
                feval(@ (class_config_str) struct('Surface', class_config_str.Surface), ...
                perso_struct_num2str(class_config_double)); 

                app.Types.classelement.HandleClassConfig = @(app_config_str) ...
                feval(@ (app_config_double) struct('Surface', app_config_double.Surface), ...
                perso_struct_str2double(app_config_str)); 

                dropdown.Items{end + 1} = 'Element';
                dropdown.ItemsData{end + 1} = 'classelement';
            % end 

            %% Element Assembly

            % if~isfield(app.Types, 'classelementassembly')
                app.Types.classelementassembly.ParametersPanelStruct = add_parameters_subpanel_structure ...
                (app, 'classelementassembly', {}, {});

                app.Types.classelementassembly.TypeName = 'classelementassembly';
                app.Types.classelementassembly.Color = 'k';
                app.Types.classelementassembly.Marker = 's';

                app.Types.classelementassembly.HandleAppObject = @(app) AppElementAssembly(app);

                app.Types.classelementassembly.HandleAppConfig = @(class_config_double) ...
                feval(@ (class_config_str) struct(), ...
                perso_struct_num2str(class_config_double)); 

                app.Types.classelementassembly.HandleClassObject = @(list_of_elements) ...
                classelementassembly(classelementassembly.create_config(list_of_elements));

                app.Types.classelementassembly.HandleClassConfig = @(app_config_str) ...
                feval(@ (app_config_double) struct(), ...
                perso_struct_str2double(app_config_str));

                dropdown.Items{end + 1} = 'Element Assembly';
                dropdown.ItemsData{end + 1} = 'classelementassembly';
            % end 

            %% Periodic

            % if~isfield(app.Types, 'Periodic')
                app.Types.Periodic.TypeName = 'Periodic';
                app.Types.Periodic.Color = 'k';
                app.Types.Periodic.Marker = 'hexagon';
                app.Types.Periodic.ParametersPanelStruct = ...
                add_parameters_subpanel_structure(app, 'Periodic', {}, {});

                dropdown.Items{end + 1} = 'Periodic';
                dropdown.ItemsData{end + 1} = 'Periodic';
            % end 

            %% Junction

            % if ~isfield(app.Types, 'Junction')

                app.Types.classjunction.ParametersPanelStruct = add_parameters_subpanel_structure ...
                (app, 'classjunction', {'Junction Element Type', 'Section'}, {'', 'm'});

                app.Types.classjunction.TypeName = 'classjunction';
                app.Types.classjunction.Color = '#30D5C8';
                app.Types.classjunction.Marker = 'diamond';

                app.Types.classjunction.HandleAppObject = @(app) AppJunction(app);

                app.Types.classjunction.HandleClassObject = @(jcn_element, app_config_double) ...
                classjunction(classjunction.create_config( ...
                jcn_element, app_config_double.Section));
    
                app.Types.classjunction.HandleAppConfig = @(class_config_double) ...
                feval(@(class_config_str) struct('JunctionElementType', class(class_config_str.JunctionElement), ...
                'Section', class_config_str.Section), perso_struct_num2str(class_config_double));

                app.Types.classjunction.HandleClassConfig = @(app_config_str) ...
                feval(@(app_config_double) struct('Section', app_config_double.Section), perso_struct_str2double(app_config_str));
                
                dropdown.Items{end + 1} = 'Junction';
                dropdown.ItemsData{end + 1} = 'classjunction';
            % end

            %% Porous Material (Rigid)

            % if ~isfield(app.Types, 'classJCA_Rigid')

                app.Types.classJCA_Rigid .ParametersPanelStruct = ...
                add_parameters_subpanel_structure(app,  'classJCA_Rigid', ...
                {'Thickness', 'Section', 'Porosity', 'AirFlow Resistivity', 'Tortuosity', 'Viscous Caracteristic Length', ...
                'Thermal Caracteristic Length'}, ...
                {'m', 'm^2', '[0-1]', 'Pa.s/m2', '(no unit)', 'm', 'm'});
    
                app.Types.classJCA_Rigid.TypeName = 'classJCA_Rigid';
                app.Types.classJCA_Rigid.Color = '#548FAB';
                app.Types.classJCA_Rigid.Marker = 'diamond';

                app.Types.classJCA_Rigid.HandleAppObject = @(app) AppSubelement(app);

                app.Types.classJCA_Rigid.HandleAppConfig = @(class_config_double) ...
                feval(@(class_config_str) struct('Thickness', class_config_str.Thickness, ...
                                                  'Section', class_config_str.Section, ...
                                                  'Porosity', class_config_str.Porosity, ...
                                                  'AirFlowResistivity', class_config_str.AirFlowResistivity, ...
                                                  'Tortuosity', class_config_str.Tortuosity, ...
                                                  'ViscousCaracteristicLength', class_config_str.ViscousCaracteristicLength, ...
                                                  'ThermalCaracteristicLength', class_config_str.ThermalCaracteristicLength), ...
                       perso_struct_num2str(class_config_double)); 

                app.Types.classJCA_Rigid.HandleClassObject = @(class_config_double) ...
                classJCA_Rigid(classJCA_Rigid.create_config(class_config_double.Section, ...
                                                            class_config_double.Thickness, ...
                                                            class_config_double.Porosity, ...
                                                            class_config_double.Tortuosity, ...
                                                            class_config_double.AirFlowResistivity, ...
                                                            class_config_double.ViscousCaracteristicLength, ...
                                                            class_config_double.ThermalCaracteristicLength));

                app.Types.classJCA_Rigid.HandleClassConfig = @(app_config_str) ...
                feval(@(app_config_double) struct('Thickness', app_config_double.Thickness, ...
                                                  'Section', app_config_double.Section, ...
                                                  'Porosity', app_config_double.Porosity, ...
                                                  'AirFlowResistivity', app_config_double.AirFlowResistivity, ...
                                                  'Tortuosity', app_config_double.Tortuosity, ...
                                                  'ViscousCaracteristicLength', app_config_double.ViscousCaracteristicLength, ...
                                                  'ThermalCaracteristicLength', app_config_double.ThermalCaracteristicLength), ...
                       perso_struct_str2double(app_config_str)); 
 
                dropdown.Items{end + 1} = 'JCA MAterial';
                dropdown.ItemsData{end + 1} = 'classJCA_Rigid';
            % end
        
            %% Perforated Plate (Circular)

            % if ~isfield(app.Types, 'classMPP_Circular')
                app.Types.classMPP_Circular.ParametersPanelStruct = ...
                add_parameters_subpanel_structure(app, 'classMPP_Circular', ...
                {'Relative Porosity', 'Thickness', 'Perforations Radius' 'Perforated Area Surface' 'Thickness Correction'}, ...
                {'[0-1]', 'm', 'm', 'm^2', 'm'});
    
                app.Types.classMPP_Circular.TypeName = 'classMPP_Circular';
                app.Types.classMPP_Circular.Color = '#094F29';
                app.Types.classMPP_Circular.Marker = 'o';

                app.Types.classMPP_Circular.HandleAppObject = @(app) AppSubelement(app);
                
                % Permet de créer l'objet de classe à partir de la configuration d'application
                app.Types.classMPP_Circular.HandleClassObject = @(app_config_double) ...
                classMPP_Circular(classMPP_Circular.create_config(app_config_double.PerforatedAreaSurface, ...
                app_config_double.Thickness, app_config_double.PerforationsRadius, app_config_double.RelativePorosity));
    
                % Permet de créer une configuration d'application à partir d'une configuration de classe/ configuration d'appel
                app.Types.classMPP_Circular.HandleAppConfig = @(class_config_double) ...
                feval(@(class_config_str) struct('RelativePorosity', class_config_str.RelativePorosity, ...
                'Thickness', class_config_str.Thickness, ...
                'PerforationsRadius', class_config_str.PerforationsRadius, ...
                'ThicknessCorrection', class_config_str.ThicknessCorrection, ...
                'PerforatedAreaSurface', class_config_str.PerforatedAreaSurface, ...
                'Section', class_config_str.Section), perso_struct_num2str(class_config_double)); 

                % Permet de créer une configuration de classe permettant de construire l'objet par la suite
                app.Types.classMPP_Circular.HandleClassConfig = @(app_config_str) ...
                feval(@(app_config_double) struct('RelativePorosity', app_config_double.RelativePorosity, ...
                'Thickness', app_config_double.Thickness, ...
                'PerforationsRadius', app_config_double.PerforationsRadius, ...
                'PerforatedAreaSurface', app_config_double.PerforatedAreaSurface, ...
                'Section', app_config_double.PerforatedAreaSurface * app_config_double.RelativePorosity, ...
                'ThicknessCorrection', app_config_double.ThicknessCorrection), perso_struct_str2double(app_config_str)); 

                dropdown.Items{end + 1} = 'Perforated plate with circular perforations';
                dropdown.ItemsData{end + 1} = 'classMPP_Circular';
            % end

            % %% Perforated Plate (Slit)
            % if ~isfield(app.SubelementsTypes, 'SlitMPP')
            % 
            %     app.SubelementsTypes.SlitMPP.ParametersPanelStruct = add_parameters_subpanel_structure ...
            %     (app, 'classMPP_Slit', {'Porosity', 'Thickness', 'Length', 'Width'}, ...
            %     {'[0-1]', 'm', 'm', 'm'});
            % 
            %     app.SubelementsTypes.SlitMPP.TypeName = 'classMPP_Slit';
            %     app.SubelementsTypes.SlitMPP.Color = '#429B46';
            %     app.SubelementsTypes.SlitMPP.Marker = 'diamond';
            %     app.SubelementsTypes.SlitMPP.HandleClassObject = @(config) classMPP_Slit(config);
            % 
                % dropdown.Items{end + 1} = 'Perforated Plate (Slit)';
                % dropdown.ItemsData{end + 1} = 'SlitMPP';
            % 
            %     app.ItemsToTypes('Perforated Plate (Slit)') = app.SubelementsTypes.SlitMPP;
            %     app.TypesToItems('SlitMPP') = 'Perforated Plate (Slit)';
            % end
                        
            % %% Perforated Plate (Rectangular)
            % if ~isfield(app.SubelementsTypes, 'RectangularMPP')
            % 
            %     app.SubelementsTypes.RectangularMPP.ParametersPanelStruct = add_parameters_subpanel_structure ...
            %     (app, 'classMPP_Rectangular', {'Porosity', 'Thickness', 'Length', 'Width'}, ...
            %     {'[0-1]', 'm', 'm', 'm'});
            % 
            %     app.SubelementsTypes.RectangularMPP = 'RectangularMPP';
            %     app.SubelementsTypes.RectangularMPP.Color = '#94C58C'; 
            %     app.SubelementsTypes.RectangularMPP.Marker = 'diamond';
            %     app.SubelementsTypes.RectangularMPP.HandleClassObject = @(config) classMPP_Rectangular(config);
            % 
                % dropdown.Items{end + 1} = 'Perforated Plate (Rectangular)';
                % dropdown.ItemsData{end + 1} = 'RectangularMPP';
            % 
            %     app.ItemsToTypes('Perforated Plate (Rectangular)') = app.SubelementsTypes.RectangularMPP;
            %     app.TypesToItems('RectangularMPP') = 'Perforated Plate (Rectangular)';
            % end

            % %% Helmholtz Resonator (Free geometry)
            % app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct = add_parameters_subpanel_structure ...
            % (app, 'FreeHelmoltzResonator', {'Neck Surface', 'Neck Length', 'Cavity Volume'}, ...
            % {'m2', 'm', 'm3'});
            %
            % app.SubelementsTypes.FreeHelmoltzResonator.Color = '#8B0001';
            % app.SubelementsClassesNames('Helmholtz Resonator (Free Geometry)') = 'FreeHelmoltzResonator';
            % app.SubelementsClassesNames('FreeHelmoltzResonator') = 'Helmholtz Resonator (Free Geometry)';

            % % Helmholtz Resonator (Cylindrical)
            % app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct = add_parameters_subpanel_structure ...
            % (app, 'CylindricalHelmholtzResonator', 4, {'Neck Radius', 'Neck Depth', 'Cavity Radius', ...
            % 'Cavity Depth'}, {'m', 'm', 'm', 'm'});
            %
            % app.SubelementsTypes.CylindricalHelmholtzResonator.Color = '#C92020';
            % app.SubelementsClassesNames('Helmholtz Resonator (Cylindrical)') = 'CylindricalHelmholtzResonator';
            % app.SubelementsClassesNames('CylindricalHelmholtzResonator') = 'Helmholtz Resonator (Cylindrical)';
        
            % %% Resistive Screen
            % 
            % if ~isfield(app.SubelementsTypes, 'Screen')
            % 
            %     app.SubelementsTypes.Screen.ParametersPanelStruct = add_parameters_subpanel_structure ...
            %     (app, 'classscreen', {'Screen Density', 'Thickness'}, {'kg/m3', 'm'});
            % 
            %     app.SubelementsTypes.Screen.Color = '#FFA500';
            %     app.SubelementsTypes.Screen.Marker = 'diamond';
            %     app.SubelementsTypes.Screen.HandleClassObject = @(config) classscreen(config);
            % 
            %     dropdown.Items{end + 1} = 'Resistive Screen';
            %     dropdown.ItemsData{end + 1} = 'Screen';
            % 
            %     app.ItemsToTypes('Resistive Screen') = app.SubelementsTypes.Screen;
            %     app.TypesToItems('Screen') = 'Resistive Screen';
            % end

            % %% Protective Grid
            % 
            % if ~isfield(app.SubelementsTypes, 'Grid')
            % 
            %     app.SubelementsTypes.Grid.ParametersPanelSt.QWL.ruct = add_parameters_subpanel_structure ...
            %     (app, 'classgrid', {'Mesh Length', 'Mesh Width', 'Thickness', 'Porosity'}, ...
            %     {'m', 'm', 'm', '%'});
            % 
            %     app.SubelementsTypes.Grid.Color = '#6600A1';
            %     app.SubelementsTypes.Grid.Marker = 'diamond';
            %     app.SubelementsTypes.Grid.HandleClassObject = @(config) classgrid(config);
            % 
            %     dropdown.Items{end + 1} = 'Protective Grid';
            %     dropdown.ItemsData{end + 1} =  'Grid';
            % 
            %     app.ItemsToTypes('Protective Grid') = app.SubelementsTypes.Grid;
            %     app.TypesToItems('Grid') = 'Pretective Grid';
            % end
        
            % %% Quarter Wavelength Resonator
            % 
            % if ~isfield(app.SubelementsTypes, 'QWL')
            % 
            %     app.SubelementsTypes.QWL.ParametersPanelStruct = add_parameters_subpanel_structure ...
            %     (app, 'classQWL', {'Main Radius', 'Resonator Radius', 'Resonator Length'}, ...
            %     {'m', 'm', 'm'});
            % 
            % 
            %     app.SubelementsTypes.QWL.Color = '#C0428A';
            %     app.SubelementsTypes.QWL.Marker = 'diamond';
            %     app.SubelementsTypes.QWL.HandleClassObject = @(config) classQWL(config);
            % 
            %     dropdown.Items{end + 1} = 'Quarter Wavelength Resonator';
            %     dropdown.ItemsData{end + 1} = 'QWL';
            % 
            %     app.ItemsToTypes('Quarter Wavelength Resonator') = app.SubelementsTypes.QWL;
            %     app.TypesToItems('QWL') = 'Quarter Wavelength Resonator';
            % end 

            %% Quarter Wavelength Resonator (Slit)

            % if ~isfield(app.Types, 'classQWL_Slit')

                app.Types.classQWL_Slit.ParametersPanelStruct = ...
                add_parameters_subpanel_structure(app,  'classQWL_Slit', ...
                {'Length', 'Width', 'InputSection'}, {'m', 'm', 'm^2'});

                app.Types.classQWL_Slit.TypeName = 'classQWL_Slit';
                app.Types.classQWL_Slit.Color = '#C0428A';
                app.Types.classQWL_Slit.Marker = '_';

                app.Types.classQWL_Slit.HandleAppObject = @(app) AppSubelement(app);

                % Permet de créer l'objet de classe à partir de la configuration d'application
                app.Types.classQWL_Slit.HandleClassObject = @(app_config_double) ...
                classQWL_Slit(classQWL_Slit.create_config(app_config_double.Length, ...
                app_config_double.Width, app_config_double.InputSection));
    
                % Permet de créer une configuration d'application à partir d'une configuration de classe/ configuration d'appel
                app.Types.classQWL_Slit.HandleAppConfig = @(class_config_double) ...
                feval(@ (class_config_str) struct('Length', class_config_str.Length, 'Width', class_config_str.Width, ...
                'InputSection', class_config_str.InputSection), perso_struct_num2str(class_config_double)); %#ok<*FVAL>

                % Permet de créer une configuration d'application à partir d'une configuration de classe/ configuration d'appel
                app.Types.classQWL_Slit.HandleClassConfig = @(app_config_str) ...
                feval(@ (app_config_double) struct('Length', app_config_double.Length, 'Width', app_config_double.Width, ...
                'InputSection', app_config_double.InputSection), perso_struct_str2double(app_config_str));
    
                dropdown.Items{end + 1} = 'Quarter Wavelength Resonator';
                dropdown.ItemsData{end + 1} = 'classQWL_Slit';
            % end 


            % Ajouter Quarter Wavelength Resonator (Circle)

            %% Cavity

            % if ~isfield(app.Types, 'classcavity')

                app.Types.classcavity.ParametersPanelStruct = ...
                add_parameters_subpanel_structure(app, 'classcavity', ...
                {'Thickness', 'Section'}, {'m', 'm^2'});
    
                app.Types.classcavity.TypeName = 'classcavity';
                app.Types.classcavity.Color = '#30D5C8';
                app.Types.classcavity.Marker = '_';

                app.Types.classcavity.HandleAppObject = @(app) AppSubelement(app);

                app.Types.classcavity.HandleClassObject = @(app_config_double) ...
                classcavity(classcavity.create_config(app_config_double.Thickness, app_config_double.Section));

                app.Types.classcavity.HandleAppConfig = @(class_config_double) ...
                feval(@(class_config_str) struct('Thickness', class_config_str.Thickness, 'Section', class_config_str.Section), ...
                perso_struct_num2str(class_config_double));

                app.Types.classcavity.HandleClassConfig = @(app_config_str) ...
                feval(@(app_config_double) struct('Thickness', app_config_double.Thickness, 'Section', app_config_double.Section), ...
                perso_struct_str2double(app_config_str));

                dropdown.Items{end + 1} = 'Cavity';
                dropdown.ItemsData{end + 1} = 'classcavity';
            % end

            % Ajouter Annular Cavity Cylindrical

            %% Annular cavity Cubical

            % if ~isfield(app.Types, 'classannularcavity_cubical')

                app.Types.classannularcavity_cubical.ParametersPanelStruct = add_parameters_subpanel_structure ...
                (app, 'classannularcavity_cubical', {'Main pore width', 'Main pore depth', ...
                'Cavity Width', 'Cavity Depth', 'Cavity Thickness'}, {'m', 'm', 'm', 'm', 'm'});

                app.Types.classannularcavity_cubical.TypeName = 'classannularcavity_cubical';
                app.Types.classannularcavity_cubical.Color = '#A3F25C';
                app.Types.classannularcavity_cubical.Marker = '_';

                app.Types.classannularcavity_cubical.HandleAppObject = @(app) AppSubelement(app);

                app.Types.classannularcavity_cubical.HandleClassObject = @(app_config_double) ...
                classannularcavity_cubical(classannularcavity_cubical.create_config(app_config_double.MainPoreWidth, app_config_double.MainPoreDepth, ...
                app_config_double.CavityWidth, app_config_double.CavityDepth, app_config_double.CavityThickness));

                app.Types.classannularcavity_cubical.HandleAppConfig = @(class_config_double) ...
                feval(@(class_config_str) struct('MainPoreWidth', class_config_str.MainPoreWidth, 'MainPoreDepth', class_config_str.MainPoreDepth, ...
                'CavityWidth', class_config_str.CavityWidth, 'CavityDepth', class_config_str.CavityDepth, 'CavityThickness', class_config_str.CavityThickness),  ...
                perso_struct_num2str(class_config_double));

                app.Types.classannularcavity_cubical.HandleClassConfig = @(app_config_str) ...
                feval(@(app_config_double) struct('MainPoreWidth', app_config_double.MainPoreWidth, 'MainPoreDepth', app_config_double.MainPoreDepth, ...
                'CavityWidth', app_config_double.CavityWidth, 'CavityDepth', app_config_double.CavityDepth, 'CavityThickness', app_config_double.CavityThickness), ...
                perso_struct_str2double(app_config_str));
                
                dropdown.Items{end + 1} = 'Annular cavity (cubic-shaped)';
                dropdown.ItemsData{end + 1} = 'classannularcavity_cubical';
            % end 

            %% Annular cavity Trapezoidal

            % if ~isfield(app.Types, 'classannularcavity_trapezoidal')

                app.Types.classannularcavity_trapezoidal.ParametersPanelStruct = add_parameters_subpanel_structure ...
                (app, 'classannularcavity_trapezoidal', {'Main Pore Width In', 'Main Pore Width Out', ...
                'Cavity Width', 'Cavity Depth', 'Cavity Thickness'}, {'m', 'm', 'm', 'm', 'm'});

                app.Types.classannularcavity_trapezoidal.TypeName = 'classannularcavity_rectangular';
                app.Types.classannularcavity_trapezoidal.Color = '#7B3FE1';
                app.Types.classannularcavity_trapezoidal.Marker = '_';

                app.Types.classannularcavity_trapezoidal.HandleAppObject = @(app) AppSubelement(app);

                app.Types.classannularcavity_trapezoidal.HandleClassObject = @(app_config_double) ...
                classannularcavity_trapezoidal(classannularcavity_trapezoidal.create_config(app_config_double.MainPoreWidthIn, app_config_double.MainPoreWidthOut, ...
                app_config_double.CavityWidth, app_config_double.CavityDepth, app_config_double.CavityThickness));

                app.Types.classannularcavity_trapezoidal.HandleAppConfig = @(class_config_double) ...
                feval(@(class_config_str) struct('MainPoreWidthIn', class_config_str.MainPoreWidthIn, 'MainPoreWidthOut', class_config_str.MainPoreWidthOut, ...
                'CavityWidth', class_config_str.CavityWidth, 'CavityDepth', class_config_str.CavityDepth, 'CavityThickness', class_config_str.CavityThickness),  ...
                perso_struct_num2str(class_config_double));

                app.Types.classannularcavity_trapezoidal.HandleClassConfig = @(app_config_str) ...
                feval(@(app_config_double) struct('MainPoreWidthIn', app_config_double.MainPoreWidthIn, 'MainPoreWidthOut', app_config_double.MainPoreWidthOut, ...
                'CavityWidth', app_config_double.CavityWidth, 'CavityDepth', app_config_double.CavityDepth, 'CavityThickness', app_config_double.CavityThickness), ...
                perso_struct_str2double(app_config_str));
                
                dropdown.Items{end + 1} = 'Annular cavity (trapezoic-shaped)';
                dropdown.ItemsData{end + 1} = 'classannularcavity_trapezoidal';
            % end 

        
        end
        
        function ParametersPanelStruct = add_parameters_subpanel_structure(app, title, tags, units)
        % Cette méthode permet de créer une structure qui donne accès à
        % l'édition du panneau des paramètres. Chaque type de sous-élement
        % se voit attribuer une structure comme cell-ci. La structure
        % renvoie vers l'objet graphique qui est par ailleurs relié à un
        % objet de classe AppComponentContainer qui permet de gérer
        % l'appartion du panneau pour un certain type de sous-élement.

            % Utilisée dans : init_subelements_types, init_elements_types,
            % ...

            % On initialise la structure des paramètres du panneau
            ParametersPanelStruct = struct();
            container = app.ParametersView.Components.ParametersSubView;
            panel = container.add_empty_panel(title);
        
            % On définie les paramètres des composants graphiques du panneau
            N = length(tags);
            Labels = cell(1, N);
            EditFields = cell(1, N);
            Units = cell(1, N);

            for i = 1:N
                Labels{i} = uilabel(panel);
                Labels{i}.HorizontalAlignment = 'center';
                Labels{i}.Position = [10 200 - 28 * (i - 1) 126 32];
                Labels{i}.Text = tags{i};
                Labels{i}.WordWrap = 'on';
                Labels{i}.Tag = 'Parameter';
        
                % Champ de saisie du paramètre
                EditFields{i} = uieditfield(panel);
                EditFields{i}.Position = [150 206 - 28 * (i - 1) 60 18];

                % On attribue le marqueur du nom de la variable au champ d'édition
                EditFields{i}.Tag = tags{i};

                % Unité du paramètre
                Units{i} = uilabel(panel);
                Units{i}.Position = [215 200 - 28 * (i - 1) 50 32];
                Units{i}.Text = units{i};
                Units{i}.Tag = 'Unit';
            end
               
            % Créer une structure pour stocker tous les composants
            ParametersPanelStruct.Container = container;
            ParametersPanelStruct.Panel = panel;
            ParametersPanelStruct.ParametersLabels = Labels;
            ParametersPanelStruct.ParametersEditFields = EditFields;
            ParametersPanelStruct.ParametersUnits = Units;
        end
 
        function subelement_type_dropdown_value_changed(app, ~, ~)
        % Ce CallBack se déclenche lorsque le bouton 'Subelement Type' change de valeur. 
        % Cela peut se produire si l'utilisateur.ice : 
        % - sélectionne un sous-élement dans le graphe
        % - change manuellement la valeur du menu déroulant
        % - importe un sous-élement
        % - ...

            % On sélectionne le menu déroulant
            subelement_type_dropdown = app.ParametersPanel.Components.SubelementParameters.UIObject.Children(2);
            
            % On récupère le type de sous-élements associé
            current_type_name = app.Types.(subelement_type_dropdown.Value).TypeName;

            % On affiche le panneau de paramètres associé
            app.Types.SubelementsParametersPanel.Components.(current_type_name).show();                
        end
        
        function import_element(app)

            % On ouvre une boîte de dialogue pour récupérer l'élement souhaité
            % default_folder = [app.Environnement.Root, '\Apps\Elements']
            default_folder = 'C:\Users\lucas.barbier\Documents\Maitrise ETS\Répertoire GitHub\Apps\Elements';
            [file, path] = uigetfile('', 'Sélectionner un fichier', default_folder); 
            selectedfile = fullfile(path, file); % Construit le chemin complet du fichier sélectionné
            class_element = importdata(selectedfile);

            % class_element = importdata(['C:\Users\lucas.barbier\OneDrive - ETS\' ...
            %     'CRIAQ-REAR\Maitrise LB\MATLAB\Classes\MLPSBH_classes\RectangularMLPSBH\' ...
            %     'Objets de classe pour l''application\test.mat']);
            
            % class_element = importdata(['C:\Users\lucas.barbier\OneDrive - ETS\' ...
            %     'CRIAQ-REAR\Maitrise LB\MATLAB\Classes\MLPSBH_classes\RectangularMLPSBH\' ...
            %     'Objets de classe pour l''application\test_6_plaques.mat']);

            app.Elements.add_content(class_element.HandleAppBuilder(app, class_element));

            % On affiche l'élement importé
            app.Elements.Content{end}.show(app);
            % perso_visualizeContentTree(app.Elements, app.Tree);
        end
        
        function compute(app, name)
        % Cette méthode calcule le résultat analytique de l'assemblage
        % en cours d'édition

            list_of_elements = {};

            % On parcours les élements de l'application
            for i = 1:length(app.Elements.Content)
                list_of_elements{end+1} = app.Elements.Content{i}.app_to_class(app);
            end
    
            % Une fois les élements construits, on va pouvoir creér un objet de
            % classe classelementassembly
            assembly = classelementassembly(list_of_elements);
    
            % Pour l'instant on affiche le tracé dans une figure à part
            assembly.plot_alpha(app.Env, name);     
        end
    end
    
    methods (Access = private)
        
        function display_handle_variables(app)
            
            % On réinitialise la table des variables muettes
            app.HandleVariablesTable.Data(:, :) = [];

            var_name = fieldnames(app.HandleVariables);
            n = length(var_name);
            for i = 1:n
                app.HandleVariablesTable.Data(i, :) = ...
                {var_name{i} ...
                app.HandleVariables.(var_name{i}).Value ...
                app.HandleVariables.(var_name{i}).Description};
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, env, event)
            
            % Récupère la taille de l'écran
            screenSize = get(0, 'ScreenSize');  % [x y width height]
            
            % Taille de la fenêtre de l'app
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            
            % Calcul des coordonnées centrées
            app.UIFigure.Position(1) = (screenSize(3) - appWidth) / 2;
            app.UIFigure.Position(2) = (screenSize(4) - appHeight) / 2;
            
            % On créer l'environnement
            app.EnvApp = env;

            % On initialise le graphe
            app.Graph = AppComponentContainer(app.GraphPanel, 'GlobalGraph');
            app.Graph.add_component(AppAxes(app.ElementsGraph, 'ElementsGraph'));
            app.Graph.add_component(AppAxes(app.SubelementsGraph, 'SubelementsGraph'));
            app.Graph.add_component(AppAxes(app.Navigator, 'Navigator'));

            % On initialise le panneau des paramètres
            app.ParametersView = AppComponentContainer(app.ParametersPanel, 'ParametersView');
            app.ParametersView.add_component(AppComponentContainer(app.ParametersSubPanel, 'ParametersSubView'));

            % On initialise les types de sous-élements 
            app.init_types();

            % On initialise la table des variables muettes
            app.HandleVariablesTable.Data = (table({}, {}, {}, 'VariableNames', {'Name', 'Value', 'Description'}));
            app.HandleVariablesTable.ColumnName = {'Name', 'Value', 'Description'};

            app.HandleVariables = struct();

            % On crée un objet de classe AppContainer représentant la
            % collection d'élements gérés par l'application. On le
            % connecte au graphe de classe AppComponent associé à la
            % représentation des élements.

            app.Elements = AppContainer();
            app.Elements.scatter_with_call(app, app.Graph.Components.ElementsGraph);
        end

        % Button pushed function: addvariableButton
        function addvariableButtonPushed(app, event)
            % Ajoute une ligne dans le tableau des variables muettes

            len_table = length(app.HandleVariablesTable.Data{:, 1}); 
            app.HandleVariablesTable.Data(len_table + 1, :) = {'' '' ''}; 
        end

        % Button pushed function: deletevariableButton
        function deletevariableButtonPushed(app, event)
            % Supprime la dernière ligne dans le tableau des variables
            % muettes

            len_table = length(app.HandleVariablesTable.Data{:, 1}); 
            app.HandleVariablesTable.Data(len_table, :) = []; 
        end

        % Menu selected function: ImportElementMenu
        function ImportElementMenuSelected(app, event)
            import_element(app)
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            cla(app.Graph.Components.SubelementsGraph.UIObject);
            cla(app.Graph.Components.ElementsGraph.UIObject);
            cla(app.Graph.Components.Navigator.UIObject);

            % On supprime les variables muettes stockées
            app.HandleVariables = struct();
            display_handle_variables(app);

            % On cache tous les panneaux de paramètres
            app.ParametersView.Components.ParametersSubView.hide_components();
            app.TypeDropDown.Value = 'None';

            % On met à jour app.Elements et on l'affiche
            app.Elements = AppContainer();
            app.Elements.scatter_with_call(app, app.Graph.Components.ElementsGraph);
            % perso_visualizeContentTree(app.Elements, app.Tree);
        end

        % Display data changed function: HandleVariablesTable
        function HandleVariablesTableDisplayDataChanged(app, event)
        % Cette méthode est appelée lorsque la table des variables muettes
        % est éditéés
            
            % On recherche le nombre de variables muettes déclarées
            n = length(app.HandleVariablesTable.Data{:, 1});
            for i = 1:n
                % On vérifie que la ligne n'est pas vide
                var_name = app.HandleVariablesTable.Data{i, 1}{1};
                if not(var_name == "")
                    app.HandleVariables.(var_name).Value = app.HandleVariablesTable.Data{i, 2}{1};
                    app.HandleVariables.(var_name).Description = app.HandleVariablesTable.Data{i, 3}{1};
                end
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
        % Cette méthode est appelée lorsque l'utilisateur.ice clique sur le
        % bouton Compute. Elle permet de reconstruire l'objet de classe à
        % partir des paramètres renseignés dans l'interface

        list_of_elements = {};

        % On parcours les élements de l'application
        for i = 1:length(app.Elements.Content)
            list_of_elements{end+1} = app.Elements.Content{i}.app_to_class(app);
        end

        % Une fois les élements construits, on va pouvoir creér un objet de
        % classe classelementassembly
        assembly = classelementassembly(classelementassembly.create_config(list_of_elements));

        % Pour l'instant on affiche le tracé dans une figure à part
        assembly.plot_alpha(app.EnvApp, 'assembly', 'iter');
        hold on
        end

        % Button pushed function: ParametricStudyButton
        function ParametricStudyButtonPushed(app, event)
        % Cette méthode est appelée lorsque l'utilisateur.ice clique sur le 
        % bouton Parametric Study.
            
            Parametric_Study_App(app);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
        % Cette méthode est appelée lorsque l'utilisateur.ice enregistre la
        % configuration de l'application

            % Ouvre une boîte de dialogue pour sauvegarder un fichier avec extension .config
            [file, path] = uiputfile('*.config');
            filename = fullfile(path, file); % Construit le chemin complet du fichier
            
    
            datas = {app.Env, app.Elements, app.HandleVariables};


            % Sauvegarde les données dans un fichier .config
            save(filename, 'datas');
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % Cette méthode est appelée lorsque l'utilisateur.ice enregistre la
            % configuration de l'application

                % Ouvre une boîte de dialogue pour sauvegarder un fichier avec extension .config
                defaultFolder = 'C:\Users\lucas.barbier\Documents\Maitrise ETS\Répertoire GitHub\Apps\Configurations';
                [file, path] = uigetfile({'*.config'},'Sélectionne un fichier', defaultFolder);
                filename = fullfile(path, file); % Construit le chemin complet du fichier
                datas = importdata(file);

                % On redéfinit les variables d'application
                app.Env = datas{1};
                app.Elements = datas{2};
                app.HandleVariables = datas{3};
                app.Elements.Content{1}.show(app);

                % On affiche la configuration d'interface importée
                display_handle_variables(app);
                % perso_visualizeContentTree(app.Elements, app.Tree);
        end

        % Button pushed function: OptimiseButton
        function OptimiseButtonPushed(app, event)
            Optimisation_App(app);
        end

        % Menu selected function: fonctiontemporaireinit_typesMenu
        function fonctiontemporaireinit_typesMenuSelected(app, event)
            app.init_types()
        end

        % Menu selected function: ModifyenvironnementparametersMenu
        function ModifyenvironnementparametersMenuSelected(app, event)
            app.EnvApp.UIFigure.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ModifyenvironnementparametersMenu = uimenu(app.FileMenu);
            app.ModifyenvironnementparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifyenvironnementparametersMenuSelected, true);
            app.ModifyenvironnementparametersMenu.Text = 'Modify environnement parameters';
            app.fonctiontemporaireinit_typesMenu = uimenu(app.FileMenu);
            app.fonctiontemporaireinit_typesMenu.MenuSelectedFcn = createCallbackFcn(app, @fonctiontemporaireinit_typesMenuSelected, true);
            app.fonctiontemporaireinit_typesMenu.Text = 'fonction temporaire : init_types';
            app.ConfigurationMenu = uimenu(app.UIFigure);
            app.ConfigurationMenu.Text = 'Configuration';
            app.NewElementMenu = uimenu(app.ConfigurationMenu);
            app.NewElementMenu.Text = 'New Element';
            app.ImportElementMenu = uimenu(app.ConfigurationMenu);
            app.ImportElementMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportElementMenuSelected, true);
            app.ImportElementMenu.Text = 'Import Element';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ViewGrid = uigridlayout(app.GridLayout);
            app.GraphPanel = uipanel(app.ViewGrid);
            app.GraphPanel.Title = 'Graph';
            app.GraphGrid = uigridlayout(app.GraphPanel);
            app.GridView = uigridlayout(app.GraphGrid);
            app.SubelementsGraph = uiaxes(app.GridView);
            app.SubelementsGraph.XTick = [];
            app.SubelementsGraph.YTick = [];
            app.SubelementsGraph.Tag = 'SubelementsGraph';
            app.ElementsGraph = uiaxes(app.GridView);
            app.ElementsGraph.XTick = [];
            app.ElementsGraph.YTick = [];
            app.ElementsGraph.Tag = 'ElementsGraph';
            app.Navigator = uiaxes(app.GridView);
            app.Navigator.XTick = [];
            app.Navigator.YTick = [];
            app.VariablesGrid = uigridlayout(app.ViewGrid);
            app.HandleVariablesTable = uitable(app.VariablesGrid);
            app.HandleVariablesTable.ColumnName = '';
            app.HandleVariablesTable.RowName = {};
            app.HandleVariablesTable.SelectionType = 'row';
            app.HandleVariablesTable.ColumnEditable = true;
            app.HandleVariablesTable.DisplayDataChangedFcn = createCallbackFcn(app, @HandleVariablesTableDisplayDataChanged, true);
            app.VariablesButtonsGrid = uigridlayout(app.VariablesGrid);
            app.addvariableButton = uibutton(app.VariablesButtonsGrid, 'push');
            app.addvariableButton.ButtonPushedFcn = createCallbackFcn(app, @addvariableButtonPushed, true);
            app.addvariableButton.Text = 'add variable';
            app.deletevariableButton = uibutton(app.VariablesButtonsGrid, 'push');
            app.deletevariableButton.ButtonPushedFcn = createCallbackFcn(app, @deletevariableButtonPushed, true);
            app.deletevariableButton.Text = 'delete variable';
            app.ParametersPanel = uipanel(app.GridLayout);
            app.ParametersGrid = uigridlayout(app.ParametersPanel);
            app.ParametersViewPanel = uipanel(app.ParametersGrid);
            app.ParametersViewPanel.Title = 'Parameters View';
            app.UpdateConfigurationButton = uibutton(app.ParametersViewPanel, 'push');
            app.UpdateConfigurationButton.Text = 'Update Configuration';
            app.ParametersSubPanel = uipanel(app.ParametersViewPanel);
            app.TypeDropDownLabel = uilabel(app.ParametersViewPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.ParametersViewPanel);
            app.TypeDropDown.Items = {};
            app.TypeDropDown.Value = {};
            app.Panel = uipanel(app.ParametersGrid);
            app.ButtonsPanel = uigridlayout(app.Panel);
            app.OptimiseButton = uibutton(app.ButtonsPanel, 'push');
            app.OptimiseButton.ButtonPushedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.OptimiseButton.Text = 'Optimise';
            app.ParametricStudyButton = uibutton(app.ButtonsPanel, 'push');
            app.ParametricStudyButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricStudyButtonPushed, true);
            app.ParametricStudyButton.Text = 'Parametric Study';
            app.ComputeButton = uibutton(app.ButtonsPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end