classdef Non_Linear_Constraints_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ContinueButton        matlab.ui.control.Button
        AddconstraintsButton  matlab.ui.control.Button
        ConstraintsTable      matlab.ui.control.Table
    end

    
    properties (Access = private)
        OptimisationApp 
        Nonlcon
    end
    
    methods (Access = private)
        function get_nonlcon_from_table(app)
            data = app.ConstraintsTable.Data;
            numRows = size(data,1);
            
            cCode = {}; ceqCode = {};
            
            for i = 1:numRows
                left = data{i,1};
                op = data{i,2};
                right = data{i,3};
                
                if strcmp(op, '<')
                    cCode{end+1} = sprintf('c(%d) = %s - (%s);', numel(cCode)+1, left, right);
                elseif strcmp(op, '=')
                    ceqCode{end+1} = sprintf('ceq(%d) = %s - (%s);', numel(ceqCode)+1, left, right);
                end
            end
            
            % Construire la fonction handle
            funText = sprintf(['@(x) (function() \n' ...
                               'c = zeros(%d,1);\n' ...
                               'ceq = zeros(%d,1);\n' ...
                               '%s\n%s\n' ...
                               'end)()'], ...
                               numel(cCode), numel(ceqCode), ...
                               strjoin(cCode, '\n'), strjoin(ceqCode, '\n'));

            app.Nonlcon = str2func(funText);
        end
        
        function update_nonlinear_constraints(app)
            
            % Récupérer le contenu de la table utilisateur
            data = app.ConstraintsTable.Data;
        
            % Préparer les vecteurs pour c et ceq
            cTerms = {};
            ceqTerms = {};
        
            % Les variables dispo dans MainApp
            varNames = fieldnames(app.OptimisationApp.HandleVariables);
        
            % Boucle sur les lignes du tableau
            for i = 1:size(data,1)
                left  = strtrim(data{i,1});
                op    = strtrim(data{i,2});
                right = strtrim(data{i,3});
        
                % Ignorer si ligne incomplète
                if isempty(left) || isempty(op) || isempty(right)
                    continue;
                end
        
                % 🔄 Remplacer "a" par "vars.a", etc.
                for k = 1:numel(varNames)
                    name = varNames{k};
                    left  = regexprep(left,  ['\<' name '\>'],  ['vars.' name]);
                    right = regexprep(right, ['\<' name '\>'],  ['vars.' name]);
                end
        
                % Ajouter aux expressions c ou ceq
                switch op
                    case '<'
                        cTerms{end+1}   = sprintf('(%s) - (%s)', left, right);
                    case '='
                        ceqTerms{end+1} = sprintf('(%s) - (%s)', left, right);
                end
            end
        
            % Construire les expressions texte pour c et ceq
            cExpr   = strjoin(cTerms, ', ');
            ceqExpr = strjoin(ceqTerms, ', ');
        
            % Si vide, éviter erreur en optimisation
            if isempty(cExpr), cExpr = ''; end
            if isempty(ceqExpr), ceqExpr = ''; end
        
            % Créer le texte du handle
            funText = sprintf('@(vars) deal([%s], [%s])', cExpr, ceqExpr);
        
            % Tenter la conversion
            try
                app.nonlcon = str2func(funText);
            catch
                app.nonlcon = [];
                disp('⚠️ Erreur lors de la génération des contraintes non linéaires');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, optimisation_app)
            app.OptimisationApp = optimisation_app;

            % Affichage centré sur l'écran
            disp_centered_app(app);
            
            app.ConstraintsTable.ColumnName = {'Left expression', 'Comparison operator', 'Right expression'};
            app.ConstraintsTable.ColumnEditable = [true true true];
            app.ConstraintsTable.ColumnFormat = {[], {'=','<'}, []}; % Menu dans la 2e colonne
            app.ConstraintsTable.Data = {}; % Commencer vide
        end

        % Button pushed function: AddconstraintsButton
        function AddconstraintsButtonPushed(app, event)
            % Récupérer données actuelles
            data = app.ConstraintsTable.Data;
            
            % Ajouter une nouvelle ligne vide
            newRow = {'', '=', ''};
            app.ConstraintsTable.Data = [data; newRow];
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.get_nonlcon_from_table()
            
            % On ferme la fenêtre secondaire
            app.UIFigure.Visible = "off";
        end

        % Cell edit callback: ConstraintsTable
        function ConstraintsTableCellEdit(app, event)
            update_nonlinear_constraints(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConstraintsTable = uitable(app.UIFigure);
            app.ConstraintsTable.ColumnName = '';
            app.ConstraintsTable.RowName = {};
            app.ConstraintsTable.CellEditCallback = createCallbackFcn(app, @ConstraintsTableCellEdit, true);
            app.AddconstraintsButton = uibutton(app.UIFigure, 'push');
            app.AddconstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @AddconstraintsButtonPushed, true);
            app.AddconstraintsButton.Text = 'Add constraints';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Non_Linear_Constraints_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end