classdef Optimisation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ModifynonlinearconstraintsMenu  matlab.ui.container.Menu
        ModifyalgorythmparametersMenu   matlab.ui.container.Menu
        DeletelastvariableButton        matlab.ui.control.Button
        DeletelastprofileButton         matlab.ui.control.Button
        ContinueButton                  matlab.ui.control.Button
        AddprofileButton                matlab.ui.control.Button
        LaunchoptimisatonButton         matlab.ui.control.Button
        AddvariableButton               matlab.ui.control.Button
        OptimisedVariablesTable         matlab.ui.control.Table
        ProfilesParametersTable         matlab.ui.control.Table
    end

    
    properties (Access = public)
        
        MainApp
        NonLinearConstraintsApp
        AlgorithmParametersApp
        OVariables
        HandleProfiles
    end
    
    methods (Access = public)
        
        function get_handle_profiles_from_table(app)
            % Récupérer la table de l'UI
            data = app.ProfilesParametersTable.Data;
            
            % Pour chaque ligne, construire un handle anonyme g_obj...
            handle_profiles = {};
            for i = 1:size(data,1)
                fmin = str2double(data{i,1});
                fmax = str2double(data{i,2});
                
                handle_profiles{end+1} = @(env) someSelector(env, [fmin, fmax]);  
                % exemple : retourne une grille de fréquences ou un masque booléen
            end
            
            app.HandleProfiles = handle_profiles;
        end
        
        function update_handle_variables_table(app)

            % Récupère les variables définies dans l’app principale
            vars = fieldnames(app.MainApp.Variables);
        
            % Ajouter 'None' au début
            vars = [{'None'}; vars];
        
            % Assurer que le format soit bien un ROW cell array
            vars = vars(:)';  % Force row orientation 1xN
        
            % Définir les noms et formats des colonnes
            app.OptimisedVariablesTable.ColumnName = ...
                {'Variable', 'Type', 'Lower Bound', 'Upper Bound', 'Integer', 'Accepted Values'};
        
            app.OptimisedVariablesTable.ColumnFormat = ...
                {vars, {'continuous','discrete'}, [], [], 'logical', []};
        
            app.OptimisedVariablesTable.ColumnEditable = [true true true true true true];
        
            % Initialiser une ligne vide par défaut
            app.OptimisedVariablesTable.Data = {'None', 'continuous', '', '', false, 'N/A'};
        end
        
        function sync_variables_format_from_main_app(app)
            
            % Get variables from main app
            varNames = fieldnames(app.MainApp.Variables);
        
            % Add 'None' as a first option
            vars = [{'None'}; varNames];
        
            % Update only the first column format to reflect these options
            app.OptimisedVariablesTable.ColumnFormat{1} = vars;
        
            % Optionally, update the first column of existing rows if needed
            for i = 1:size(app.OptimisedVariablesTable.Data,1)
                if ~ismember(app.OptimisedVariablesTable.Data{i,1}, vars)
                    app.OptimisedVariablesTable.Data{i,1} = 'None';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            % Stocke une référence vers l'application principale
            app.MainApp = main_app;
        
            % Centrer l'UI
            disp_centered_app(app);
        
            % Initialiser et configurer le tableau des variables d'optimisation
            update_handle_variables_table(app);
        
            % Initialisation du tableau des paramètres "profiles"
            app.ProfilesParametersTable.ColumnFormat = {'char', 'char'};
            app.ProfilesParametersTable.ColumnName = {'Lower bound frequency', 'Upper bound frequency'};
            app.ProfilesParametersTable.Data = {'', ''};
        end

        % Button pushed function: AddvariableButton
        function AddvariableButtonPushed(app, event)
            
            % ajoute une ligne dans le tableau des variables muettes
            app.OptimisedVariablesTable.Data = [app.OptimisedVariablesTable.Data; {'None', ''}]; 
        end

        % Button pushed function: AddprofileButton
        function AddprofileButtonPushed(app, event)
            % ajoute une ligne dans le tableau des variables muettes
            app.ProfilesParametersTable.Data = [app.ProfilesParametersTable.Data; {'None', '', '', '', ''}]; 
        end

        % Button pushed function: DeletelastprofileButton
        function DeletelastprofileButtonPushed(app, event)
            app.ProfilesParametersTable.Data = app.ProfilesParametersTable.Data(1:end-1,:); 
        end

        % Menu selected function: ModifynonlinearconstraintsMenu
        function ModifynonlinearconstraintsMenuSelected(app, event)
            if isempty(app.NonLinearConstraintsApp) || ~isvalid(app.NonLinearConstraintsApp)
                app.NonLinearConstraintsApp = Non_Linear_Constraints_App(app);
            else
                app.NonLinearConstraintsApp.UIFigure.Visible = 'on';
            end
        end

        % Menu selected function: ModifyalgorythmparametersMenu
        function ModifyalgorythmparametersMenuSelected(app, event)
            if isempty(app.AlgorithmParametersApp)
                app.AlgorithmParametersApp = app.Algorithm_Parameters_App();
            else
                app.NonLinearConstraintsApp.UIFigure.Visible = 'on';
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.UIFigure.Visible = 'off';


        end

        % Cell edit callback: OptimisedVariablesTable
        function OptimisedVariablesTableCellEdit(app, event)
            
            % Récupérer Informations de l'événement
            row = event.Indices(1);
            col = event.Indices(2);
        
            % Vérifier si modification dans la colonne 'Type' (colonne 2)
            if col ~= 2
                return;
            end
        
            % Récupérer le type choisi (continuous/discrete)
            type = app.OptimisedVariablesTable.Data{row, col};
        
            switch type
                case 'continuous'
                    % Nettoyer et activer les bornes et l'option integer
                    app.OptimisedVariablesTable.Data{row,3} = '';    % Lower Bound
                    app.OptimisedVariablesTable.Data{row,4} = '';    % Upper Bound
                    app.OptimisedVariablesTable.Data{row,5} = false; % Integer
                    app.OptimisedVariablesTable.Data{row,6} = 'N/A';    % Accepted Values
                    
                    % Colonnes éditables : Variable, Type, LB, UB, Integer, AcceptedValues
                    app.OptimisedVariablesTable.ColumnEditable = [true true true true true false];
        
                case 'discrete'
                    % Désactiver les bornes et activer les valeurs admissibles
                    app.OptimisedVariablesTable.Data{row,3} = 'N/A'; % Lower
                    app.OptimisedVariablesTable.Data{row,4} = 'N/A'; % Upper
                    app.OptimisedVariablesTable.Data{row,5} = false; % Integer
                    app.OptimisedVariablesTable.Data{row,6} = '[ ]'; % Accepted Values
                    
                    % Colonnes éditables
                    app.OptimisedVariablesTable.ColumnEditable = [true true false false false true];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ModifynonlinearconstraintsMenu = uimenu(app.Menu);
            app.ModifynonlinearconstraintsMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifynonlinearconstraintsMenuSelected, true);
            app.ModifynonlinearconstraintsMenu.Text = 'Modify non linear constraints';
            app.ModifyalgorythmparametersMenu = uimenu(app.Menu);
            app.ModifyalgorythmparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifyalgorythmparametersMenuSelected, true);
            app.ModifyalgorythmparametersMenu.Text = 'Modify algorythm parameters';
            app.ProfilesParametersTable = uitable(app.UIFigure);
            app.ProfilesParametersTable.ColumnName = '';
            app.ProfilesParametersTable.RowName = {};
            app.ProfilesParametersTable.ColumnEditable = true;
            app.OptimisedVariablesTable = uitable(app.UIFigure);
            app.OptimisedVariablesTable.ColumnName = '';
            app.OptimisedVariablesTable.RowName = {};
            app.OptimisedVariablesTable.ColumnEditable = true;
            app.OptimisedVariablesTable.CellEditCallback = createCallbackFcn(app, @OptimisedVariablesTableCellEdit, true);
            app.AddvariableButton = uibutton(app.UIFigure, 'push');
            app.AddvariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddvariableButtonPushed, true);
            app.AddvariableButton.Text = 'Add variable';
            app.LaunchoptimisatonButton = uibutton(app.UIFigure, 'push');
            app.LaunchoptimisatonButton.Text = 'Launch optimisaton';
            app.AddprofileButton = uibutton(app.UIFigure, 'push');
            app.AddprofileButton.ButtonPushedFcn = createCallbackFcn(app, @AddprofileButtonPushed, true);
            app.AddprofileButton.Text = 'Add profile';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.DeletelastprofileButton = uibutton(app.UIFigure, 'push');
            app.DeletelastprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastprofileButtonPushed, true);
            app.DeletelastprofileButton.Text = 'Delete last profile';
            app.DeletelastvariableButton = uibutton(app.UIFigure, 'push');
            app.DeletelastvariableButton.Text = 'Delete last variable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Optimisation_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end