classdef Subelement_Name_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SubelementNameUIFigure  matlab.ui.Figure
        OKButton                matlab.ui.control.Button
        NewnameEditField        matlab.ui.control.EditField
        NewnameEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        Done
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, done)
            app.CallingApp = mainApp;
            app.Done = done;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            config_name = strtrim(app.NewnameEditField.Value);
            if config_name ~= "Manual"
                parameters_pannels = fieldnames(app.CallingApp.UIComponents);
                for i = 1:length(parameters_pannels)
                    if app.CallingApp.UIComponents.(parameters_pannels{i}).ParametersPanel.Visible == "on"
                        already_exists = false;
                        config = fieldnames(app.CallingApp.UIComponents.(parameters_pannels{i}).Configurations);
                        for j = 1:length(config)
                            if strcmp(app.CallingApp.UIComponents.(parameters_pannels{i}).Configurations.(config{j}).Name , config_name)
                                already_exists = true;
                                config_rank = config{j};
                            end
                        end
                        param_list = [];
                        for j = 1:length(app.CallingApp.UIComponents.(parameters_pannels{i}).ParameterEditField)
                            param_list = [param_list, app.CallingApp.UIComponents.(parameters_pannels{i}).ParameterEditField{j}.Value];
                        end
                        if already_exists
                            overwrite = questdlg('This configuration already exists. Do you want to overright the existing configuration?', 'Overwrite','Yes','No');
                            switch overwrite
                                case 'Yes'
                                    app.CallingApp.UIComponents.(parameters_pannels{i}).Configurations.(config_rank).Name = config_name;
                                    app.CallingApp.UIComponents.(parameters_pannels{i}).Configurations.(config_rank).Value = param_list;

                                    app.CallingApp.SaveAsConfig(event)
                                    delete(app)
                                    return
                                case 'No'

                            end
                        else
                            new_config_number=num2str(str2double(regexp(config{end},'\d*','Match'))+1);
                            app.CallingApp.UIComponents.(parameters_pannels{i}).Configurations.("Config"+num2str(new_config_number)).Name = config_name;
                            app.CallingApp.UIComponents.(parameters_pannels{i}).Configurations.("Config"+num2str(new_config_number)).Value = param_list;
                            app.CallingApp.UIComponents.(parameters_pannels{i}).ConfigDropDown.Items{end+1} = config_name;
                            app.CallingApp.UIComponents.(parameters_pannels{i}).ConfigDropDown.Value = config_name;
                            
                            app.CallingApp.SaveAsConfig(event)
                            delete(app)
                            return
                        end
                    end
                end
            else
                msgbox("You cannot name this config 'Manual'",'Error','error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SubelementNameUIFigure = uifigure('Visible', 'off');
            app.SubelementNameUIFigure.Name = 'Subelement Name';
            app.NewnameEditFieldLabel = uilabel(app.SubelementNameUIFigure);
            app.NewnameEditFieldLabel.Text = 'New name';
            app.NewnameEditField = uieditfield(app.SubelementNameUIFigure, 'text');
            app.OKButton = uibutton(app.SubelementNameUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.SubelementNameUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Subelement_Name_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SubelementNameUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SubelementNameUIFigure)
        end
    end
end