classdef Appwithfigure_clement_2023_07_17 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ManageSettingsMenu          matlab.ui.container.Menu
        PresettingsMenu             matlab.ui.container.Menu
        InputSurfaceAreasMenu       matlab.ui.container.Menu
        ComputeButton               matlab.ui.control.Button
        DeleteSubelementButton      matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        SublementtypeDropDown       matlab.ui.control.DropDown
        SublementtypeDropDownLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SubelementDatas % structure with all the subelements
        UIComponents % Visual components of the app
        ElementInputSurfacesApp % Pop up app when a new element is created (get the input surface area of the element)
        ElementDatas % structure with all the elements
        PresettingsApp % cf. "Presettings.mlapp"
        FairingWidth
        EPCSurface
        AirParameters % structure composed of air tem
        Computing = 0 % used to know if the EPC is completed (if Compute button was pushed)
        SubelementChosen = "" % name of the  class of the subelement chosen in the Drop Down (defined in SubelementtypeDropDownValueChanged, useful for SaveButtonPushed)
    end
    methods (Access = private)
        
%%%%%%%%%%%%%%%%%% IF YOU LEFT CLICK ON A SUBELEMENT %%%%%%%%%%%%%%%%%%%%%%   
        function output = subelementLeftClick(app,~,event_obj) 
             % ~         Currently not used (empty)
             % event_obj Object containing event SubelementDatas structure
             % output    SubelementDatas cursor output
            
            hold(app.UIAxes,"on");
            current_position = event_obj.Position; %return the position [x, y]
                
            fnames = fieldnames(app.SubelementDatas);      
            for i = 1:length(fnames)
                
                subelement_name = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement_name.Position;


        %%%%%%%%%%%%%%%%% Add a new subelement %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if all(coordinates == current_position) && subelement_name.Name =="None"
                    %modified + -> s
                    app.SubelementDatas.(fnames{i}).Name ='Undefined';
                    app.SubelementDatas.(fnames{i}).Marker ='s';
                    app.SubelementDatas.(fnames{i}).Color ='#000000';
                    app.UIAxes.XTick = [app.UIAxes.XTick,length(app.UIAxes.XTick)];
                    app.UIAxes.XTickLabel{end+1} = char(length(app.UIAxes.XTickLabel)+64);

                    %create a new + 
                    newsubelementnumber=num2str(str2double(regexp(fnames{end},'\d*','Match'))+1);
                    app.SubelementDatas.("Subelement"+newsubelementnumber).Name ='None';
                    app.SubelementDatas.("Subelement"+newsubelementnumber).Marker ='+';
                    app.SubelementDatas.("Subelement"+newsubelementnumber).Position = [coordinates(1)+1 coordinates(2)];
                    app.SubelementDatas.("Subelement"+newsubelementnumber).Color ='#000000';
                    

                     %add the last + on the right
                    plot(app.UIAxes,coordinates(1)+1,coordinates(2),'k','Marker','+','MarkerSize',15,'LineWidth',2,'Color','#000000');
                    %Add a new + under to create a new element if wanted 
                    if coordinates(1) == 1 
                        
                        app.ElementDatas.NumberOfElements = app.ElementDatas.NumberOfElements+1;
                        app.ElementDatas.ListOfInputSurfaces = [app.ElementDatas.ListOfInputSurfaces, 0];
                        newsubelementnumber=num2str(str2double(regexp(fnames{end},'\d*','Match'))+2);
                        app.SubelementDatas.("Subelement"+newsubelementnumber).Name ='None';
                        app.SubelementDatas.("Subelement"+newsubelementnumber).Marker ='+';
                        app.SubelementDatas.("Subelement"+newsubelementnumber).Position = [coordinates(1) coordinates(2)-1];
                        app.SubelementDatas.("Subelement"+newsubelementnumber).Color ='#000000';
                        app.UIAxes.YTick = [-(length(app.UIAxes.YTick)+1),app.UIAxes.YTick];
                        app.UIAxes.YTickLabel = {-app.UIAxes.YTick};
                    
                        set(app.UIAxes,'YLim',[coordinates(2)-2 0]);
                        plot(app.UIAxes,coordinates(1),coordinates(2)-1,'k','Marker','+','MarkerSize',15,'LineWidth',2,'Color','#000000');
                    end
                    
                end
        %%%%%%%%%%%%% Clicking on a subelement make its name appear on the graph %%%%%%%%%%   
                if all(coordinates == current_position)% si curseur sur cet élément
                    output={getfield(app.SubelementDatas,fnames{i}).Name};%mouse text

                end
            end


        %%%%%%%%%%%%% draw the subelements on the graph %%%%%%%%%%%%%%%%%%%%%%%%%%%
            % fnames_old = fnames;%the names of the previous item
            
            fnames = fieldnames(app.SubelementDatas);%a new subelement has been added
            xmax = 0;
            ymax = 0;
            for i = 1:length(fnames)%parcourt notre nouvelle strucutre 
                fi_coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                for j = 1:length(app.UIAxes.Children)%parcourt les points du graph
                    plot_coordinates = [app.UIAxes.Children(j).XData app.UIAxes.Children(j).YData];
                    if all(fi_coordinates==plot_coordinates)%on regarde si ça correspond
                        marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                        set(app.UIAxes.Children(j),'Marker',marker)
                    end      
                end
                xmax = max(xmax,fi_coordinates(1));
                ymax = min(ymax,fi_coordinates(2));
            end
            set(app.UIAxes,'XLim',[0.3 xmax+0.5]) ;
            set(app.UIAxes,'YLim',[ymax-0.5 -0.3]);
        
        %%%%%%%%%%%%% Make the corresponding panel appear with actual values on the right %%%%%%%%%
            fnames = fieldnames(app.SubelementDatas);
           
            for i = 1:length(fnames)
                subelement = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement.Position;
                subelement_name = getfield(app.SubelementDatas,fnames{i}).Name;
                if all(coordinates == current_position)  
                    
                    if subelement_name =="None" || subelement_name == "Undefined"
                        app.SublementtypeDropDown.Value = 'Type';
                    else
                        app.SublementtypeDropDown.Value = getfield(app.SubelementDatas,fnames{i}).Name;
                        
                    end
                    app.SublementtypeDropDownValueChanged(NaN);
                    
                end
            end

        end


%%%%%%%%%%%%%%%%%%%%%%%% DELETE SUBELEMENT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function deletesubelement(app,current_position) 
             %delete the selected element
            

            fnames = fieldnames(app.SubelementDatas);
            
            issubelementdeleted = false;
            for i = 1:length(fnames)
                 % make sure the subelement called exists because some are deleted in the loop
                if isfield(app.SubelementDatas,fnames{i}) && not(issubelementdeleted)
                    subelement = getfield(app.SubelementDatas,fnames{i});
                    coordinates =  subelement.Position;
                    if all(coordinates == current_position)
                        issubelementdeleted = true;%the selected subelementdeleted is going to be deleted
                        if subelement.Name ~= "None"% Make sure no + can be deleted
                            app.SubelementDatas = rmfield(app.SubelementDatas,fnames{i});% Delete the selected subelement
                            app.SubelementDatas
                            new_fnames = fieldnames(app.SubelementDatas);
                            
                            go_to_other_case = false;
                            if coordinates(1) == 1  %if a single-subelement element is deleted, delete the subelement, and both the + around. then create a +
                                % make sure the case is the right one

                                    for j = 1 : length(new_fnames)
                                        other_subelements = getfield(app.SubelementDatas,new_fnames{j});
                                        if all(other_subelements.Position ==[coordinates(1)+1 coordinates(2)]) && other_subelements.Name=="None"%delete subelement  on the right 
                                            app.SubelementDatas = rmfield(app.SubelementDatas,new_fnames{j});
                                            % go_to_other_case = true;
                                        elseif all(other_subelements.Position==[coordinates(1) coordinates(2)-1]) && other_subelements.Name=="None"%delete subelement under if it is a none
                                            app.SubelementDatas = rmfield(app.SubelementDatas,new_fnames{j});
                                        end       
                                        if all(other_subelements.Position==[coordinates(1) coordinates(2)-1]) && other_subelements.Name~="None"
                                            %create a new + except if the subelement is in the first column and between two other subelemnts
                                            go_to_other_case = true;
                                        end

                                    end

                                    %create a new + except if the subelement is in the first column and between two other subelemnts
                                    if not(go_to_other_case)
                                        newsubelementnumber=num2str(str2double(regexp(fnames{end},'\d*','Match'))+1);
                                        app.SubelementDatas.("Subelement"+newsubelementnumber).Name ='None';
                                        app.SubelementDatas.("Subelement"+newsubelementnumber).Marker ='+';
                                        app.SubelementDatas.("Subelement"+newsubelementnumber).Position = [coordinates(1) coordinates(2)];
                                        app.SubelementDatas.("Subelement"+newsubelementnumber).Color ='#000000';                                    
                                        plot(app.UIAxes,coordinates(1),coordinates(2),'+','MarkerSize',15,'LineWidth',2,"Color",'#000000');
                                    else %supress the empty line
                                        new_fnames = fieldnames(app.SubelementDatas);
                                        for j = 1 : length(new_fnames)%all the subelements on the right of the deleted subelement go 1 to the left
                                            under_subelement = getfield(app.SubelementDatas,new_fnames{j});
                                            if under_subelement.Position(2) < subelement.Position(2)
                                                % eval("app.SubelementDatas."+new_fnames{j}+".Position(2)=app.SubelementDatas."+new_fnames{j}+".Position(2) + 1;");
                                                % app.SubelementDatas."+new_fnames{j}+".Position(2)=app.SubelementDatas."+new_fnames{j}+".Position(2) + 1;
                                                app.SubelementDatas.(new_fnames{j}).Position(2) = app.SubelementDatas.(new_fnames{j}).Position(2) +1;
                                            end
                                        end
                                    end
        
                            else % case when the subelement isn't the first one on the line
                                for j = 1 : length(new_fnames)%all the subelements on the right of the deleted subelement go 1 to the left
                                    right_subelement = getfield(app.SubelementDatas,new_fnames{j});
                                    if right_subelement.Position(1) > subelement.Position(1)
                                        % eval("app.SubelementDatas."+new_fnames{j}+".Position(1)=app.SubelementDatas."+new_fnames{j}+".Position(1) - 1;");
                                        app.SubelementDatas.(new_fnames{j}).Position(1) = app.SubelementDatas.(new_fnames{j}).Position(1) -1;
                                    end
                                end
                            end
                        end
                    end

                end
            end
            
            % Reset axis
            xmax = 0;
            ymax = 0;
            fnames = fieldnames(app.SubelementDatas);      
            for i = 1:length(fnames)
                if i==1
                    hold(app.UIAxes,'off')
                elseif i==2
                    hold(app.UIAxes,'on')
                end
                coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2)
                xmax = max(xmax,coordinates(1));
                ymax = min(ymax,coordinates(2));
            end
            % resize the axis
            set(app.UIAxes,'XLim',[0 xmax+0.3]) 
            set(app.UIAxes,'YLim',[ymax-0.3 0])

            %If no element yet (only a +)
            % if length(fnames)==1
            %     app.InputSurfaceAreasMenuSelected.Enable = "off";
            % end

        end


            %             draw the subelements

%%%%%%%%%%%%%%%% CREATE PARAMETERS PANNELS MORE EASILY %%%%%%%%%%%%%%%%%%%%       
        function param_panel = plotParametersPanel(app,number_of_param,name_of_param,unit_of_param)
            %Create parameters panel under the subelement type selection
            Parameters.ParametersPanel = uipanel(app.Panel);
            Parameters.ParametersPanel.Visible = 'off';
            Parameters.ParametersPanel.Position = [11 10 417 325];
            %Parameters Title
            Parameters.PanelTitle = uilabel(Parameters.ParametersPanel);
            Parameters.PanelTitle.BackgroundColor = [0.8 0.8 0.8];
            Parameters.PanelTitle.HorizontalAlignment = 'center';
            Parameters.PanelTitle.FontWeight = 'bold';
            Parameters.PanelTitle.Position = [10 292 394 22];
            Parameters.PanelTitle.Text = 'PARAMETERS';

            if number_of_param >= 1
                for i = 1:number_of_param
                    %Parameter label
                    Parameters.ParameterLabel{i}=uilabel(Parameters.ParametersPanel);
                    Parameters.ParameterLabel{i}.HorizontalAlignment = 'right';
                    Parameters.ParameterLabel{i}.Position = [48 250-32*(i-1) 126 32];
                    Parameters.ParameterLabel{i}.Text = name_of_param(i);
                    Parameters.ParameterLabel{i}.WordWrap = "on";

                    %Parameter Edit Field
                    Parameters.ParameterEditField{i} = uieditfield(Parameters.ParametersPanel,'numeric');
                    Parameters.ParameterEditField{i}.Position = [180 256-32*(i-1) 100 22];

                    %Parameter unit
                    Parameters.ParameterUnit{i} = uilabel(Parameters.ParametersPanel);
                    Parameters.ParameterUnit{i}.Position = [285 256-32*(i-1) 50 22];
                    Parameters.ParameterUnit{i}.Text = unit_of_param(i);
                    
                end
            end
            Parameters.SaveButton = uibutton(Parameters.ParametersPanel,'push');
            Parameters.SaveButton.ButtonPushedFcn = createCallbackFcn(app,@SaveButtonPushed,true);
            Parameters.SaveButton.Position = [335 9 75 22];
            Parameters.SaveButton.Text = "Save";

            
            param_panel = Parameters;
        end
        
    end
    
    methods (Access = public)


        function updateInputSurfaces(app,list_of_input_surfaces)
            app.ElementDatas.ListOfInputSurfaces = list_of_input_surfaces;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, presettings_app, fairing_width, EPC_surface, temperature, static_pressure, relative_humidity)
            %Import object classes
            addpath("D:\ETS\Acoustique\Blocs\Classes")
            app.PresettingsApp = presettings_app;
            app.EPCSurface = EPC_surface;
            app.FairingWidth = fairing_width;
            
            %air parameters
            app.AirParameters.Temperature = temperature;
            app.AirParameters.StaticPressure = static_pressure;
            app.AirParameters.RelativeHumidity = relative_humidity;

            app.ElementDatas.NumberOfElements = 0; % No Element at the beginnig 
            app.ElementDatas.ListOfInputSurfaces = [];
            %plot initialisation
            app.SubelementDatas.Subelement1.Name = "None";
            app.SubelementDatas.Subelement1.Marker = '+';
            app.SubelementDatas.Subelement1.Position = [1 -1];

%             app.SubelementDatas.Subelement2.Name = "None";
%             app.SubelementDatas.Subelement2.Marker = '+';
%             app.SubelementDatas.Subelement2.Position = [1 -2];


%             draw the subelements

            hold( app.UIAxes, 'on' )
            fn = fieldnames(app.SubelementDatas);      
            for i = 1:length(fn)
                coordinates = eval( "app.SubelementDatas."+fn{i}+".Position");
                marker = eval( "app.SubelementDatas."+fn{i}+".Marker");
                plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2)
            end

            % active datacursormode (selection mode)
            d = datacursormode(app.UIFigure);
            d.Enable = 'on';
            d.UpdateFcn = @app.subelementLeftClick;


            % Set every Parameters Panel :
            % - Porous Material (Rigid)
            app.UIComponents.RigidPorousMaterial = plotParametersPanel(app,6,["Porosity","Airflow Resistivity","Tortuosity","Viscous Characteristic Length","Thermal Characteristic Length","Thickness"],["%","Pa.s/m2","(no unit)","m","m","m"]);
            % - Porous Material (Limp)
            app.UIComponents.LimpPorousMaterial = plotParametersPanel(app,7,["Porosity","Airflow Resistivity","Tortuosity","Viscous Characteristic Length","Thermal Characteristic Length","Thickness","Material Density"],...
                                                                            ["%","Pa.s/m2","(no unit)","m","m","m","kg/m3"]);

            % - MPP (Circular)
            app.UIComponents.CircularMPP = plotParametersPanel(app,3,["Porosity","Thickness","Radius"],["%","m","m"]);
            % - MPP (Slit)
            app.UIComponents.SlitMPP = plotParametersPanel(app,3,["Porosity","Thickness","Width"],["%","m","m"]);
            % - MPP (Rectangular)
            app.UIComponents.RectangularMPP = plotParametersPanel(app,4,["Porosity","Thickness","Length","Width"],["%","m","m","m"]);

            % - Helmholtz Resonator (Free geometry)
            app.UIComponents.HR1 = plotParametersPanel(app,3,["Neck Surface","Neck Length","Cavity Volume"],["m2","m","m3"]);
            % - Helmholtz Resonator (Cylindrical)
            app.UIComponents.HR2 = plotParametersPanel(app,4,["Neck Radius","Neck Depth","Cavity Radius","Cavity Depth"],["m","m","m","m"]);

            % - Resistive Screen
            app.UIComponents.Screen = plotParametersPanel(app,2,["Screen Density","Thickness"],["kg/m3","m"]);

            % - Grid
            app.UIComponents.Grid = plotParametersPanel(app,4,["Mesh Length","Mesh Width","Thickness","Porosity"],["m","m","m","%"]);

            % - Quarter Wavelength Resonator
            app.UIComponents.QWL = plotParametersPanel(app,3,["Main Radius","Resonator Radius","Resonator Length"],["m","m","m"]);

            % - Cavity
            app.UIComponents.Cavity = plotParametersPanel(app,1,["Length"],["m"]);
            
            % - Section Change
            app.UIComponents.SectionChange = plotParametersPanel(app,2,["Input Surface","Output Surface"],["m2","m2"]);


        end

        % Button pushed function: DeleteSubelementButton
        function DeleteSubelementButtonPushed(app, event)
            try
                d = datacursormode(app.UIFigure);
                app.deletesubelement(d.getCursorInfo.Position);
            catch
                 warndlg("Select a valid subelement")   
            end
           

        end

        % Value changed function: SublementtypeDropDown
        function SublementtypeDropDownValueChanged(app, event)
            value = app.SublementtypeDropDown.Value;
            if strcmp(value,'Porous Material (Rigid)')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "on";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classJCA";
            elseif strcmp(value,'Porous Material (Limp)')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "on";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classJCA";

            elseif strcmp(value,'MPP (Circular)')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "on";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classMPP";

            elseif strcmp(value,'MPP (Slits)')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "on";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classMPP";
            elseif strcmp(value,'MPP (Rectangular)')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "on";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classMPP";
            elseif strcmp(value,'Helmholtz Resonator (Free Geometry)')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "on";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classHR1";
            elseif strcmp(value,'Helmholtz Resonator (Cylindrical)')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "on";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classHR2";
            elseif strcmp(value,'Resistive Screen')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "on";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classscreen";
            elseif strcmp(value,'Protective Grid')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "on";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classgrid";
            elseif strcmp(value,'Quarter Wavelength Resonator')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "on";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classQWL";
            elseif strcmp(value,'Cavity')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "on";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "classcavity";
            elseif strcmp(value,'Section Change')
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "on";
                app.SubelementChosen = "classsectionchange";
            else
                app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR1.ParametersPanel.Visible = "off";
                app.UIComponents.HR2.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
                app.UIComponents.Grid.ParametersPanel.Visible = "off";
                app.UIComponents.QWL.ParametersPanel.Visible = "off";
                app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                app.UIComponents.SectionChange.ParametersPanel.Visible = "off";
                app.SubelementChosen = "";
            end

        end

        % Button down function: UIFigure
        function SaveButtonPushed(app, event)
        %create corresponding objects
            % create list of selected parameters
            param_list = {app.SublementtypeDropDown.Value};
            parameters_pannels = fieldnames(app.UIComponents);
            for i = 1:length(parameters_pannels)
                if app.UIComponents.(parameters_pannels{i}).Visible == "on"
                    ui_components = fieldnames(app.UIComponents.(parameters_pannels{i}));
                    for j = 1:length(ui_components)
                        if isa(app.UIComponents.(parameters_pannels{i}).(ui_components{i}),'matlab.ui.control.NumericEditField')
                            param_list{end+1} = app.UIComponents.(parameters_pannels{i}).(ui_components{i}).Value;
                        end
                    end
                end
            end
            % Porous Material (Rigid)
            if app.SublementtypeDropDown.Value == "Porous Material (Rigid)"
                eval_text = "classJCA(";
                for i=2:length(param_list)
                    eval_text = eval_text+num2str(app.UIComponents.RigidPorousMaterial.ParameterEditField{i}.Value)+","; 
                end
                if app.UIComponents.PorousMaterial.RigidButton.Value == 1
                    eval_text = eval_text+");";
                else
                    eval_text = eval_text+"'limp',app.UIComponents.PorousMaterial.MaterialDensityEditField.Value);";
                end
                color = '#7E2F8E'; % Actually used but in an 'eval' function
            end


            % Case of Helmholtz Resonator
            if app.SublementtypeDropDown.Value == "Helmholtz Resonator"
                eval_text = "classHR2(";
                values = {};
                for i=1:length(app.UIComponents.HR.ParameterLabel)-1
                    eval_text = eval_text+num2str(app.UIComponents.HR.ParameterEditField{i}.Value)+",";
                    values{end+1} = app.UIComponents.HR.ParameterEditField{i}.Value;
                end
                eval_text = eval_text+num2str(app.UIComponents.HR.ParameterEditField{end}.Value)+");";
                color = '#0072BD'; % Actually used but in an 'eval' function
            end


            % Case of Resistive Screen
            if app.SublementtypeDropDown.Value == "Resistive Screen"
                eval_text = "classscreen(";
                for i=1:length(app.UIComponents.Screen.ParameterLabel)-1
                    eval_text = eval_text+num2str(app.UIComponents.Screen.ParameterEditField{i}.Value)+","; 
                end
                eval_text = eval_text+num2str(app.UIComponents.Screen.ParameterEditField{end}.Value)+");";
                color = '#EDB120'; % Actually used but in an 'eval' function
            end


        %add the object to the list of subelements
            d = datacursormode(app.UIFigure);
            current_position = d.getCursorInfo.Position; 

            fnames = fieldnames(app.SubelementDatas);      
            for i = 1:length(fnames)
                subelement = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement.Position;

                if all(coordinates == current_position) && subelement.Name ~="None"
                    eval( "app.SubelementDatas."+fnames{i}+".Name ="+"app.SublementtypeDropDown.Value");
                    eval( "app.SubelementDatas."+fnames{i}+".Marker ='o'");
                    eval( "app.SubelementDatas."+fnames{i}+".Color ="+"color");
                    eval( "app.SubelementDatas."+fnames{i}+".Object ="+eval_text);
                    app.SubelementDatas.(fnames{i}).ParamList = param_list; % {type of subelement ()}{Param 1}{...}
                    
                end
            end

%             draw the subelements
            fnames = fieldnames(app.SubelementDatas);%a new subelement has been added
            for i = 1:length(fnames)%parcourt notre strucutre
                fi_coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                for j = 1:length(app.UIAxes.Children)%parcourt les points du graph
                    plot_coordinates = [app.UIAxes.Children(j).XData app.UIAxes.Children(j).YData];
                    if all(fi_coordinates==plot_coordinates)%on regarde si ça correspond
                        marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                        color = getfield(app.SubelementDatas,fnames{i}).Color;
                        set(app.UIAxes.Children(j),'Marker',marker,'Color',color)
%                         d = datacursormode(app.UIFigure);
%                         d.UpdateFcn = @app.addsubelement;
                    end
                end
            end
        end

        % Menu selected function: InputSurfaceAreasMenu
        function InputSurfaceAreasMenuSelected(app, event)
            app.InputSurfaceAreasMenu.Enable = 'off';
            app.Computing = 0;
            app.ElementInputSurfacesApp = Element_Input_Surface_v2(app,app.ElementDatas.NumberOfElements,app.ElementDatas.ListOfInputSurfaces); % Set input surface of the new element

        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % Opens the Input Surface Area app to set up the input settings
            error = 0;
            fnames = fieldnames(app.SubelementDatas);
            for i = 1:length(fnames) % return error message as soon as one subelement is undefined
                name = getfield(app.SubelementDatas, fnames{i}).Name;
                
                if name == "Undefined" && error == 0

                    error = 1;
                end
            end
            if error == 1
                msgbox("One or several subelements are undefined","Error","error");
            else
                app.InputSurfaceAreasMenu.Enable = "off";
                app.Panel.Enable = "off";
                app.DeleteSubelementButton.Enable = "off";
                app.Computing = 1;
                app.ElementInputSurfacesApp = Element_Input_Surface_v2(app,app.ElementDatas.NumberOfElements,app.ElementDatas.ListOfInputSurfaces); % Set input surface of the new element
            end


        end

        % Menu selected function: PresettingsMenu
        function PresettingsMenuSelected(app, event)
            app.PresettingsMenu.Enable = 'off';
            app.ElementInputSurfacesApp = Presettings(app,app.EPCSurface,app.FairingWidth,app.AirParameters.Temperature,app.AirParameters.StaticPressure,app.AirParameters.RelativeHumidity); % Modify presettings

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.ElementInputSurfacesApp)
            delete(app.PresettingsApp)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.ManageSettingsMenu = uimenu(app.UIFigure);
            app.ManageSettingsMenu.Text = 'Manage Settings';
            app.PresettingsMenu = uimenu(app.ManageSettingsMenu);
            app.PresettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @PresettingsMenuSelected, true);
            app.PresettingsMenu.Text = 'Presettings';
            app.InputSurfaceAreasMenu = uimenu(app.ManageSettingsMenu);
            app.InputSurfaceAreasMenu.MenuSelectedFcn = createCallbackFcn(app, @InputSurfaceAreasMenuSelected, true);
            app.InputSurfaceAreasMenu.Enable = 'off';
            app.InputSurfaceAreasMenu.Text = 'Input Surface Areas';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, {'Wave'; '||'; '\ || /'; '\/'})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-3 0];
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.XTick = [0 1];
            app.UIAxes.XTickLabel = {''; 'A'};
            app.UIAxes.YTick = -1;
            app.UIAxes.YTickLabel = {'1'};
            app.Panel = uipanel(app.UIFigure);
            app.SublementtypeDropDownLabel = uilabel(app.Panel);
            app.SublementtypeDropDownLabel.Text = 'Sublement type';
            app.SublementtypeDropDown = uidropdown(app.Panel);
            app.SublementtypeDropDown.Items = {'Type', 'Porous Material (Rigid)', 'Porous Material (Limp)', 'MPP (Circular)', 'MPP (Slits)', 'MPP (Rectangular)', 'Helmholtz Resonator (Free Geometry)', 'Helmholtz Resonator (Cylindrical)', 'Resistive Screen', 'Protective Grid', 'Quarter Wavelength Resonator', 'Cavity', 'Section Change'};
            app.SublementtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SublementtypeDropDownValueChanged, true);
            app.SublementtypeDropDown.Value = 'Type';
            app.DeleteSubelementButton = uibutton(app.UIFigure, 'push');
            app.DeleteSubelementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubelementButtonPushed, true);
            app.DeleteSubelementButton.Text = 'Delete Subelement';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appwithfigure_clement_2023_07_17(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end