classdef Element_Input_Surface_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputSettingsUIFigure  matlab.ui.Figure
        BackButton             matlab.ui.control.Button
        ContinueButton         matlab.ui.control.Button
        INPUTSETTINGSLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        UIComponents %all the componans of the dialogue box
        MainApp % Main App 
        NumberOfElements
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, number_of_elements, list_of_input_surfaces)
            addpath("E:\ETS\Acoustique\AppBell\Functions")
            
            app.MainApp = main_app;
            app.NumberOfElements = number_of_elements;

            for i = 1:number_of_elements
                app.InputSettingsUIFigure.Position(4) = app.InputSettingsUIFigure.Position(4) + 32;
                app.INPUTSETTINGSLabel.Position(2) = app.INPUTSETTINGSLabel.Position(2) + 32;
                % Create EditFieldLabel
                app.UIComponents.("ElementLabel"+num2str(i)) = uilabel(app.InputSettingsUIFigure);
                app.UIComponents.("ElementLabel"+num2str(i)).HorizontalAlignment = 'right';
                app.UIComponents.("ElementLabel"+num2str(i)).Position = [25 43+32*(number_of_elements-i) 56 22];
                app.UIComponents.("ElementLabel"+num2str(i)).Text = convertCharsToStrings('Element ') +num2str(i);
    
                % Create EditField
                app.UIComponents.("ElementEditField"+num2str(i)) = uieditfield(app.InputSettingsUIFigure, 'numeric');
                app.UIComponents.("ElementEditField"+num2str(i)).Position = [96 43+32*(number_of_elements-i) 100 22];
                app.UIComponents.("ElementEditField"+num2str(i)).Value = list_of_input_surfaces(i);
    
                % Create m2Label
                app.UIComponents.("m2Label"+num2str(i)) = uilabel(app.InputSettingsUIFigure);
                app.UIComponents.("m2Label"+num2str(i)).Position = [202 43+32*(number_of_elements-i) 28 22];
                app.UIComponents.("m2Label"+num2str(i)).Text = 'm^2';
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            list_of_input_surfaces = zeros([1 app.NumberOfElements]);
            for i = 1:app.NumberOfElements
                eval("list_of_input_surfaces(i) = app.UIComponents.ElementEditField"+num2str(i)+".Value");
            end

            if app.MainApp.Computing
                list_of_elements = [];
                for ii = 1:length(app.MainApp.UIAxes.YTick)
                    list_of_subelements = {};
                    for jj = 1:length(app.MainApp.UIAxes.XTick)
                        fnames = fieldnames(app.MainApp.SubelementDatas);%
                        for fi = 1:length(fnames)%parcourt notre strucutre
                            if not(ismember(getfield(app.MainApp.SubelementDatas,fnames{fi},"Name"),["None" "Undefined"]))
                                Position = getfield(app.MainApp.SubelementDatas,fnames{fi},"Position");
                                if Position(1)==app.MainApp.UIAxes.XTick(jj)+1 && Position(2)==app.MainApp.UIAxes.YTick(ii)+1 
                                    list_of_subelements{end+1}=getfield(app.MainApp.SubelementDatas,fnames{fi},"Object");
                                end
                            end
                        end
                    end
                    if not(length(app.MainApp.UIAxes.XTick)==1) && ~isempty(list_of_subelements)% Create element and put it in the list of elements
                        new_element = Element(list_of_input_surfaces(ii),list_of_subelements,'open');
                        thickness_error = false;
                        if new_element.ElementThickness > app.MainApp.FairingWidth
                            msgbox("Element "+num2str(ii)+" is too thick","Error","error")
                            thickness_error = true;
                        end
                        list_of_elements = [list_of_elements,new_element];
                    end               
                end
                if not(thickness_error)
                    %compute the normal sound absorption coeffecient
                    absorptionCoefGraph(app.MainApp,300,4000,list_of_elements)
                end
            end
            
                updateInputSurfaces(app.MainApp,list_of_input_surfaces)
                app.MainApp.InputSurfaceAreasMenu.Enable = 'on';
                app.MainApp.Panel.Enable = "on";
                app.MainApp.DeleteSubelementButton.Enable = "on";
                app.MainApp.ContinueButton.Enable = "on";
                app.MainApp.Computing = 0; 
                delete(app)
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.MainApp.InputSurfaceAreasMenu.Enable = 'on';
            app.MainApp.Panel.Enable = "on";
            app.MainApp.DeleteSubelementButton.Enable = "on";
            app.MainApp.ContinueButton.Enable = "on";
            app.MainApp.Computing = 0; 
            delete(app)
        end

        % Close request function: InputSettingsUIFigure
        function InputSettingsUIFigureCloseRequest(app, event)
            app.MainApp.InputSurfaceAreasMenu.Enable = 'on';
            app.MainApp.Panel.Enable = "on";
            app.MainApp.DeleteSubelementButton.Enable = "on";
            app.MainApp.ContinueButton.Enable = "on";
            app.MainApp.Computing = 0; 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputSettingsUIFigure = uifigure('Visible', 'off');
            app.InputSettingsUIFigure.Name = 'Input Settings';
            app.InputSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @InputSettingsUIFigureCloseRequest, true);
            app.INPUTSETTINGSLabel = uilabel(app.InputSettingsUIFigure);
            app.INPUTSETTINGSLabel.Text = 'INPUT SETTINGS';
            app.ContinueButton = uibutton(app.InputSettingsUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.BackButton = uibutton(app.InputSettingsUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.InputSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Element_Input_Surface_v2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputSettingsUIFigure)
        end
    end
end