classdef Presettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PresettingsUIFigure             matlab.ui.Figure
        AirparametersLabel              matlab.ui.control.Label
        RelativeHumidityEditField       matlab.ui.control.NumericEditField
        RelativeHumidityEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        StaticPressureEditField         matlab.ui.control.NumericEditField
        StaticPressureEditFieldLabel    matlab.ui.control.Label
        PaLabel                         matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        neitherfairingwidthnorEPCSurfacecanbezeroLabel  matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        m2Label                         matlab.ui.control.Label
        EPCSurfaceEditField             matlab.ui.control.NumericEditField
        EPCSurfaceEditFieldLabel        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        FairingWidthEditField           matlab.ui.control.NumericEditField
        FairingWidthEditFieldLabel      matlab.ui.control.Label
        PRESETTINGSLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin1, varargin2, varargin3, varargin4, varargin5, varargin6)
            %this app set fairing and air settings -- it must be opened first
            addpath('E:\EPC Bell App\Files\App_windows')
            % varargin1 means the main app (not used)
            % varargin2 means the EPC surface area
            % varargin3 means the fairing width
            % varargin4 means air temperature
            % varargin5 means air static pressure
            % varargin6 means air relative humidity
            if exist("varargin1","var") 
                app.MainApp = varargin1;
                app.EPCSurfaceEditField.Value = varargin2;
                app.FairingWidthEditField.Value = varargin3;
                app.TemperatureEditField.Value = varargin4;
                app.StaticPressureEditField.Value = varargin5;
                app.RelativeHumidityEditField.Value = varargin6;
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            if app.EPCSurfaceEditField.Value == 0 || app.FairingWidthEditField.Value == 0
                app.neitherfairingwidthnorEPCSurfacecanbezeroLabel.Visible = "on";
            else
                
                temperature = app.TemperatureEditField.Value;
                static_pressure = app.StaticPressureEditField.Value;
                relative_humidity = app.RelativeHumidityEditField.Value;
                if isempty(app.MainApp)
                    app.MainApp = Main_App(app,app.EPCSurfaceEditField.Value,app.FairingWidthEditField.Value,temperature,static_pressure,relative_humidity);
                else
                    app.MainApp.EPCSurface = app.EPCSurfaceEditField.Value;
                    app.MainApp.FairingWidth = app.FairingWidthEditField.Value;
                    app.MainApp.Air.Temperature = temperature;
                    app.MainApp.Air.StaticPressure = static_pressure;
                    app.MainApp.Air.RelativeHumidity = relative_humidity;

                end
                app.ContinueButton.Enable = 'off';
                app.MainApp.PresettingsMenu.Enable = 'on';
                delete(app)
            end
        end

        % Close request function: PresettingsUIFigure
        function PresettingsUIFigureCloseRequest(app, event)
            if ~isempty(app.MainApp)
                app.MainApp.PresettingsMenu.Enable = 'on';
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PresettingsUIFigure = uifigure('Visible', 'off');
            app.PresettingsUIFigure.Name = 'Presettings';
            app.PresettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @PresettingsUIFigureCloseRequest, true);
            app.PRESETTINGSLabel = uilabel(app.PresettingsUIFigure);
            app.PRESETTINGSLabel.Text = 'PRESETTINGS';
            app.FairingWidthEditFieldLabel = uilabel(app.PresettingsUIFigure);
            app.FairingWidthEditFieldLabel.Text = 'Fairing Width';
            app.FairingWidthEditField = uieditfield(app.PresettingsUIFigure, 'numeric');
            app.FairingWidthEditField.Value = 1;
            app.mLabel = uilabel(app.PresettingsUIFigure);
            app.mLabel.Text = 'm';
            app.EPCSurfaceEditFieldLabel = uilabel(app.PresettingsUIFigure);
            app.EPCSurfaceEditFieldLabel.Text = 'EPC Surface';
            app.EPCSurfaceEditField = uieditfield(app.PresettingsUIFigure, 'numeric');
            app.EPCSurfaceEditField.Value = 1;
            app.m2Label = uilabel(app.PresettingsUIFigure);
            app.m2Label.Text = 'm^2';
            app.ContinueButton = uibutton(app.PresettingsUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.neitherfairingwidthnorEPCSurfacecanbezeroLabel = uilabel(app.PresettingsUIFigure);
            app.neitherfairingwidthnorEPCSurfacecanbezeroLabel.Enable = 'off';
            app.neitherfairingwidthnorEPCSurfacecanbezeroLabel.Visible = 'off';
            app.neitherfairingwidthnorEPCSurfacecanbezeroLabel.Text = '*neither fairing width nor EPC Surface can be zero  ';
            app.CLabel = uilabel(app.PresettingsUIFigure);
            app.CLabel.Text = '°C';
            app.TemperatureEditFieldLabel = uilabel(app.PresettingsUIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.PresettingsUIFigure, 'numeric');
            app.TemperatureEditField.Value = 20;
            app.PaLabel = uilabel(app.PresettingsUIFigure);
            app.PaLabel.Text = 'Pa';
            app.StaticPressureEditFieldLabel = uilabel(app.PresettingsUIFigure);
            app.StaticPressureEditFieldLabel.Text = 'Static Pressure';
            app.StaticPressureEditField = uieditfield(app.PresettingsUIFigure, 'numeric');
            app.StaticPressureEditField.Value = 100000;
            app.Label = uilabel(app.PresettingsUIFigure);
            app.Label.Text = '%';
            app.RelativeHumidityEditFieldLabel = uilabel(app.PresettingsUIFigure);
            app.RelativeHumidityEditFieldLabel.Text = 'Relative Humidity';
            app.RelativeHumidityEditField = uieditfield(app.PresettingsUIFigure, 'numeric');
            app.RelativeHumidityEditField.Value = 20;
            app.AirparametersLabel = uilabel(app.PresettingsUIFigure);
            app.AirparametersLabel.Text = 'Air parameters';
            % Show the figure after all components are created
            app.PresettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Presettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PresettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PresettingsUIFigure)
        end
    end
end