classdef Air_Parameters_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AirparametersLabel              matlab.ui.control.Label
        RelativeHumidityEditField       matlab.ui.control.NumericEditField
        RelativeHumidityEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        StaticPressureEditField         matlab.ui.control.NumericEditField
        StaticPressureEditFieldLabel    matlab.ui.control.Label
        PaLabel                         matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        PresetsLabel                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        % the application "Air_Parameters_App.mlapp" request the user to fill in Air parameters 
        % after that another windows is open (another application) : Main_App 
        
        MainApp
        Temperature
        StaticPressure 
        RelativeHumidity
    end
    
    methods (Access = public)
        
        function modify_air_parameters(app, varargin)

            % Cette fonction est une fonction publique qui affiche la fenêtre, les valeurs enregistrées
            % et permet de les modifier

            % varargin = {main_app, ...}

            % On fait apparaitre la fenêtre de dialogue
            app.UIFigure.Visible = "on";

             % Si l'application principale est donnée en entrée
            if nargin > 0

                app.MainApp = varargin{1};
                % On désactive toutes les interactions dans l'application principale 
                % à chaque réouverture de AirParametersWindow
                app.MainApp.ModifyairparametersMenu.Enable = 'off';

                % On remplace les champs de l'instance courante par les attributs de l'instance statique 
                % enregistrée dans main_app
                app.TemperatureEditField.Value = app.MainApp.AirParametersWindow.Temperature;
                app.StaticPressureEditField.Value = app.MainApp.AirParametersWindow.StaticPressure;
                app.RelativeHumidityEditField.Value = app.MainApp.AirParametersWindow.RelativeHumidity;
            end 
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            % Cette fonction se lance à l'exécution du fichier "Air_Parameters_App.mlapp", que se soit
            % depuis le gestionnaire de fichier ou depuis une autre application

            % varargin = {main_app, ...}

            % Au lancement de l'application, celle-ci rest invisible
            app.UIFigure.Visible = "off";

            % Si l'application est lancée sans arguments
            if nargin == 1

                % On définie arbitrairement la valeur des champs d'écriture
                app.TemperatureEditField.Value = 20;
                app.StaticPressureEditField.Value = 100000;
                app.RelativeHumidityEditField.Value = 20;
                
            % Si un argument est donné en entrée (deuxième lancement et +)
            else 

                app.MainApp = varargin{1};

                % On désactive toutes les interactions dans l'application principale 
                % à chaque réouverture de AirParametersWindow
                app.MainApp.ModifyairparametersMenu.Enable = 'off';

                % On remplace les champs de l'instance courante par les attributs de l'instance statique 
                % enregistrée dans main_app
                app.TemperatureEditField.Value = app.MainApp.AirParametersWindow.Temperature;
                app.StaticPressureEditField.Value = app.MainApp.AirParametersWindow.StaticPressure;
                app.RelativeHumidityEditField.Value = app.MainApp.AirParametersWindow.RelativeHumidity;  
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)

            % Cette fonction initialise l'application principale qui lui est donnée en attribut

            app.Temperature = app.TemperatureEditField.Value;
            app.StaticPressure = app.StaticPressureEditField.Value;
            app.RelativeHumidity = app.RelativeHumidityEditField.Value;

            % On ferme la fenêtre secondaire
            app.UIFigure.Visible = "off";
            
            % Au premier lancement, le programme passe par cette boucle
            if isempty(app.MainApp)
                % On lance l'application
                app.MainApp = Main_App(app);

            % A partir du deuxième lancement, plus besoin de recréer une instance de Main_App
            else
                % On met à jour l'instance de AirParametersWindow dans l'instance de l'application principale
                app.MainApp.AirParametersWindow = app;
            end

            % On réactive les interactions dans l'application principale
            app.MainApp.ModifyairparametersMenu.Enable = "on";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'EPC Bell Presettings App';
            app.PresetsLabel = uilabel(app.UIFigure);
            app.PresetsLabel.Text = 'Presets';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = '°C';
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField.AllowEmpty = 'on';
            app.TemperatureEditField.Value = [];
            app.PaLabel = uilabel(app.UIFigure);
            app.PaLabel.Text = 'Pa';
            app.StaticPressureEditFieldLabel = uilabel(app.UIFigure);
            app.StaticPressureEditFieldLabel.Text = 'Static Pressure';
            app.StaticPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.StaticPressureEditField.AllowEmpty = 'on';
            app.StaticPressureEditField.Value = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.RelativeHumidityEditFieldLabel = uilabel(app.UIFigure);
            app.RelativeHumidityEditFieldLabel.Text = 'Relative Humidity';
            app.RelativeHumidityEditField = uieditfield(app.UIFigure, 'numeric');
            app.RelativeHumidityEditField.AllowEmpty = 'on';
            app.RelativeHumidityEditField.Value = [];
            app.AirparametersLabel = uilabel(app.UIFigure);
            app.AirparametersLabel.Text = 'Air parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Air_Parameters_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end