classdef Configuration_Name_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ContinueButton              matlab.ui.control.Button
        ConfigurationNameEditField  matlab.ui.control.EditField
        ConfigurationNameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        MainApp
        Config
        ConfigName
        
    end

    methods (Access = public)
        
        function open_configuration_name_window(app, config)

            % Cette fonction publique est appelée depuis l'application principale 
            % pour définir le nom d'une nouvelle configuration

            % On stoque la configuration en cours d'enregistrement
            app.Config = config;

            % On désasctive les interactions dans l'application principale
            disable_main_app(app.MainApp);

            % On vide le champ d'écriture du nom de la configuration
            app.ConfigurationNameEditField.Value = "";

            % On fait apparaitre la fenêtre de dialogue
            app.UIFigure.Visible = "on";

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.Visible = "off";
           
        end

        % Value changed function: ConfigurationNameEditField
        function ConfigurationNameEditFieldValueChanged(app, event)
            
            app.ConfigName = app.ConfigurationNameEditField.Value;
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            
            % On crée une nouvelle configuration dans la structure des configurations 
            % du type de sous-élement associée au sous-élement sélectionné
            app.MainApp.SubelementsDatas{app.MainApp.Position(1), app.MainApp.Position(2)} ...
            .Type.ParametersPanelStruct.Configs.(app.ConfigName) = app.Config;

            % On ajoute le nom de la nouvelle configuration au menu déroulant du panneau 
            % d'affichage associé au type du sous-élement sélectionné
            app.MainApp.SubelementsDatas{app.MainApp.Position(1), app.MainApp.Position(2)} ...
            .Type.ParametersPanelStruct.ConfigDropDown.Items{end + 1} = app.ConfigName;

            % On change la valeur du menu déroulant des configurations vers la nouvelle
            % configuration crée (sans lancer le Callback associé)
            app.MainApp.SubelementsDatas{app.MainApp.Position(1), app.MainApp.Position(2)} ...
            .Type.ParametersPanelStruct.ConfigDropDown.Value = app.ConfigName;

            % On associe au sous-élement le nom de la configuration qui lui est attribuée
            app.MainApp.SubelementsDatas{app.MainApp.Position(1), app.MainApp.Position(2)}.ConfigName = app.ConfigName; 
             
            % On réactive l'application principale
            enable_main_app(app.MainApp); 

            % On ferme la fenêtre de dialogue
            app.UIFigure.Visible = "off";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfigurationNameEditFieldLabel = uilabel(app.UIFigure);
            app.ConfigurationNameEditFieldLabel.Text = 'Configuration Name';
            app.ConfigurationNameEditField = uieditfield(app.UIFigure, 'text');
            app.ConfigurationNameEditField.ValueChangedFcn = createCallbackFcn(app, @ConfigurationNameEditFieldValueChanged, true);
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Configuration_Name_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end