classdef Input_Surface_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ContinueButton                matlab.ui.control.Button
        mLabel                        matlab.ui.control.Label
        ElementinputsurfaceEditField  matlab.ui.control.NumericEditField
        ElementinputsurfaceEditFieldLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
    end


    properties (Access = public)

        MainApp
        ElementNumber
        InputSurfaceValue

    end
    
    methods (Access = public)
        
        function open_input_surface_window(app, element_number)

            % Cette fonction publique est appelée depuis l'application principale pour mettre à jour la surface d'entrée
            % d'un élement de l'application
            
            % On met à jour les attributs de la fenêtre de dialogue
            app.ElementNumber = element_number;

            % On désasctive les interactions dans l'application principale
            disable_main_app(app.MainApp);

            % On fait apparaitre sur la fenêtre le numéro associé à l'élement modifé
            app.Label.Text = "Element " + element_number;

            % On récupère la valeur de la surface d'entrée préalablement associée à l'élement modifé
            app.ElementinputsurfaceEditField.Value = app.MainApp.ElementsDatas{element_number}.InputSurface;

            % On fait apparaitre la fenêtre de dialogue
            app.UIFigure.Visible = "on";
        end
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
        
            % Cette application permet à l'utilisateur de renseigner une surface d'entrée pour l'élement considéré
            % Elle est exécutée dans plusieurs cas : 
            % - Lorsque un élement est importé ou crée (voir Main_App.mlapp/subelement_selected)
            % - Lorsque un élement est selectionné et que l'utilisateur souhaite modifier la surface d'entrée (voir Main_App.mlapp/ModifycurrentelementsinputsurfaceMenuSelected)
            
            % varargin = {main_app, element_number, ...}

            % Le lancement de l'application conserve l'interface cachée
            app.UIFigure.Visible = "off";


            % On attribue des valeurs aux propriétés de l'application si besoin
            if nargin > 1 

                app.MainApp = main_app;

                if nargin > 2

                    % On récupère le numéro correspondant à l'élement contenant le sous-élement selectionné dans l'interface
                    app.ElementNumber = element_number;

                    % Si la surface de l'élément est donnée
                    if ~isempty(app.MainApp.ElementsDatas{app.ElementNumber}.InputSurface)

                        %On récupère cette valeur pour l'inscrire dans le champ d'écriture
                        app.ElementinputsurfaceEditField.Value = app.MainApp.ElementsDatas{app.ElementNumber}.InputSurface;

                    end
                end
            end

        end

        % Value changed function: ElementinputsurfaceEditField
        function ElementinputsurfaceEditFieldValueChanged(app, event)
            
            % Ce callback est lancé dès que la valeur prise par le champ est modifée par l'utilisateur
            
            % Si l'utilisateur modifie la valeur contenue dans le champ
            app.InputSurfaceValue = app.ElementinputsurfaceEditField.Value; 

        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            
            % Ce callback est lancé lorsque l'utilisateur à fini de renseigner la valeur de la surface d'entrée
            
            % On appelle une fontion publique de l'application principale pour mettre à jour ses informations
            modify_input_surface(app.MainApp, app.ElementNumber, app.InputSurfaceValue)
            
            enable_main_app(app.MainApp); 

            % On cache la fenêtre de dialogue
            app.UIFigure.Visible = "off";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ElementinputsurfaceEditFieldLabel = uilabel(app.UIFigure);
            app.ElementinputsurfaceEditFieldLabel.Text = 'Element input surface';
            app.ElementinputsurfaceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElementinputsurfaceEditField.ValueChangedFcn = createCallbackFcn(app, @ElementinputsurfaceEditFieldValueChanged, true);
            app.ElementinputsurfaceEditField.Value = 12;
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm²';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Input_Surface_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end