classdef Input_Surface_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputSettingsUIFigure  matlab.ui.Figure
        BackButton             matlab.ui.control.Button
        ContinueButton         matlab.ui.control.Button
        INPUTSETTINGSLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        UIComponents % all the componans of the dialogue box
        MainApp % Main App 
        NumberOfElements
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, number_of_elements, list_of_input_surfaces)
            % root = pwd;
            % addpath(root + "\Files\Functions")
            app.MainApp = main_app;
            % number_of_elements = app.NumberOfElements;
            
            y0 = 43; % y coordinates (bottom) of edit field values of surface ratio
            
            
            % Add extra parameters if optimzation or parametric study

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Parametric Study
            if app.MainApp.ParametricStudy
                app.InputSettingsUIFigure.Position(4) = app.InputSettingsUIFigure.Position(4) + 150;
                app.INPUTSETTINGSLabel.Position(2) = app.INPUTSETTINGSLabel.Position(2) + 150;
                 y0 = 170; % y coordinates (bottom) of edit field values of surface ratio
                tablePreviouslyDeterminedParameters = app.MainApp.UITableHandleparameters.Data;
                % first: get the handle parameter defined by the user
                n_parameters = length(tablePreviouslyDeterminedParameters{:,1});%number of parameters

                app.UIComponents.OPTIMIZATIONPARAMETERSLabel = uilabel(app.InputSettingsUIFigure);
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel.HorizontalAlignment = 'center';
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel.FontWeight = 'bold';
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel.Position = [10 120 281 26];
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel.Text = 'PARAMETRIC STUDY PARAMETERS ';
                % create a table to get the number of parametric test for
                % each parameters
                table = tablePreviouslyDeterminedParameters(:,1);
                table.NumberOfValues = ones(n_parameters,1);
                app.UIComponents.ParametricTable = uitable(app.InputSettingsUIFigure);
                app.UIComponents.ParametricTable.Data = table;
                app.UIComponents.ParametricTable.ColumnName{2} = "Number of values";
                app.UIComponents.ParametricTable.Position = [9 43 270 80];
                app.UIComponents.ParametricTable.ColumnEditable = [false true];

                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Optimizing
            elseif app.MainApp.Optimizing
                app.InputSettingsUIFigure.Position(4) = app.InputSettingsUIFigure.Position(4) + 160;
                app.INPUTSETTINGSLabel.Position(2) = app.INPUTSETTINGSLabel.Position(2) + 160;
                y0 = 190;% y coordinates (bottom) of edit field values of surface ratio


                % Create OPTIMIZATIONPARAMETERSLabel
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel = uilabel(app.InputSettingsUIFigure);
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel.HorizontalAlignment = 'center';
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel.FontWeight = 'bold';
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel.Position = [10 145 281 26];
                app.UIComponents.OPTIMIZATIONPARAMETERSLabel.Text = 'OPTIMIZATION PARAMETERS ';
                % Create FrequencylimitstooptimizeLabel
                app.UIComponents.FrequencylimitstooptimizeLabel = uilabel(app.InputSettingsUIFigure);
                app.UIComponents.FrequencylimitstooptimizeLabel.HorizontalAlignment = 'center';
                app.UIComponents.FrequencylimitstooptimizeLabel.FontWeight = 'bold';
                app.UIComponents.FrequencylimitstooptimizeLabel.Position = [10 130 281 26];
                app.UIComponents.FrequencylimitstooptimizeLabel.Text = 'Frequency limits to optimize';
                
                % edit field for lower, upper and step limit for frequency
                text_edit_field = ["Lower" "Upper" "Step"];
                value_edit_field = [ 100 1000 2 ];
                for i = 1:3
                    % Create EditFieldLabel
                    app.UIComponents.(text_edit_field(i)+"frequencyLabel") = uilabel(app.InputSettingsUIFigure);
                    app.UIComponents.(text_edit_field(i)+"frequencyLabel").HorizontalAlignment = 'right';
                    app.UIComponents.(text_edit_field(i)+"frequencyLabel").Position = [25 43+32*(i-1)  56 22];
                    app.UIComponents.(text_edit_field(i)+"frequencyLabel").Text = text_edit_field(i);
        
                    % Create EditField
                    app.UIComponents.(text_edit_field(i)+"frequencyEditField")= uieditfield(app.InputSettingsUIFigure, 'numeric');
                    app.UIComponents.(text_edit_field(i)+"frequencyEditField").Position = [96 43+32*(i-1) 100 22];
                    app.UIComponents.(text_edit_field(i)+"frequencyEditField").Value = value_edit_field(i);
        
                    % Create m2Label
                    app.UIComponents.(text_edit_field(i)+"frequencyUnityLabel") = uilabel(app.InputSettingsUIFigure);
                    app.UIComponents.(text_edit_field(i)+"frequencyUnityLabel").Position = [202 43+32*(i-1)  28 22];
                    app.UIComponents.(text_edit_field(i)+"frequencyUnityLabel").Text = 'Hz';
                end
            end
               
            % edit values to get the POA

            app.NumberOfElements = app.MainApp.ElementDatas.NumberOfElements;
            % list_of_input_surfaces  = app.list_of_input_surfaces;
            list_of_input_surfaces  = app.MainApp.ElementDatas.ListOfInputSurfaces;
            % one line by element (each line has 2 Labels and 1 EditField)
            for i = 1:app.NumberOfElements
                app.InputSettingsUIFigure.Position(4) = app.InputSettingsUIFigure.Position(4) + 32;
                app.INPUTSETTINGSLabel.Position(2) = app.INPUTSETTINGSLabel.Position(2) + 32;
                % Create EditFieldLabel
                app.UIComponents.("ElementLabel"+num2str(i)) = uilabel(app.InputSettingsUIFigure);
                app.UIComponents.("ElementLabel"+num2str(i)).HorizontalAlignment = 'right';
                app.UIComponents.("ElementLabel"+num2str(i)).Position = [25 y0+32*(app.NumberOfElements-i) 56 22];
                app.UIComponents.("ElementLabel"+num2str(i)).Text = convertCharsToStrings('Element ') +num2str(i);
    
                % Create EditField
                app.UIComponents.("ElementEditField"+num2str(i)) = uieditfield(app.InputSettingsUIFigure, 'numeric');
                app.UIComponents.("ElementEditField"+num2str(i)).Position = [96 y0+32*(app.NumberOfElements-i) 100 22];
                app.UIComponents.("ElementEditField"+num2str(i)).Value = list_of_input_surfaces(i);
    
                % Create m2Label
                app.UIComponents.("m2Label"+num2str(i)) = uilabel(app.InputSettingsUIFigure);
                app.UIComponents.("m2Label"+num2str(i)).Position = [202 y0+32*(app.NumberOfElements-i) 28 22];
                app.UIComponents.("m2Label"+num2str(i)).Text = 'm^2';
            end


        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)

            % Après que l'utilisateur a rempli la surface de chaque élément
            list_of_input_surfaces = zeros([1 app.NumberOfElements]);
            for i = 1:app.NumberOfElements
                list_of_input_surfaces(i) = app.UIComponents.("ElementEditField" + num2str(i)).Value;
            end

            % Si le calcul ou l'optimisation est "activé" (l'utilisateur souhaite calculer ou optimiser)
            % Préparer l'assemblage des éléments
            if app.MainApp.Computing || app.MainApp.Optimizing || app.MainApp.ParametricStudy
                % Définitions des fréquences
                fmin = 1;
                fmax = app.MainApp.Air.parameters.c0/2/sqrt(app.MainApp.EPCSurface);
                fstep = 2;
                f=fmin:fstep:fmax;
                w=2*pi*f;


                tablePreviouslyDeterminedParameters = app.MainApp.UITableHandleparameters.Data;
                % first: get the handle parameter defined by the user
                n_parameters = length(tablePreviouslyDeterminedParameters{:,1}); % number of parameters
                struct_parameter_user_values=struct;
                for i = 1:n_parameters
                    if tablePreviouslyDeterminedParameters{i,1} ~=""
                        struct_parameter_user_values.(tablePreviouslyDeterminedParameters{i,1})=tablePreviouslyDeterminedParameters{i,2};
                    end
                end
                
                % Create the handle element_assembly object of handle
                % Elements composed of handle object
                cell_of_elements = {};
                for ii = 1:length(app.MainApp.UIAxes.YTick) % for each element
                    cell_of_subelements = {};
                    for jj = 1:length(app.MainApp.UIAxes.XTick)
                        % for each subelement (SE) of the current element
                        % puts the SE in a cell in the user defined order
                        fnames = fieldnames(app.MainApp.SubelementDatas);
                        for fi = 1:length(fnames)%parcourt notre strucutre
                            if not(ismember(getfield(app.MainApp.SubelementDatas,fnames{fi},"Name"),["None" "Undefined"]))
                                Position = getfield(app.MainApp.SubelementDatas,fnames{fi},"Position");
                                if Position(1)==app.MainApp.UIAxes.XTick(jj)+1 && Position(2)==app.MainApp.UIAxes.YTick(ii)+1 
                                    handle_object=getfield(app.MainApp.SubelementDatas,fnames{fi},"Object");%get the object
                                    cell_of_subelements{end+1}=handle_object;%replace handle parameters if exist of the object
                                end
                            end
                        end
                    end

                    % elements = @(params) cellfun(@(x) cellfunction2list(x,params),elements_cells);
                    % After putting all the SE in a cell
                    % create element and put it in the list of elements 
                    if not(isscalar(app.MainApp.UIAxes.XTick)) && ~isempty(cell_of_subelements)% 
                        if ii ==1 %one of the element need to be close for the absorption coefficient
                            new_element = @(params) Element(list_of_input_surfaces(ii)/app.MainApp.EPCSurface, ...
                                cellfun(@(x) cellfunction2list(x,params),cell_of_subelements,'UniformOutput',false),'opened');
                        else
                            new_element = @(params) Element(list_of_input_surfaces(ii)/app.MainApp.EPCSurface, ...
                                cellfun(@(x) cellfunction2list(x,params),cell_of_subelements,'UniformOutput',false),'closed');
                        end
                        cell_of_elements{end+1} = new_element;
                    end               
                end
                % convert  cell_of_elements to a list (needed for element_assembly)
                list_of_elements = @(params) cellfun(@(x) cellfunction2list(x,params),cell_of_elements);

                handle_assembly = @(params) element_assembly(list_of_elements(params));
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Computing
            if app.MainApp.Computing
                    % a changer car le calcul de alpha et de R doit se faire
                    % dans une méthode assembly                
                    %compute the normal sound absorption coeffecient
                    figure
                    absorptionCoefGraph(app.MainApp,w,handle_assembly(struct_parameter_user_values))

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Parametric Study
            elseif app.MainApp.ParametricStudy
                    % a changer car le calcul de alpha et de R doit se faire
                    % dans une méthode assembly                
                    % compute the normal sound absorption coeffecient
                   parametric_values = struct;
                    for i=1:n_parameters
                        % n_values = input("Nombre de valeur pour :"+tablePreviouslyDeterminedParameters{i,1}+": ");
                        n_values = app.UIComponents.ParametricTable.Data{i,2};
                        if n_values == 0 %if no values use that defined by the user 
                            parametric_values.(tablePreviouslyDeterminedParameters{i,1}) = tablePreviouslyDeterminedParameters{i,2};
                        else % else create a vector for apremtric study
                            vector = linspace(tablePreviouslyDeterminedParameters{i,3},tablePreviouslyDeterminedParameters{i,4},n_values);
                            parametric_values.(tablePreviouslyDeterminedParameters{i,1})=vector;
                        end
                    end
                    fnames = fieldnames(struct_parameter_user_values);
                    figure
                    
                    for i=1:n_parameters
                        for current_parameter = parametric_values.(fnames{i})
                            struct_current_parameters = struct_parameter_user_values;
                            struct_current_parameters.(fnames{i}) = current_parameter;
                            absorptionCoefGraph(app.MainApp,w,handle_assembly(struct_current_parameters),fnames{i}+" = "+num2str(current_parameter))
                            legend("Location","best")
                        end
                    end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Optimizing
            elseif app.MainApp.Optimizing
                % fmin_opti = input("Bande de frequences pour l'optimisation, borne inf [Hz] :");
                % fmax_opti = input("Bande de frequences pour l'optimisation, borne sup [Hz] :");
                % fstep_opti = input("Bande de frequences pour l'optimisation, pas [Hz] :");
                app.UIComponents.LowerfrequencyEditField.Value
                fmin_opti = app.UIComponents.LowerfrequencyEditField.Value;
                fmax_opti = app.UIComponents.UpperfrequencyEditField.Value;
                fstep_opti = app.UIComponents.StepfrequencyEditField.Value;
            
                f_opti = fmin_opti:fstep_opti:fmax_opti;
                w_opti = 2*pi*f_opti;

                % reflexion coefficient with handle paramters
                R = @(w,params,assembly,fluid)(assembly(params).transfermatrix(fluid,w).T11./assembly(params).transfermatrix(fluid,w).T21-fluid.parameters.rho*fluid.parameters.c0)./(assembly(params).transfermatrix(fluid,w).T11./assembly(params).transfermatrix(fluid,w).T21+fluid.parameters.rho*fluid.parameters.c0);
    
                % Cost function
                costf = @(w,params,assembly,fluid) sum(abs(R(w,params,assembly,fluid)).^2);
                objectif = @(x)build_obj(x,costf,w_opti,handle_assembly,app.MainApp.Air,tablePreviouslyDeterminedParameters);
    
                % Initial vector = value set by the user
                x0 =tablePreviouslyDeterminedParameters{:,2};
                
                %===== Results initial vector = user values
                fval0 = objectif(x0);
                alpha0 = 1-abs(R(w,struct_parameter_user_values,handle_assembly,app.MainApp.Air)).^2;
                figure, hold on
                plot(f,alpha0,"-",'linewidth',2,"displayname","Initial values");
                patch([f_opti(1) f_opti(end) f_opti(end) f_opti(1)], [0 0 1 1],"green", "facealpha",0.1,"displayname","study area")

                % bornes sup et inf
                lb =tablePreviouslyDeterminedParameters{:,3};%borne inf
                ub =tablePreviouslyDeterminedParameters{:,4};%borne sup
                    
                %===== Constraints
                % Aineq*x ≤ bineq
                Aineq  = zeros(length(x0));
                bineq = zeros(length(x0),1);
                rng % For reproducibility
                
                % multistart options
                number_of_starting_points_MS = 4;%nombre de points de depart pour algo multi start
    
                optionsmultistart = optimset('Algorithm','interior-point');
                
                optionsgraph = [];%optimset('Disp','iter','PlotFcns',{'optimplotfval','optimplotx'});%,'OutputFcn',@peaksPlotIterates);
                optionsmultistart= optimset(optionsmultistart,optionsgraph);
                
                % création du problème pour Global Search and Multi start
                problem = createOptimProblem('fmincon','objective',objectif,'x0',x0,'Aineq',Aineq,'bineq',bineq,...
                    'lb',lb,'ub',ub,'options',optionsmultistart);         

                %===== Run opti           
                tic;
                [xoptiMs, fvalMs,eflagMs,ouputMs,solutionsMs]=run(MultiStart,problem,number_of_starting_points_MS);
                % sum([solutionsMs.X(:4*(1:end/4)) solutionsMs.X(:,2*(1:end/4))])
                % solutionsMs.X(:,1)
                tempsMs = toc;
       
                %===== Results opti   
                vector_MS=struct;
                for i=1:n_parameters
                    vector_MS.(tablePreviouslyDeterminedParameters{i,1})=xoptiMs(i);
                end
                
                display("Geometric values from the optimization:")
                vector_MS

                alphaMs = 1-abs(R(w,vector_MS,handle_assembly,app.MainApp.Air)).^2;
                % [~, fpics0 ] = findpeaks(alpha0,f);
                % [~, fpicsMs ] = findpeaks(alphaMs,f);

                plot(f,alphaMs,"--",'linewidth',2,"displayname","Optimized value Ms");
                
                grid on
                legend("location","northwest");
                legend
                xlabel('Frequencies (Hz)');ylabel("Sound absorption coefficient (1)");
                ylim([0 1])
            end

            % enable the Main application and delete the current one
            updateInputSurfaces(app.MainApp,list_of_input_surfaces)
            app.MainApp.InputSurfaceAreasMenu.Enable = 'on';
            app.MainApp.SubelementPanel.Enable = "on";
            app.MainApp.DeleteSubelementButton.Enable = "on";
            app.MainApp.ComputeButton.Enable = "on";
            app.MainApp.OptimizeButton.Enable = "on";
            app.MainApp.ParametricStudyButton.Enable = "on";
            % Reset the information about computing...
            app.MainApp.Computing = 0; 
            app.MainApp.ParametricStudy = 0; 
            app.MainApp.Optimizing = 0; 
            delete(app)


        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.MainApp.InputSurfaceAreasMenu.Enable = 'on';
            app.MainApp.SubelementPanel.Enable = "on";
            app.MainApp.DeleteSubelementButton.Enable = "on";
            app.MainApp.ComputeButton.Enable = 'on';
            app.MainApp.OptimizeButton.Enable = 'on';
            app.MainApp.ParametricStudyButton.Enable = 'on';
            app.MainApp.Computing = 0; 
            app.MainApp.ParametricStudy = 0; 
            app.MainApp.Optimizing = 0; 
            delete(app)
        end

        % Close request function: InputSettingsUIFigure
        function InputSettingsUIFigureCloseRequest(app, event)
            app.MainApp.InputSurfaceAreasMenu.Enable = 'on';
            app.MainApp.SubelementPanel.Enable = "on";
            app.MainApp.DeleteSubelementButton.Enable = "on";
            app.MainApp.ComputeButton.Enable = 'on';
            app.MainApp.OptimizeButton.Enable = 'on';
            app.MainApp.ParametricStudyButton.Enable = 'on';
            app.MainApp.Computing = 0; 
            app.MainApp.ParametricStudy = 0; 
            app.MainApp.Optimizing = 0; 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputSettingsUIFigure = uifigure('Visible', 'off');
            app.InputSettingsUIFigure.Name = 'Input Settings';
            app.InputSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @InputSettingsUIFigureCloseRequest, true);
            app.INPUTSETTINGSLabel = uilabel(app.InputSettingsUIFigure);
            app.INPUTSETTINGSLabel.Text = 'INPUT SETTINGS';
            app.ContinueButton = uibutton(app.InputSettingsUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.BackButton = uibutton(app.InputSettingsUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.InputSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Input_Surface_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputSettingsUIFigure)
        end
    end
end