classdef Main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        ClearWorkspaceMenu         matlab.ui.container.Menu
        ManageSettingsMenu         matlab.ui.container.Menu
        ModifyairparametersMenu    matlab.ui.container.Menu
        ModifycurrentelementsinputsurfaceMenu  matlab.ui.container.Menu
        ConfigurationMenu          matlab.ui.container.Menu
        SaveConfigurationMenu      matlab.ui.container.Menu
        ImportConfigurationMenu    matlab.ui.container.Menu
        ImportElementMenu          matlab.ui.container.Menu
        Panel                      matlab.ui.container.Panel
        SubelementsPanel           matlab.ui.container.Panel
        SaveAsConfigurationButton  matlab.ui.control.Button
        CreateSubelementButton     matlab.ui.control.Button
        DeleteSubelementButton     matlab.ui.control.Button
        SubelementTypeDropDown     matlab.ui.control.DropDown
        SubelementtypeLabel        matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        EditFieldLabel             matlab.ui.control.Label
        ImportElementButton        matlab.ui.control.Button
        HandleVariablesPanel       matlab.ui.container.Panel
        AddlineButton              matlab.ui.control.Button
        DeletelastlineButton       matlab.ui.control.Button
        HandleVariablesTable       matlab.ui.control.Table
        ComputeButton              matlab.ui.control.Button
        ParametricStudyButton      matlab.ui.control.Button
        OptimizeButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The application consists of a figure on which the various 
        % sub-elements are displayed. 
        % These sub-elements are created and selected via a callback when 
        % the user clicks on the figure.
        
        % Depending on the type of sub-elements selected, edit fields
        % appear. After entering the data and validating, the data is 
        % stored in the SubelementDatas structure.

        SubelementsTypes 
        % Structure contenant les élements relatifs aux types de sous-élement
        % app.SubelementsTypes.SubelementType_1 (struct) (voir add_subelement_type)
        %                                        .ParametersPanelStruct (struct) (voir add_parameters_panel)
        %                                                               .Panel (panel)
        %                                                                       .Visible
        %                                                                       .Position
        %                                                               .PanelTitle (label)
        %                                                               .ParametersLabels (cell)
        %                                                               .ParametersEditFields (cell)
        %                                                               .ParametersUnits (cell)
        %                                                               .CreateSubelementButton (= uibutton)
        %                                                               .SaveAsConfigButton (= uibutton)
        %                                        .HandleClassObject (string)
        %                                        
        %                                        .Color ('#335987')
        %                                        .Configs (struct)
        %                                                .Config_1 (struct)
        %                                                          .ConfigName
        %                                                          .Value.Param_1
        %                                                                .Param_2
        %                                                          etc.
        % 
        %                                                .Config_2
        %                                                etc.
        %                                        .TypeName (string) nom du
        %                                        type dont il est question 
        
        TypesToItems % visiblement utilisé dans aucune fonction
        ItemsToTypes 

        SubelementsDatas % (cell array) tableau contenant des structures associées à chaque sous-élements
        % représenté dans la fenêtre graphique.

        % app.SubelementsDatas{i, j} = Subelement_<i,j> (struct)
        %                                               .Type (string) nom associé à la classe du sous-élement (si classe associée existante)
        %                                               .Class : (struct) handle qui pointe vers la classe associée au sous-élement
        %                                               .Position : [X Y] (double) 
        %                                               .HandleFunction : fonction handle associée au constructeur de classe pour le type de sous-élement considéré
        %                                               .Config : (struct) structure associée à la configuration de l'objet
        %                                                      .ConfigName (string)
        %                                                      .ConfigDatas (struct) argument de la fonction handle
        %                                               .ElementSource (struct) élement auquel le sous-élement est rattaché

        HandleVariables % Structure contenant les élements relatifs aux variables muettes
        % HandleVariables.a.Value (double) or (string)
        %                  .UpperBound (double)
        %                  .LowerBound
        %                  .Unit (string)
        %                  .Description (string) 

        ElementsDatas % (cell array) tableau contenant des structures associées à chaque élément
        % représenté dans la fenêtre graphique.

        % app.ElementsDatas.{1} = Element_1 (struct)
        %                                  .HandleElement (handle function)
        %                                  .ListOfSubelements (cell array)
        %                                  .InputSurface (double)
        %                                  .EndStatus (string)
        %                                  
        % Indicateurs

        % When the user click on the buttons : Compute, Opimize or Parametric
        % The three actions launch the same function, but with a parameter
        % set to one instead of 0 :
        Computing % used to know if the EPC is completed (and if Compute button has been pushed)
        Optimizing % used to know if the EPC is completed (and if Optimize button has been pushed)
        ParametricStudy % used to know if the EPC is completed (and if ParametricStudy button has been pushed)

        Position % Coordonnées du sous-élement sélectionné

        % Fenêtres secondaires

        InputSurfaceWindow % Fenêtre de dialogue permettant de renseigner la surface d'entrée d'un élement
        AirParametersWindow % Fenêtre de dialogue permettant de renseigner les paramètres de l'environnement
        ConfigurationNameWindow % Fenêtre de dialogue permettant de renseigner le nom d'une nouvelle configuration
        ParametricStudyWindow 

        % Graph % (AppComponentContainer)
        %      .Components.ElementGraph (AppComponent)
        %                 .SubelementGraph (AppComponent)

        % ParametersPanel % (AppComponentContainer)
        %               .Components.ElementsParametersPanel (AppComponentContainer)
        %                                                 .Components

        %                          .SubelementsParametersPanel (AppComponentContainer)
        %                                                    .Components =l
        %                                                    {Subelement
    end

    methods (Access = public)

        function save_as_config(app, event)

                CreateSubelementsButtonPushed(app, event)
        end
        
        function enable_main_app(app)
        
           % Cette fonction publique désactive toutes les interactions dans l'application principale
            
            % Désactiver tous les éléments de l'application principale
            components = app.UIFigure.Children; % Récupère tous les enfants de la figure principale
            
            for i = 1:length(components)
                % Désactive chaque composant en réglant sa propriété 'Enable' sur 'off'
                if isprop(components(i), 'Enable') % Vérifie si le composant a la propriété 'Enable'
                    components(i).Enable = 'on';
                end
            end
        end 
            
        function disable_main_app(app)
            
            % Cette fonction publique désactive toutes les interactions dans l'application principale
            
            % Désactiver tous les éléments de l'application principale
            components = app.UIFigure.Children; % Récupère tous les enfants de la figure principale
            
            for i = 1:length(components)
                % Désactive chaque composant en réglant sa propriété 'Enable' sur 'off'
                if isprop(components(i), 'Enable') % Vérifie si le composant a la propriété 'Enable'
                    components(i).Enable = 'off';
                end
            end
        end    
        
        function modify_input_surface(app, element_number, input_surface_value)

            % Cette fonction est publique est permet de mettre à jour les surfaces d'entrée des élements de 
            % l'application principale depuis l'application secondaire 'Modify_Input_Surface.mlapp'
            app.ElementsDatas{element_number}.InputSurface = input_surface_value;
            
        end
           
        function ParametersPanelStruct = add_parameters_panel_structure(app, title, number_of_params, ... 
        tag_of_params, unit_of_params)
            
            % Cette méthode permet de créer un panneau d'affichage des paramètres adapté à un type de sous-élément
            % Créer un nouveau panneau de paramètres sous le panneau de sélection des sous-éléments

            % Utilisée dans : init_classes

            % 1. On initialise la structure des paramètres du panneau
            ParametersPanelStruct = struct();
            ParametersPanel = uipanel(app.SubelementsPanel); % Créer un nouveau panneau à l'intérieur de l'objet app.SubelementsPanel
            ParametersPanel.Title = title;
            ParametersPanel.Visible = 'off';
            ParametersPanel.Position = [11 50 417 300];
            
            % Titre des paramètres
            PanelTitle = uilabel(ParametersPanel);
            PanelTitle.BackgroundColor = [0.8 0.8 0.8];
            PanelTitle.HorizontalAlignment = 'center';
            PanelTitle.FontWeight = 'bold';
            PanelTitle.Position = [10 272 394 22];
            PanelTitle.Text = 'PARAMETERS';
        
            % Initialiser les structures de stockage pour les composants
            ParametersLabels = cell(1, number_of_params);
            ParametersEditFields = cell(1, number_of_params);
            ParametersUnits = cell(1, number_of_params);
        
            % Boucle pour ajouter les labels, champs de saisie et unités
            for i = 1:number_of_params
                ParametersLabels{i} = uilabel(ParametersPanel);
                ParametersLabels{i}.HorizontalAlignment = 'right';
                ParametersLabels{i}.Position = [48 240 - 32 * (i - 1) 126 32];
                ParametersLabels{i}.Text = tag_of_params{i};
                ParametersLabels{i}.WordWrap = 'on';
                ParametersLabels{i}.Tag = 'Parameter';
        
                % Champ de saisie du paramètre
                ParametersEditFields{i} = uieditfield(ParametersPanel);
                ParametersEditFields{i}.Position = [180 246 - 32 * (i - 1) 100 22];

                % On attribue le marqueur du nom de la variable au champ d'édition
                ParametersEditFields{i}.Tag = tag_of_params{i};

                % Unité du paramètre
                ParametersUnits{i} = uilabel(ParametersPanel);
                ParametersUnits{i}.Position = [285 246 - 32 * (i - 1) 50 22];
                ParametersUnits{i}.Text = unit_of_params{i};
                ParametersUnits{i}.Tag = 'Unit';
            end
        
            % Ajouter un menu déroulant pour la configuration
            ConfigDropDown = uidropdown(ParametersPanel);
            ConfigDropDown.Position = [180 246 - 32 * number_of_params 100 22];
            ConfigDropDown.Items = {'Manual'};
            ConfigDropDown.Value = 'Manual';
            ConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigDropDownValueChanged, true);

            % Initialiser les configurations
            Configs = struct();
            Configs.Manual = zeros(1, number_of_params);
            % Configs.Manual = panel_to_config(app, ParametersPanel);            

            % Créer une structure pour stocker tous les composants
            ParametersPanelStruct.Panel = ParametersPanel;
            ParametersPanelStruct.PanelTitle = PanelTitle;
            ParametersPanelStruct.ParametersLabels = ParametersLabels;
            ParametersPanelStruct.ParametersEditFields = ParametersEditFields;
            ParametersPanelStruct.ParametersUnits = ParametersUnits;
            ParametersPanelStruct.ConfigDropDown = ConfigDropDown;
            ParametersPanelStruct.Configs = Configs;
        end
   
        function init_workspace(app)

            % Ce qui existe en premier ce sont les uiobject construits dans
            % la design view

            % app.Graph = AppComponentContainer(app.GraphPanel)
            % app.

            % Cette fonction prend pour argument une instance de l'application Main_App.mlapp
            % Elle initialise l'espace de travail

            % Initialisation du tableau d'affichage des variables muettes (ui.control.table)
            app.HandleVariablesTable.Data = (table({}, [], [], [], {}, 'VariableNames', ...
            {'Name', 'Value', 'Lb', 'Ub', 'Description'}));

            app.TypesToItems = containers.Map();
            app.ItemsToTypes = containers.Map();

            % Initialisation des panneaux d'affichage
            init_classes(app);

            % On vide les élements qui existent déjà dans la fenêtre graphique
            cla(app.UIAxes);

            % Initialisation de la position
            app.Position = [1 1];

            % Initialisation des sous-éléments
            app.SubelementsDatas = cell([]);
        
            % Initialisation des élements
            app.ElementsDatas = cell([]);

            app.HandleVariables = struct();

            % Initialisation des sous-élements
            add_subelement(app, app.Position, 'None');

            % Affichage de la fenêtre graphique    
            plot_subelements(app);

            % Changement de la valeur du menu déroulant 'Subelement Class'
            app.SubelementTypeDropDown.Value = 'None';
            SubelementTypeDropDownValueChanged(app, []);
        
        end
        
        function init_classes(app)

            % On initialise le contenu de la structure app.SubelementsClasses comprenant 
            % les attributs de chaque type, les panneaux de paramètres et le dictionnaire de correspondance

            %% None

            if ~isfield(app.SubelementsTypes, 'None')
                app.SubelementsTypes.None.TypeName = 'None';
                app.SubelementsTypes.None.Color = 'k';
                app.SubelementsTypes.None.Marker = '+';
            end

            %% Undefined

            if ~isfield(app.SubelementsTypes, 'Undefined')
                app.SubelementsTypes.Undefined.TypeName = 'Undefined';
                app.SubelementsTypes.Undefined.Color = 'k';
                app.SubelementsTypes.Undefined.Marker = 'square';
            end

            %% Porous Material (Limp)

            if ~isfield(app.SubelementsTypes, 'classJCA_Rigid')

                app.SubelementsTypes.JCA.ParametersPanelStruct = add_parameters_panel_structure ...
                (app, 'classJCA_Rigid', 7, {'Porosity', 'Airflow Resistivity', 'Tortuosity', ...
                'Viscous Characteristic Length', 'Thermal Characteristic Length', 'Thickness', ...
                'Material Density'}, {'[0-1]', 'Pa.s/m2', '(no unit)', 'm', 'm', 'm', 'kg/m3'});
    
                app.SubelementsTypes.classJCA_Rigid.TypeName = 'classJCA_Rigid';
                app.SubelementsTypes.classJCA_Rigid.Color = '#548FAB';
                app.SubelementsTypes.classJCA_Rigid.Marker = 'diamond';
                app.SubelementsTypes.classJCA_Rigid.HandleClassObject = @(config) classJCA_Rigid(config);
 
                app.SubelementTypeDropDown.Items{end + 1} = 'JCA MAterial';
                app.SubelementTypeDropDown.ItemsData{end + 1} = 'classJCA_Rigid';
            end
        
            %% Perforated Plate (Circular)
            if ~isfield(app.SubelementsTypes, 'classMPP_Circular')

                app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct = add_parameters_panel_structure ...
                (app, 'classMPP_Circular', 5, {'Porosity', 'Thickness', 'Perforations Radius' 'Plate Section' ...
                'Thickness Correction'}, {'[0-1]', 'm', 'm', 'm^2', 'm'});
    
                app.SubelementsTypes.classMPP_Circular.TypeName = 'classMPP_Circular';
                app.SubelementsTypes.classMPP_Circular.Color = '#094F29';
                app.SubelementsTypes.classMPP_Circular.Marker = 'diamond';
                
                % Permet de créer l'objet de classe à partir de la configuration d'application
                app.SubelementsTypes.classMPP_Circular.HandleClassObject = @(app_config_double) ...
                classMPP_Circular(classMPP_Circular.create_config(app_config_double.Porosity, ...
                app_config_double.PerforationsRadius, app_config_double.Thickness, ...
                app_config_double.PlateSection, app_config_double.ThicknessCorrection));
    
                % Permet de créer une configuration d'application à partir d'une configuration de classe/ configuration d'appel
                app.SubelementsTypes.classMPP_Circular.HandleAppConfig = @(class_config_str) ...
                struct('Porosity', class_config_str.PlatePorosity, 'Thickness', class_config_str.PlateThickness, ...
                'PerforationsRadius', class_config_str.PerforationsRadius, 'PlateSection', class_config_str.PlateSection, ...
                'ThicknessCorrection', class_config_str.ThicknessCorrection);

                app.SubelementTypeDropDown.Items{end + 1} = 'Perforated Plate (Circular)';
                app.SubelementTypeDropDown.ItemsData{end + 1} = 'classMPP_Circular';
            end

            % %% Perforated Plate (Slit)
            % if ~isfield(app.SubelementsTypes, 'SlitMPP')
            % 
            %     app.SubelementsTypes.SlitMPP.ParametersPanelStruct = add_parameters_panel_structure ...
            %     (app, 'classMPP_Slit', 4, {'Porosity', 'Thickness', 'Length', 'Width'}, ...
            %     {'[0-1]', 'm', 'm', 'm'});
            % 
            %     app.SubelementsTypes.SlitMPP.TypeName = 'classMPP_Slit';
            %     app.SubelementsTypes.SlitMPP.Color = '#429B46';
            %     app.SubelementsTypes.SlitMPP.Marker = 'diamond';
            %     app.SubelementsTypes.SlitMPP.HandleClassObject = @(config) classMPP_Slit(config);
            % 
            %     app.SubelementTypeDropDown.Items{end + 1} = 'Perforated Plate (Slit)';
            %     app.SubelementTypeDropDown.ItemsData{end + 1} = 'SlitMPP';
            % 
            %     app.ItemsToTypes('Perforated Plate (Slit)') = app.SubelementsTypes.SlitMPP;
            %     app.TypesToItems('SlitMPP') = 'Perforated Plate (Slit)';
            % end
                        
            % %% Perforated Plate (Rectangular)
            % if ~isfield(app.SubelementsTypes, 'RectangularMPP')
            % 
            %     app.SubelementsTypes.RectangularMPP.ParametersPanelStruct = add_parameters_panel_structure ...
            %     (app, 'classMPP_Rectangular', 4, {'Porosity', 'Thickness', 'Length', 'Width'}, ...
            %     {'[0-1]', 'm', 'm', 'm'});
            % 
            %     app.SubelementsTypes.RectangularMPP = 'RectangularMPP';
            %     app.SubelementsTypes.RectangularMPP.Color = '#94C58C'; 
            %     app.SubelementsTypes.RectangularMPP.Marker = 'diamond';
            %     app.SubelementsTypes.RectangularMPP.HandleClassObject = @(config) classMPP_Rectangular(config);
            % 
            %     app.SubelementTypeDropDown.Items{end + 1} = 'Perforated Plate (Rectangular)';
            %     app.SubelementTypeDropDown.ItemsData{end + 1} = 'RectangularMPP';
            % 
            %     app.ItemsToTypes('Perforated Plate (Rectangular)') = app.SubelementsTypes.RectangularMPP;
            %     app.TypesToItems('RectangularMPP') = 'Perforated Plate (Rectangular)';
            % end

            % %% Helmholtz Resonator (Free geometry)
            % app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct = add_parameters_panel_structure ...
            % (app, 'FreeHelmoltzResonator', 3, {'Neck Surface', 'Neck Length', 'Cavity Volume'}, ...
            % {'m2', 'm', 'm3'});
            %
            % app.SubelementsTypes.FreeHelmoltzResonator.Color = '#8B0001';
            % app.SubelementsClassesNames('Helmholtz Resonator (Free Geometry)') = 'FreeHelmoltzResonator';
            % app.SubelementsClassesNames('FreeHelmoltzResonator') = 'Helmholtz Resonator (Free Geometry)';

            % % - Helmholtz Resonator (Cylindrical)
            % app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct = add_parameters_panel_structure ...
            % (app, 'CylindricalHelmholtzResonator', 4, {'Neck Radius', 'Neck Depth', 'Cavity Radius', ...
            % 'Cavity Depth'}, {'m', 'm', 'm', 'm'});

            % app.SubelementsTypes.CylindricalHelmholtzResonator.Color = '#C92020';
            % app.SubelementsClassesNames('Helmholtz Resonator (Cylindrical)') = 'CylindricalHelmholtzResonator';
            % app.SubelementsClassesNames('CylindricalHelmholtzResonator') = 'Helmholtz Resonator (Cylindrical)';
        
            % %% Resistive Screen
            % 
            % if ~isfield(app.SubelementsTypes, 'Screen')
            % 
            %     app.SubelementsTypes.Screen.ParametersPanelStruct = add_parameters_panel_structure ...
            %     (app, 'classscreen', 2, {'Screen Density', 'Thickness'}, {'kg/m3', 'm'});
            % 
            %     app.SubelementsTypes.Screen.Color = '#FFA500';
            %     app.SubelementsTypes.Screen.Marker = 'diamond';
            %     app.SubelementsTypes.Screen.HandleClassObject = @(config) classscreen(config);
            % 
            %     app.SubelementTypeDropDown.Items{end + 1} = 'Resistive Screen';
            %     app.SubelementTypeDropDown.ItemsData{end + 1} = 'Screen';
            % 
            %     app.ItemsToTypes('Resistive Screen') = app.SubelementsTypes.Screen;
            %     app.TypesToItems('Screen') = 'Resistive Screen';
            % end

            % %% Protective Grid
            % 
            % if ~isfield(app.SubelementsTypes, 'Grid')
            % 
            %     app.SubelementsTypes.Grid.ParametersPanelSt.QWL.ruct = add_parameters_panel_structure ...
            %     (app, 'classgrid', 4, {'Mesh Length', 'Mesh Width', 'Thickness', 'Porosity'}, ...
            %     {'m', 'm', 'm', '%'});
            % 
            %     app.SubelementsTypes.Grid.Color = '#6600A1';
            %     app.SubelementsTypes.Grid.Marker = 'diamond';
            %     app.SubelementsTypes.Grid.HandleClassObject = @(config) classgrid(config);
            % 
            %     app.SubelementTypeDropDown.Items{end + 1} = 'Protective Grid';
            %     app.SubelementTypeDropDown.ItemsData{end + 1} =  'Grid';
            % 
            %     app.ItemsToTypes('Protective Grid') = app.SubelementsTypes.Grid;
            %     app.TypesToItems('Grid') = 'Pretective Grid';
            % end
        
            % %% Quarter Wavelength Resonator
            % 
            % if ~isfield(app.SubelementsTypes, 'QWL')
            % 
            %     app.SubelementsTypes.QWL.ParametersPanelStruct = add_parameters_panel_structure ...
            %     (app, 'classQWL', 3, {'Main Radius', 'Resonator Radius', 'Resonator Length'}, ...
            %     {'m', 'm', 'm'});
            % 
            % 
            %     app.SubelementsTypes.QWL.Color = '#C0428A';
            %     app.SubelementsTypes.QWL.Marker = 'diamond';
            %     app.SubelementsTypes.QWL.HandleClassObject = @(config) classQWL(config);
            % 
            %     app.SubelementTypeDropDown.Items{end + 1} = 'Quarter Wavelength Resonator';
            %     app.SubelementTypeDropDown.ItemsData{end + 1} = 'QWL';
            % 
            %     app.ItemsToTypes('Quarter Wavelength Resonator') = app.SubelementsTypes.QWL;
            %     app.TypesToItems('QWL') = 'Quarter Wavelength Resonator';
            % end 

            %% Quarter Wavelength Resonator (Slit)

            if ~isfield(app.SubelementsTypes, 'classQWL_Slit')

                app.SubelementsTypes.classQWL_Slit.ParametersPanelStruct = add_parameters_panel_structure ...
                (app, 'classQWL', 3, {'Length', 'Width', ''}, ...
                {'m', 'm', 'm'});
                app.SubelementsTypes.classQWL_Slit.TypeName = 'classQWL_Slit';
                app.SubelementsTypes.classQWL_Slit.Color = '#C0428A';
                app.SubelementsTypes.classQWL_Slit.Marker = 'diamond';

                % Permet de créer l'objet de classe à partir de la configuration d'application
                % app.SubelementsTypes.classQWL_Slit.HandleClassObject = @(config) classQWL_Slit.create_config('Length', config.);
    
                app.SubelementTypeDropDown.Items{end + 1} = 'Quarter Wavelength Resonator';
                app.SubelementTypeDropDown.ItemsData{end + 1} = 'QWL';
    
                app.ItemsToTypes('Quarter Wavelength Resonator') = app.SubelementsTypes.classQWL_Slit;
                app.TypesToItems('QWL') = 'Quarter Wavelength Resonator';
            end 

            %% Cavity

            if ~isfield(app.SubelementsTypes, 'classcavity')

                app.SubelementsTypes.classcavity.ParametersPanelStruct = add_parameters_panel_structure ...
                (app, 'classcavity', 2, {'Length', 'Input Section'}, {'m', 'm^2'});
    
                app.SubelementsTypes.classcavity.TypeName = 'classcavity';
                app.SubelementsTypes.classcavity.Color = '#30D5C8';
                app.SubelementsTypes.classcavity.Marker = 'diamond';

                app.SubelementsTypes.classcavity.HandleClassObject = @(app_config_double) ...
                classcavity(classcavity.create_config(app_config_double.Length, app_config_double.InputSection));

                app.SubelementsTypes.classcavity.HandleAppConfig = @ (class_config_str) ...
                struct('Length', class_config_str.Length, 'InputSection', class_config_str.InputSection);

                app.SubelementTypeDropDown.Items{end + 1} = 'classcavity';
                app.SubelementTypeDropDown.ItemsData{end + 1} = 'classcavity';
    
                app.ItemsToTypes('classcavity') = app.SubelementsTypes.classcavity;
                app.TypesToItems('classcavity') = 'classcavity';
            end 

            % %% Section Change
            % 
            % if ~isfield(app.SubelementsTypes, 'SectionChange')
            % 
            %     app.SubelementsTypes.SectionChange.ParametersPanelStruct = add_parameters_panel_structure ...
            %     (app, 'classsectionchange', 2, {'Input Surface', 'Output Surface'}, {'m2', 'm2'});
            % 
            %     app.SubelementsTypes.SectionChange.Color = '#6F4E37';
            %     app.SubelementsTypes.SectionChange.Marker = 'diamond';
            %     app.SubelementsTypes.SectionChange.HandleClasseBuilder = @(config) classsectionchange(config);
            % 
            %     app.SubelementTypeDropDown.Items{end + 1} = 'Section Change';
            %     app.SubelementTypeDropDown.ItemsData{end + 1} = 'SectionChange';
            % 
            %     app.ItemsToTypes('Section Change') = app.SubelementsTypes.SectionChange; 
            %     app.TypesToItems('Section Change') = 'Section Change';
            % end
        
            % Rectangular Junction


            if ~isfield(app.SubelementsTypes, 'classjunction_rectangular')

                app.SubelementsTypes.classcavity.ParametersPanelStruct = add_parameters_panel_structure ...
                (app, 'classcavity', 1, {'Length'}, {'m'});

                app.SubelementsTypes.classcavity.Color = '#30D5C8';
                app.SubelementsTypes.classcavity.Marker = 'diamond';
                app.SubelementsTypes.classcavity.HandleClassObject = @(config) classcavity(config);
                
                app.SubelementTypeDropDown.Items{end + 1} = 'classcavity';
                app.SubelementTypeDropDown.ItemsData{end + 1} = 'classcavity';
            end 
        
        end    
   
        function config = panel_to_config(~, panel)
            
            % Cette fonction parcourt tous les 'EditField' présents dans le panneau
            % des paramètres ouvert et retourne une structure de configuration
            % associée au type de sous-élément correspondant.
            
            % Initialise la structure de configuration vide
            config = struct();
            
            % Trouver tous les EditFields dans le panneau, y compris dans les sous-panneaux
            labels = findall(panel, 'Type', 'uilabel', 'Tag', 'Parameter');
            
            % Parcourir chaque EditField trouvé
            for i = 1:numel(labels)

                % Récupérer le Label associé à l'EditField par marqueur
                editfield = findall(panel, 'Type', 'uieditfield', 'Tag', labels(i).Text);
                
                % Nettoyer le texte du Label pour en faire un nom de champ valide
                cleanLabelText = supprimerEspaces(labels(i).Text);
                
                % Ajouter le champ convertit en double à la structure de configuration avec la valeur courante
                config.(cleanLabelText) = editfield.Value;
            end
        end
 

        function config_to_panel(~, config, panel)

            % Cette fonction permet d'afficher la configuration donnée en entrée dans le 
            % panneau des paramètres donné en entrée

            % On recupère les champs d'éditions présents sur le panneau
            edit_fields = findall(panel, 'Type', 'uieditfield');

            % On parcours ces champs
            for i = 1:numel(edit_fields)
                % On attribut au champ courant la valeur de la variable à laquelle il est associé 
                % donnée par la configuration
                edit_fields(i).Value = config.(supprimerEspaces(edit_fields(i).Tag));
            end
        end

        function add_element(app, varargin)
            
            % Cette fonction permet d'ajouter un nouvel élément 
            % Elle est appelée quand :
            % - un sous élement vide est cliqué en début de ligne. 
            % - le menu 'Add Element' est cliqué
            % - le menu 'Import Element' est cliqué

            % Le nouvel élement est systématiquement ajouté au dessus du
            % précédent puisque l'ordre des élements n'importe pas

            % Le processus d'importation consiste à 
            % - aller chercher dans les fichiers un objet de type 'classelement'
            % - Identifier le dernier élement crée et ajoute un attribut à la structure app.ElementsDatas
            % - Lui fournir l'objet de classe en attribut
            % - Appeler la fenêtre de dialogue et définir la surface d'entrée de l'élement
            % - Implémenter un à un les sous-éléments de la liste de sous-élements de l'objet de classe
            % dans la structure app.SubelementsDatas
            % - Ajouter les noms des sous-élements dans les attributs de l'élement crée
            
            % varargin = {class_object ...

            % On ajoute un élément vierge à la structure app.ElementsDatas 

            element_position = numel(app.ElementsDatas) + 1;
            
            config = struct();
            config.ListOfSubelements = {};
            config.InputSurface = 0;
            config.EndStatus = 'closed';
            element = classelement(config);  

            if nargin > 1 % l'object de type élement est renseigné

                element = varargin{1};
                config = element.Configuration;

                % On ajoute tous les sous-élements à la structure
                for i = 1:numel(config.ListOfSubelements)
                    subelement = config.ListOfSubelements{i};
                    type_name = app.SubelementsTypes.(class(subelement)).TypeName;
                    add_subelement(app, [i, element_position], type_name, subelement)
                end  
            
                % Si l'élément importé est ouvert
                if element.Configuration.EndStatus == "opened"

                    % On ajoute en bout de ligne un sous-élément vierge
                    add_subelement(app, [numel(config.ListOfSubelements) + 1, numel(app.ElementsDatas)], 'None')
                end

            else % si l'objet n'est pas renseigné

                % On ajoute un sous-élément indéfini et un sous-élement vierge à la structure app.SubelementsDatas
                add_subelement(app, [1, element_position], 'Undefined');
                add_subelement(app, [2, element_position], 'None');

                % On ajoute un sous-élement vierge au dessus du sous-élement selectionné
                add_subelement(app, [1, element_position + 1], 'None');
            end
            
            % On met à jour j'affichage de l'interface
            plot_subelements(app);

            app.ElementsDatas{element_position}.HandleClassObject = element;
        end
        
        function import_element(app)

            % Le processus d'importation consiste à 
            % - aller chercher dans les fichiers un objet de type 'classelement'
            % - Identifier le dernier élement crée et ajoute un attribut à la structure app.ElementsDatas
            % - Lui fournir l'objet de classe en attribut
            % - Appeler la fenêtre de dialogue et définir la surface d'entrée de l'élement
            % - Implémenter un à un les sous-éléments de la liste de sous-élements de l'objet de classe
            % dans la structure app.SubelementsDatas
            % - Ajouter les noms des sous-élements dans les attributs de l'élement crée

            % On ouvre une boîte de dialogue pour récupérer l'élement souhaité
            [file, path] = uigetfile(); 
            selectedfile = fullfile(path, file); % Construit le chemin complet du fichier sélectionné
            element = importdata(selectedfile);

            add_element(app, element); 
        end

        function import_subelement(app)

            % Importer un sous-élément consiste à importer un objet de classe, obtenir sa classe 
            % avec la fonction class(objet), à créer un sous-élement vierge du même type à l'emplacement 
            % souhaité
            % D'importer la structure de configuration de l'objet objet.Configuration et de l'attribuer 
            % dans la structure du sous-élements
            % puis d'afficher le panneau des paramètres associé à celui-ci avec les bonnes valeurs

            % On ouvre une boîte de dialogue pour récupérer l'élement souhaité
            [file, path] = uigetfile(); 
            selectedfile = fullfile(path, file); % Construit le chemin complet du fichier sélectionné
            data = importdata(selectedfile);

            subelement = empty_subelement(app.SelectedSubelementPosition);
            subelement.Class = class(data);
            subelement.Object = data;
            subelement.Config = data.Configuration;
            
        end
        
        function add_subelement(app, subelement_position, varargin)
            
            % Cette fonction permet d'ajouter un sous-élement dans app.SubelementsDatas
            % Elle est appelée :
            % - à la création d'un nouvel élement avec la fonction 'add_element'
            % - à la création d'un nouveau sous-élément (appui sur un sous-élement indéfini en fin de ligne)
            % - lors de l'appui sur le bouton 'Create Subelement'. Dans ce cas, le sous-élement 
            % courant est écrit par dessus le précèdent
            % lors de l'importation d'un élement (menu 'Import Element')
            % lors de l'importation d'un sous-élément (menu 'Import Subelement')
            %
            % varargin = {type_name, class_object, ...
            
            subelement = struct();
            subelement.Position = subelement_position;  
            
            % Si le type de sous-élement est fourni (obligatoire)
            if nargin > 2
                subelement.Type = app.SubelementsTypes.(varargin{1});
                
                % Si l'objet de classe est fourni
                if nargin > 3  
                    % On créer une configuration d'application à partir de
                    % la configuration de classe
                    class_config_str = perso_struct_num2str(varargin{2}.Configuration);
                    subelement.AppConfig = subelement.Type.HandleAppConfig(class_config_str);
                end        
            end                 

        app.SubelementsDatas{subelement_position(1), subelement_position(2)} = subelement;
        
        end

        function plot_subelements(app)

            % Description:
            % Affiche les sous-éléments contenus dans la structure app.SubelementsDatas 
            % et ajuste les limites des axes pour s'assurer que tous les sous-éléments sont visibles.
        
            hold(app.UIAxes, 'on');
        
            % On vide les élements qui existent déjà dans la fenêtre graphique
            cla(app.UIAxes);
        
            % Initialisation des valeurs maximales de position
            x_max = 0;
            y_max = 0;
        
            % Boucle d'affichage de tous les sous-éléments
            for i = 1:numel(app.SubelementsDatas)
  
                current_position = app.SubelementsDatas{i}.Position;
                x_max = max(x_max, current_position(1));
                y_max = max(y_max, current_position(2));

                % On créer un objet graphique associé au sous-élement courant
                app.SubelementsDatas{i}.Plot = plot(app.UIAxes, current_position(1), current_position(2), ...
                'Marker', app.SubelementsDatas{i}.Type.Marker, 'MarkerSize', 15, ...
                'LineWidth', 2, 'Color',  app.SubelementsDatas{i}.Type.Color);

                % On donne au sous-élément graphique un callback qui se déclenche lorsque on clique dessus
                app.SubelementsDatas{i}.Plot.ButtonDownFcn = @(src, event) SubelementSelected(app, src, event);

                % On crée un lien d'appel bidirectionnel entre les deux sous-élements 
                app.SubelementsDatas{i}.Plot.UserData = app.SubelementsDatas{i};
            end

        
            % Redimensionnement des limites de l'affichage pour s'assurer que tous les sous-éléments sont visibles
            padding = 1; % Padding pour éviter que les sous-éléments ne soient trop proches du bord
            set(app.UIAxes, 'XLim', [0, x_max + padding]);
            set(app.UIAxes, 'YLim', [0, y_max + padding]);
        end

        
        function SubelementSelected(app, src, ~)

            % Lorsque un sous-élement est cliqué :
            % - on affiche le panneau des paramètres correspondant 
   
            % On retrouve le sous-élement dont le sous-élement graphique est l'attribut
            % disp(src);
            selected_subelement = src.UserData;
            type = selected_subelement.Type;
            type_name = type.TypeName;
            app.Position = selected_subelement.Position;

            % On met à jour l'affichage du panneau des paramètres
            app.SubelementTypeDropDown.Value = type.TypeName;
            SubelementTypeDropDownValueChanged(app, []);

            % Si le sous-élément est un '+' dans la première colonne
            if type_name == "None" && app.Position(1) == 1
                
               % On crée un élément vierge à l'emplacement du sous-élement sélectionné 
               % - ajout de deux sous-éléments de type 'None' au dessus et à droite
               % -ajout d'un sous-élement de type 'Undefined' à
               % l'emplacement de la sélection

               add_element(app, app.Position(2));  

               % On change la valeur du menu déroulant vers 'Undefined'
               app.SubelementTypeDropDown.Value = 'Undefined';
               SubelementTypeDropDownValueChanged(app, []);
                
               % On modifie la surface d'entrée de l'élément sélectionné
               open_input_surface_window(app.InputSurfaceWindow, app.Position(2));

            % Si le sous-élement est vierge et à l'extremité d'un élement existant
            elseif type_name == "None"

                % On remplace l'élément actuel par un élement indéfini
                add_subelement(app, [app.Position(1), app.Position(2)], 'Undefined')

                % On change la valeur du menu déroulant vers 'Undefined'
                app.SubelementTypeDropDown.Value = 'Undefined';
                SubelementTypeDropDownValueChanged(app, []);

                % On ajoute un sous-élement vierge à droite du sous-élement sélectionné
                add_subelement(app, [app.Position(1) + 1, app.Position(2)], 'None');

           % Si l'élement est indéfini, on ne fait rien de plus que mettre à jour le menu type de sous-élement
            elseif  type_name == "Undefined"

            % Si le sous-élement est défini : 
            else

                % % On change la valeur du menu déroulant des configuration
                % app.SubelementsTypes.(type_name).ParametersPanelStruct.Panel.ConfigDropDown.Value = ...
                % selected_subelement.ConfigName; % POUR PLUS TARD

                %  on récupère sa configuration et on l'affiche dans le panneau courant
                config_to_panel(app, selected_subelement.AppConfig, selected_subelement.Type.ParametersPanelStruct.Panel);

            
            end

            % Mise à jour de l'affichage des sous-éléments dans la fenêtre graphique
            plot_subelements(app);
            
        end
        
        function ConfigDropDownValueChanged(app, ~, ~)

            % Cette fonction est déclenchée lorsque la valeur du menu déroulant des configurations
            % est modifiée par l'utilisateur
            
            subelement = app.SubelementsDatas{app.Position(1), app.Position(2)};
            type = subelement.Type;
            panel = type.ParametersPanelStruct.Panel;
            config_name = panel.ConfigDropDown.Value;
            config = app.SubelementsDatas{app.Position(1), app.Position(2)}.Type.Config.(config_name);

            config_to_panel(app, config, panel);
      

            % On modifie le sous-élement
            subelement.ConfigName = config_name;
            subelement.Config = config;
        end
        
        function enable_parameters_panel(app)

            % Cette fonction prend en entrée une instance de Main_App.mlapp
        
            % Elle permet d'activer les élements du panneau d'affichage des paramètres
            % à savoir : 
            %   - le bouton 'Delete Subelement'
            %   - le menu déroulant 'Subelement Type'
        
            app.SubelementTypeDropDown.Enable = 'on';
        end

        function HandleVariablesTableUpdated(app)

            % Cette fonction prend comme argument l'application principale
            % Elle permet de mettre à jour la structure app.HandleVariables à partir des valeurs 
            % inscrites par l'utilisateur dans le tableau des variables muettes
            
            % On recherche le nombre de variables muettes déclarées
            n = length(app.HandleVariablesTable.Data{:, 1});
            for i = 1 : n
                % On vérifie que la ligne n'est pas vide
                var_name = app.HandleVariablesTable.Data{i, 1}{1};
                if not(var_name == "")
                    app.HandleVariables.(var_name).Value = app.HandleVariablesTable.Data{i, 2};
                    app.HandleVariables.(var_name).LowerBound = app.HandleVariablesTable.Data{i, 3};
                    app.HandleVariables.(var_name).UpperBound = app.HandleVariablesTable.Data{i, 4};
                end
            end
        end

        function compute(app, var_struct)
               
            % Pour l'instant on suppose qu'il n'y a qu'un seul element dans
            % l'application. Le calcul consiste à 
            % - recupérer les données des variables muette dans une
            % structure var_struct (compatible avec app.HandleVariables)
            % - parcourir tous les sous-élements d'un élement et évaluer la
            % config d'application

            list_of_subelements = {};

            for i = 1:length(app.SubelementsDatas(:,1)) 
                app_subelement = app.SubelementsDatas{i, 1};
                % On convertit la configuration d'application en double, on
                % remplace les champs muets et on construit l'élement de
                % classe associé à la configuration de classe
                list_of_subelements{end+1} = app_subelement.Type.HandleClassObject(...
                replace_fields(perso_struct_str2double(app_subelement.AppConfig), var_struct));
            end

            input_surface = list_of_subelements{1}.Configuration.PlateSection; % marche seulement pour MPP
            element = classelement(classelement.create_config(list_of_subelements, 'closed', input_surface)); % Il faut donner la surface d'entrée
            env = create_environnement(23, 100000, 23, 1, 3000, 100);

            fields = fieldnames(var_struct);
            
            element.plot_alpha(env, [fields{1} ' = ' num2str(var_struct.(fields{1}).Value)]);
        end 
    end
    
    methods (Access = private)
        
        function plot_parameters_panel(app, subelement)

            % Cette fonction affiche le panneau des paramètres associé au sous-élément fourni en entrée
            % Elle effectue les opérations suivantes : 
            % - mise à jour de la valeur du menu déroulant 'Subelement Type'. Cette opération entraine : 
            %       - l'affichage du panneau associé à la classe du sous-élement considéré
            %       - l'activation ou non des boutons 'Create Subelement' et 'Save as Configuration'
            % - mise à jour de la valeur du menu déroulant 'Config' dans le panneau de la classe considérée. Cette opération entraine : 
            %       - le rempissage du panneau par les valeurs associé à la configuration du sous-élément sélectionné

            % Mise à jour de la valeur du menu déroulant 'Subelement Class'
            app.SubelementTypeDropDown.Value = subelement.Type.TypeName;
            SubelementTypeDropDownValueChanged(app, []);

            % Mise à jour de la valeur du menu déroulant 'Config' dans le panneau de la classe considérée
            subelement.Type.ParametersPanelStruct.ConfigDropDown.Value ...
            = subelement.Config.ConfigName;

        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
     
            % Cette fonction se déclenche à l'appel su script 'launch app'
            % dans matlab. 

            % varargin = {air_parameters_window, input_surface_window, configuration_name_window, ...}
            
            % On initialise l'espace de travail (sous élements, élements, etc.)
            init_workspace(app);

            % Si l'application est lancée avec des arguments
            if nargin > 1

                % On définit la fenêtre de dialogue associée aux propriétés de l'air
                app.AirParametersWindow = varargin{1};
                app.AirParametersWindow.MainApp = app;

                if nargin > 2

                    % On définit la fenêtre de dialogue associée à la surface d'entrée des différents éléments
                    app.InputSurfaceWindow = varargin{2};
                    app.InputSurfaceWindow.MainApp = app;
                    app.InputSurfaceWindow.ElementNumber = 1;
                    app.InputSurfaceWindow.InputSurfaceValue = 0;

                    if nargin > 3

                        % On définir la fenêtre de dialogue associée au nom des configurations
                        app.ConfigurationNameWindow = varargin{3};
                        app.ConfigurationNameWindow.MainApp = app;

                    end
                end
            end
        end

        % Button pushed function: DeleteSubelementButton
        function DeleteSubelementButtonPushed(app, event)
%             try

                d = datacursormode(app.UIFigure);
                deleteSubelement(app, d.getCursorInfo.Position);
                
%             catch
%                  warndlg('Select a valid subelement')   
%             end
           
        end

        % Value changed function: SubelementTypeDropDown
        function SubelementTypeDropDownValueChanged(app, event)

            % Cette commande contrôle l'affichage de la fenêtre des paramètres du sous-élement courant
            % La valeur du menu déroulant peux changer si :
            %   - l'utiliateur change manuellement le type d'un sous-élement de la configuration
            %   - l'utilisateur clique sur un sous-élement dont le type est différent du sous-élement précedemment sélectionné 

            value = app.SubelementTypeDropDown.Value;
        
            switch value
        
                case 'Porous Material (Limp)'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'on';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                case 'classMPP_Circular'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'on';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                case 'SlitMPP'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'on';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                case 'RectangularMPP'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'on';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                % case 'Helmholtz Resonator (Free Geometry)'
                %     app.DeleteSubelementButton.Enable = 'on';
                %     app.CreateSubelementButton.Enable = 'on';
                %     app.SaveAsConfigurationButton.Enable = 'on';
                %     app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'on';
                %     app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                % case 'Helmholtz Resonator (Cylindrical)'
                %     app.DeleteSubelementButton.Enable = 'on';
                %     app.CreateSubelementButton.Enable = 'on';
                %     app.SaveAsConfigurationButton.Enable = 'on';
                %     app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'on';
                %     app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                %     app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
                % 
                case 'Screen'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'on';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                case 'Grid'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'on';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                case 'QWL'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'on';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                case 'classcavity'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'on';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
        
                case 'SectionChange'
                    app.DeleteSubelementButton.Enable = 'on';
                    app.CreateSubelementButton.Enable = 'on';
                    app.SaveAsConfigurationButton.Enable = 'on';
                    app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                    app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'on';
        
                otherwise

                    try
                        app.DeleteSubelementButton.Enable = 'off';
                        app.CreateSubelementButton.Enable = 'off';
                        app.SaveAsConfigurationButton.Enable = 'off';
                        app.SubelementsTypes.classJCA_Rigid.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.classMPP_Circular.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.SlitMPP.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.RectangularMPP.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.FreeHelmoltzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.CylindricalHelmholtzResonator.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.Screen.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.Grid.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.QWL.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.classcavity.ParametersPanelStruct.Panel.Visible = 'off';
                        app.SubelementsTypes.SectionChange.ParametersPanelStruct.Panel.Visible = 'off';
                    catch ME
                        disp(ME.Message)
                    end
            end

        end

        % Menu selected function: ModifycurrentelementsinputsurfaceMenu
        function ModifycurrentelementsinputsurfaceMenuSelected(app, event)
            
            % Ce Callback permet de modifier la surface d'entrée de l'élement sélectionné

            % On lance une fonction publique de l'instance de 'Input_Surface_App.mlapp' attribuée à l'application principale
            app.InputSurfaceWindow.modify_input_surface(app, app.SelectedSubelementPosition(1));  % A changer

        end

        % Menu selected function: ModifyairparametersMenu
        function ModifyairparametersMenuSelected(app, event)
            
            % Cette fonction réouvre la fenêtre de préconfiguration permettant de modifier les paramètres
            % de l'air et de la cellule élementaire

            % On appelle l'application secondaire
            app.AirParametersWindow = Bell_EPC_App(app);

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            
            % Ce Callback est exécuté lorsque l'utilisateur clique sur le menu 'Save'

            % Il permet de stocker tous les réglages de l'interface

            % On définit un emplacement pour stocker l'interface
            [file, path] = uiputfile('*.EPC'); 
            filename = fullfile(path, file); 

            % On crée une structure contenant les données contenues dans l'application
            data_save = struct();
            data_save.SubelementsClasses = app.TypesToItems;
            data_save.HandleVariables = app.HandleVariables;
            data_save.SubelementsDatas = app.SubelementsDatas;
            data_save.ElementDatas = app.ElementsDatas;

            save(filename, 'data_save')

        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)

            % Cette fonction charge les données pour l'application à partir d'un fichier précédent.
            
            % Ouvre une boîte de dialogue pour sélectionner un fichier avec extension .EPC
            [file, path] = uigetfile('*.EPC'); 
            selectedfile = fullfile(path, file); % Construit le chemin complet du fichier sélectionné
            datas = importdata(selectedfile); % Importe les données du fichier
            
            % On atttribue les données importées à chaque propriété de l'application
            app.SubelementsTypes = datas.SubelementsClasses;
            app.SubelementsDatas = datas.SubelementDatas;
            app.ElementsDatas = datas.ElementDatas;
            app.HandleVariables = datas.HandleVariables;     

            % On affichage les sous-élements dans la fenêtre d'affichage
            plot_subelements(app);

            % On met à jour l'affichage du tableau des variables muettes
            display_handle_variables_table(app)
            

            % On réinitialise l'affichage de la fenêtre des paramètres
            app.SubelementTypeDropDown.Value = 'Class'; 
            SubelementTypeDropDownValueChanged(app, []);

        end

        % Menu selected function: SaveConfigurationMenu
        function SaveConfigurationMenuSelected(app, event)

            % Ouvre une boîte de dialogue pour sauvegarder un fichier avec extension .config
            [file, path] = uiputfile('*.config');
            filename = fullfile(path, file); % Construit le chemin complet du fichier
            
            % Récupère les noms de tous les types de sous-éléments dans l'application
            subelements_classes = fieldnames(app.SubelementsTypes); 
            
            % Initialise une structure pour stocker les données à sauvegarder
            datas = struct();
        
            % Boucle sur tous les types de sous-éléments
            for i = 1 : length(subelements_classes)
                % Vérifie si le panneau de paramètres du type courant est visible
                if app.SubelementsTypes.(subelements_classes{i}).ParametersPanelStruct.Panel.Visible == "on"
                    % Stocke le panneau de paramètres du type courant
                    datas.ParametersPanelStruct = app.SubelementsTypes.(subelements_classes{i}).ParametersPanelStruct;
                    
                    % Récupère la valeur du menu déroulant des configurations pour le type courant
                    datas.ConfigDropDownValue = app.SubelementsTypes.(subelements_classes{i}).ParametersPanelStruct.ConfigDropDown.Value; 
                    
                    % Boucle sur toutes les configurations disponibles dans le menu déroulant
                    for j = 1 : length(app.SubelementsTypes.(subelements_classes{i}).ParametersPanelStruct.ConfigDropDown.Items)
                        % Vérifie si l'élément courant du menu déroulant correspond à la configuration sélectionnée
                        if strcmp(app.SubelementsTypes.(subelements_classes{i}).ParametersPanelStruct.ConfigDropDown.Items{j}, datas.ConfigDropDownValue)
                            % Récupère tous les noms de configurations pour le type de sous-élément
                            config_names = fieldnames(app.SubelementsTypes.(subelements_classes{i}).Configs);
                            % Stocke les paramètres de la configuration correspondant à l'élément sélectionné
                            datas.ConfigParameters = app.SubelementsTypes.(subelements_classes{i}).Configs.(config_names{j});
                        end
                    end
                end
            end
            
            % Sauvegarde les données dans un fichier .config
            save(filename, '-struct', 'datas', 'ConfigDropDownValue', 'ConfigParameters', 'ParametersPanelStruct');

        end

        % Menu selected function: ImportConfigurationMenu
        function ImportConfigurationMenuSelected(app, event)
    
            % Ce ballback permet d'importer une configuration et de l'intégrer à la structure app.SubelementsClasses

            % Ouvre une boîte de dialogue pour sélectionner un fichier avec extension .config
            [file, path] = uigetfile();
            selectedfile = fullfile(path, file); % Construit le chemin complet du fichier sélectionné
            
            % Importe les données depuis le fichier sélectionné
            datas = importdata(selectedfile); % datas = config.Class
            %                                                 .ConfigName = 'name'
            %                                                 .Value.Param_1 = value_param_1

            
            % Récupère les noms des configurations du panneau de paramètres importé
            configs_name = fieldnames(app.SubelementsTypes.(datas.Class).Configs); 
            
            % Génère un nouveau numéro pour la nouvelle configuration
            % Extraie le numéro de la dernière configuration, l'incrémente et le convertit en chaîne de caractères
            new_config_number = num2str(str2double(regexp(configs_name{end}, '\d*', 'Match')) + 1);
            
            % Ajoute la nouvelle configuration importée dans la structure des configurations
            app.SubelementsTypes.(datas.Class).Configs.('Config_' + num2str(new_config_number)) = datas.Value;
            
            % Ajoute la nouvelle valeur au menu déroulant des configurations
            app.SubelementsTypes.(datas.Class).ConfigDropDown.Items{end+1} = datas.ConfigName;

        end

        % Button pushed function: AddlineButton
        function AddlineButtonPushed(app, event)
            % ajoute une ligne dans le tableau des variables muettes
            len_table = length(app.HandleVariablesTable.Data{:, 1}); % number of raw
            app.HandleVariablesTable.Data(len_table + 1, :) = {'' NaN NaN NaN ''}; % adds
        end

        % Button pushed function: DeletelastlineButton
        function DeletelastlineButtonPushed(app, event)
            
            % Supprime la dernière ligne dans le tableau des variables
            % muettes
            len_table = length(app.HandleVariablesTable.Data{:, 1}); % number of raw
            app.HandleVariablesTable.Data(len_table, :) = []; % deletes
        end

        % Display data changed function: HandleVariablesTable
        function HandleVariablesTableDisplayDataChanged(app, event)
            
            % Ce Callback est exécuté dès qu'une cellule est édité

            % Il sauvegarde les données du tableau dans la structure app.HandlesVariables
            
            % Mise à jour de la table des variables muettes
            HandleVariablesTableUpdated(app);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % 
            % 
            % % Vérifie que tous les sous-élements sont définis
            % error = disable_main_app(app);
            % % Opens the Input Surface Area app to set up the input settings
            % if ~error
            %     app.Computing = 1; % the user want to compute the model
            %     app.ElementInputSurfacesApp = Input_Surface_App(app); % Set input surface of the new element
            % end 

            compute(app, app.HandleVariables);
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            
            % Desactivate all the UIComponents of the Main_APP

            error = disable_main_app(app);
            % Opens the Input Surface Area app to set up the input settings
            if ~error
                app.Optimizing = 1; % the user want to optimize the model
                app.ElementInputSurfacesApp = Input_Surface_App(app); % Set input surface of the new element
            end
        
        end

        % Button pushed function: ParametricStudyButton
        function ParametricStudyButtonPushed(app, event)
        % % Desactivate all the UIComponents of the Main_APP
        %     error = disable_main_app(app);
        %     % Opens the Input Surface Area app to set up the input settings
        %     if ~error
        %         app.ParametricStudy = 1; % the user want to do a parametric study
        %         app.ElementInputSurfacesApp = Input_Surface_App(app); % Set input surface of the new element
        %     end    


        Parametric_Study_App(app);
        end

        % Callback function: CreateSubelementButton, UIFigure
        function CreateSubelementButtonPushed(app, event)
        
            % Ce Callback permet de :
            % - Ajouter un attribut .AppConfig au sous-élement sélectionné
            % - Modifier/ ajouter l'attribut .ConfigName, Modifier la configuration sélectionnée
            %   (si le menu déroulant des configurations n'est pas Manual)
            %
    
            % Il est exécuté : 
            %   - lorsque l'utilisateur enregistre la configuration courante
            %   - lorsque l'utilisateur appuie sur le bouton 'Create Subelement' de la fenêtre d'affichage des paramètres associée au sous-élement courant 
                     
            % Obtenir chaque paramètre du sous-élément à partir du champ de saisie
            % Créer la chaîne string_handle_function  
    
            subelement_selected = app.SubelementsDatas{app.Position(1), app.Position(2)};
            type = subelement_selected.Type;
            panel = type.ParametersPanelStruct.Panel;
    
            % On récupère les informations issues du panneau d'affichage des paramètres sous forme d'une configuration
            config = panel_to_config(app, panel);
    
            % On remplace les champs de la configuration qui détiennent des variables par leur
            % valeur indiquée dans le tableau des variables muettes
            % config = replace_fields(config, app.HandleVariables);
                    
            % On met à jour la configuration du sous-élement sélectionné
            app.SubelementsDatas{app.Position(1), app.Position(2)}.AppConfig = config;
    
            % % Si le sous-élement est associé à une configuration enregistrée
            % if ~panel.ConfigDropDown.Value == "Manual"
            %   type.Configs.(panel.ConfigDropDown.Value) = config; 
            % end
    
            % On réinitialise l'affichage des sous-élements dans la fenêtre graphique
            plot_subelements(app);
           
        end

        % Button pushed function: SaveAsConfigurationButton
        function SaveAsConfigurationButtonPushed(app, event)
            
            % Ce callback se déclenche lorsque le bouton 'Save As Configuration' est appuyé

            % Les opérations effectuées sont : 
            % - création d'une structure de configuration (panel_to_config)
            % - ouverture de la fenêtre de dialogue 'Configuration_Name_App.mlapp'
            % - enregistrement de la nouvelle configuration dans app.SubelementsClasses
            % - ajout de la configuration au menu déroulant des configurations

            % type_name = app.SubelementTypeDropDown.Value;

            % On récupère le panneau couramment ouvert
            panel = app.SubelementsDatas{app.Position(1), ...
            app.Position(2)}.Type.ParametersPanelStruct.Panel;

            % On convertit les données du panneau en une configuration
            config = panel_to_config(app, panel);

            % On implémente la nouvelle configuration
            open_configuration_name_window(app.ConfigurationNameWindow, config);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)  
        end

        % Menu selected function: ClearWorkspaceMenu
        function ClearWorkspaceMenuSelected(app, event)
            
            init_workspace(app);

            % On efface le panneau des paramètres couramment affiché si il existe
            try
                app.SubelementsTypes.(app.SubelementsDatas{app.Position(1), app.Position(2)}.Type.TypeName) ...
                .ParametersPanelStruct.Panel.Visible = 'off';
                drawnow;
            catch ME
                disp(ME.message)
            end

        end

        % Button pushed function: ImportElementButton
        function ImportElementButtonPushed(app, event)
           import_element(app);
        end

        % Menu selected function: ImportElementMenu
        function ImportElementMenuSelected(app, event)
           import_element(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'EPC Bell Main App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @CreateSubelementButtonPushed, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save as';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ClearWorkspaceMenu = uimenu(app.FileMenu);
            app.ClearWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearWorkspaceMenuSelected, true);
            app.ClearWorkspaceMenu.Text = 'Clear Workspace';
            app.ManageSettingsMenu = uimenu(app.UIFigure);
            app.ManageSettingsMenu.Text = 'Manage Settings';
            app.ModifyairparametersMenu = uimenu(app.ManageSettingsMenu);
            app.ModifyairparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifyairparametersMenuSelected, true);
            app.ModifyairparametersMenu.Text = 'Modify air parameters';
            app.ModifycurrentelementsinputsurfaceMenu = uimenu(app.ManageSettingsMenu);
            app.ModifycurrentelementsinputsurfaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifycurrentelementsinputsurfaceMenuSelected, true);
            app.ModifycurrentelementsinputsurfaceMenu.Text = 'Modify current element''s input surface';
            app.ConfigurationMenu = uimenu(app.UIFigure);
            app.ConfigurationMenu.Text = 'Configuration';
            app.SaveConfigurationMenu = uimenu(app.ConfigurationMenu);
            app.SaveConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigurationMenuSelected, true);
            app.SaveConfigurationMenu.Text = 'Save';
            app.ImportConfigurationMenu = uimenu(app.ConfigurationMenu);
            app.ImportConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportConfigurationMenuSelected, true);
            app.ImportConfigurationMenu.Text = 'Import Workspace';
            app.ImportElementMenu = uimenu(app.ConfigurationMenu);
            app.ImportElementMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportElementMenuSelected, true);
            app.ImportElementMenu.Text = 'Import Element';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.YLim = [-3 0];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.OptimizeButton = uibutton(app.UIFigure, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.ParametricStudyButton = uibutton(app.UIFigure, 'push');
            app.ParametricStudyButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricStudyButtonPushed, true);
            app.ParametricStudyButton.Text = 'Parametric Study';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.HandleVariablesPanel = uipanel(app.UIFigure);
            app.HandleVariablesPanel.Title = 'Handle Variables';
            app.HandleVariablesTable = uitable(app.HandleVariablesPanel);
            app.HandleVariablesTable.ColumnName = '';
            app.HandleVariablesTable.RowName = {};
            app.HandleVariablesTable.ColumnEditable = true;
            app.HandleVariablesTable.DisplayDataChangedFcn = createCallbackFcn(app, @HandleVariablesTableDisplayDataChanged, true);
            app.DeletelastlineButton = uibutton(app.HandleVariablesPanel, 'push');
            app.DeletelastlineButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastlineButtonPushed, true);
            app.DeletelastlineButton.Text = 'delete last line';
            app.AddlineButton = uibutton(app.HandleVariablesPanel, 'push');
            app.AddlineButton.ButtonPushedFcn = createCallbackFcn(app, @AddlineButtonPushed, true);
            app.AddlineButton.Text = 'add line';
            app.ImportElementButton = uibutton(app.UIFigure, 'push');
            app.ImportElementButton.ButtonPushedFcn = createCallbackFcn(app, @ImportElementButtonPushed, true);
            app.ImportElementButton.Text = 'Import Element';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.SubelementsPanel = uipanel(app.Panel);
            app.SubelementtypeLabel = uilabel(app.SubelementsPanel);
            app.SubelementtypeLabel.Text = 'Subelement Type';
            app.SubelementTypeDropDown = uidropdown(app.SubelementsPanel);
            app.SubelementTypeDropDown.Items = {'None', 'Undefined'};
            app.SubelementTypeDropDown.ItemsData = {'None', 'Undefined'};
            app.SubelementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SubelementTypeDropDownValueChanged, true);
            app.SubelementTypeDropDown.Value = 'None';
            app.DeleteSubelementButton = uibutton(app.SubelementsPanel, 'push');
            app.DeleteSubelementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubelementButtonPushed, true);
            app.DeleteSubelementButton.Enable = 'off';
            app.DeleteSubelementButton.Text = 'Delete Subelement';
            app.CreateSubelementButton = uibutton(app.SubelementsPanel, 'push');
            app.CreateSubelementButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSubelementButtonPushed, true);
            app.CreateSubelementButton.Enable = 'off';
            app.CreateSubelementButton.Text = 'Create Subelement';
            app.SaveAsConfigurationButton = uibutton(app.SubelementsPanel, 'push');
            app.SaveAsConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsConfigurationButtonPushed, true);
            app.SaveAsConfigurationButton.Enable = 'off';
            app.SaveAsConfigurationButton.Text = 'Save As Configuration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end