classdef Optimisation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        deletelastlineButton    matlab.ui.control.Button
        UpdateGabaritButton     matlab.ui.control.Button
        addlineGabaritButton    matlab.ui.control.Button
        LaunchStudyButton       matlab.ui.control.Button
        addlineVariablesButton  matlab.ui.control.Button
        HandleVariablesTable    matlab.ui.control.Table
        GabaritParametersTable  matlab.ui.control.Table
        GabaritGraph            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        Main_App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.Main_App = main_app;

            %% Initialisation du tableau des variables muettes

            format_variables = fieldnames(app.Main_App.HandleVariables);

            % On ajoute la possibilité de le rien indiquer
            format_variables{end+1} = 'None';

            app.HandleVariablesTable.ColumnFormat = {format_variables, 'char', 'char'};
            app.HandleVariablesTable.ColumnName = {'Variable', 'Lower Bound', 'UpperBound'};
            app.HandleVariablesTable.Data = {'None', '', ''};

            %% Initialisation du tableau des paramètres du gabarit

            format_gabarit = {'None', 'notch', 'low_pass', 'high_pass', 'band_pass'};
            app.GabaritParametersTable.ColumnFormat = {format_gabarit, 'char', 'char', 'char', 'char'};
            app.GabaritParametersTable.ColumnName = {'Shape', 'Frequency Peak', 'Band Width', 'Q factor', 'Alpha Max'};
            app.GabaritParametersTable.Data = {'None', '', '', '', ''};
        end

        % Button pushed function: addlineVariablesButton
        function addlineVariablesButtonPushed(app, event)
            
            % ajoute une ligne dans le tableau des variables muettes
            app.HandleVariablesTable.Data = [app.HandleVariablesTable.Data; {'None', ''}]; 
        end

        % Button pushed function: addlineGabaritButton
        function addlineGabaritButtonPushed(app, event)
            % ajoute une ligne dans le tableau des variables muettes
            app.GabaritParametersTable.Data = [app.GabaritParametersTable.Data; {'None', '', '', '', ''}]; 
        end

        % Button pushed function: deletelastlineButton
        function deletelastlineButtonPushed(app, event)
            app.GabaritParametersTable.Data = app.GabaritParametersTable.Data(1:end-1,:); 
        end

        % Button pushed function: UpdateGabaritButton
        function UpdateGabaritButtonPushed(app, event)
            w = app.Main_App.Environnement.w;
            gabarit = classgabarit(app.GabaritParametersTable.Data);
            plot(app.GabaritGraph, w/(2*pi), gabarit.build_gabarit(w));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GabaritGraph = uiaxes(app.UIFigure);
            title(app.GabaritGraph, 'Title')
            xlabel(app.GabaritGraph, 'Fréquence (Hz)')
            ylabel(app.GabaritGraph, 'Y')
            zlabel(app.GabaritGraph, 'Z')
            app.GabaritParametersTable = uitable(app.UIFigure);
            app.GabaritParametersTable.ColumnName = '';
            app.GabaritParametersTable.RowName = {};
            app.GabaritParametersTable.ColumnEditable = true;
            app.HandleVariablesTable = uitable(app.UIFigure);
            app.HandleVariablesTable.ColumnName = '';
            app.HandleVariablesTable.RowName = {};
            app.HandleVariablesTable.ColumnEditable = true;
            app.addlineVariablesButton = uibutton(app.UIFigure, 'push');
            app.addlineVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @addlineVariablesButtonPushed, true);
            app.addlineVariablesButton.Text = 'add line';
            app.LaunchStudyButton = uibutton(app.UIFigure, 'push');
            app.LaunchStudyButton.Text = 'Launch Study';
            app.addlineGabaritButton = uibutton(app.UIFigure, 'push');
            app.addlineGabaritButton.ButtonPushedFcn = createCallbackFcn(app, @addlineGabaritButtonPushed, true);
            app.addlineGabaritButton.Text = 'add line';
            app.UpdateGabaritButton = uibutton(app.UIFigure, 'push');
            app.UpdateGabaritButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGabaritButtonPushed, true);
            app.UpdateGabaritButton.Text = 'Update Gabarit';
            app.deletelastlineButton = uibutton(app.UIFigure, 'push');
            app.deletelastlineButton.ButtonPushedFcn = createCallbackFcn(app, @deletelastlineButtonPushed, true);
            app.deletelastlineButton.Text = 'delete last line';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Optimisation_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end