classdef Parametric_Study_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LaunchStudyButton     matlab.ui.control.Button
        addlineButton         matlab.ui.control.Button
        HandleVariablesTable  matlab.ui.control.Table
    end

    
    properties (Access = private)
        
        Main_App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.Main_App = main_app;

            % On définit les entrée possible de la table des variables 
            format = fieldnames(app.Main_App.HandleVariables);

            % On ajoute la possibilité de le rien indiquer
            format{end+1} = 'None';

            app.HandleVariablesTable.ColumnFormat = {reshape(format, 1, []), 'char'};
            app.HandleVariablesTable.ColumnName = {'Variable', 'Values list'};
            
            % Initialisation du tableau d'affichage des variables muettes (ui.control.table)
            app.HandleVariablesTable.Data = {'None', ''};
        end

        % Button pushed function: addlineButton
        function addlineButtonPushed(app, event)
            
            % ajoute une ligne dans le tableau des variables muettes
             app.HandleVariablesTable.Data = [app.HandleVariablesTable.Data; {'None', ''}]; % adds
        end

        % Button pushed function: LaunchStudyButton
        function LaunchStudyButtonPushed(app, event)
           
            % Lorsque on appuie sur le bouton lancer
            % - on créer la matrice de toutes les configurations à tester

            % On suppose pour l'instant qu'il n'y a que deux variables
            % et qu'elle est sur la première ligne du tableau
            % figure()
            hold on
            
            % Extraction des données de la table
            data = app.HandleVariablesTable.Data;
            param_names = data(:, 1);
            param_values_str = data(:, 2);
            
            % Conversion des chaînes de valeurs en vecteurs numériques
            param_values = cell(size(param_values_str));
            for i = 1:length(param_values_str)
                param_values{i} = str2num(param_values_str{i}); %#ok<ST2NM>
            end
            
            % Génération des combinaisons avec ndgrid
            combinations = cell(1, length(param_values));
            [combinations{:}] = ndgrid(param_values{:});
            
            % Réorganisation des combinaisons en une matrice
            num_combinations = numel(combinations{1});
            combinations_matrix = zeros(num_combinations, length(param_values));
            for i = 1:length(param_values)
                combinations_matrix(:, i) = combinations{i}(:);
            end

            % Création de structures pour chaque combinaison
            configurations(num_combinations) = struct();
            
            for i = 1:num_combinations
                for j = 1:length(param_names)
                    configurations(i).(param_names{j}).Value = num2str(combinations_matrix(i, j));
                end
            end

            % Création de structures pour chaque combinaison et lancement du calcul
            for i = 1:num_combinations
                % Générer la chaîne formatée
                formatted_string = '';
                for j = 1:length(param_names)
                    if j > 1
                        formatted_string = [formatted_string, ', '];
                    end
                    formatted_string = [formatted_string, param_names{j}, ' = ', num2str(combinations_matrix(i, j))];
                end
                app.Main_App.HandleVariables = transferFields(configurations(i), app.Main_App.HandleVariables);
                % Lancer le calcul avec la chaîne formatée
                app.Main_App.compute(formatted_string);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HandleVariablesTable = uitable(app.UIFigure);
            app.HandleVariablesTable.ColumnName = '';
            app.HandleVariablesTable.RowName = {};
            app.HandleVariablesTable.ColumnEditable = true;
            app.addlineButton = uibutton(app.UIFigure, 'push');
            app.addlineButton.ButtonPushedFcn = createCallbackFcn(app, @addlineButtonPushed, true);
            app.addlineButton.Text = 'add line';
            app.LaunchStudyButton = uibutton(app.UIFigure, 'push');
            app.LaunchStudyButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchStudyButtonPushed, true);
            app.LaunchStudyButton.Text = 'Launch Study';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parametric_Study_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end