classdef Appwithfigure_clement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ComputeButton               matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        SublementtypeDropDown       matlab.ui.control.DropDown
        SublementtypeDropDownLabel  matlab.ui.control.Label
        DeleteSubelementButton      matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        datas % structure with all the subelements
        UIComponents % Visual components of the app
    end

    methods (Access = private)
        
   
        function output = addsubelement(app,~,event_obj) 
             % ~         Currently not used (empty)
             % event_obj Object containing event datas structure
             % output    datas cursor output
            
            current_position = event_obj.Position; %return the position [x, y]
       

            fnames = fieldnames(app.datas);      
            for i = 1:length(fnames)
                subelement = getfield(app.datas,fnames{i});
                coordinates = subelement.Position;

                if all(coordinates == current_position) && subelement.Name =="None"
                    %modified + -> s
                    eval( "app.datas."+fnames{i}+".Name ='Undefined'");
                    eval( "app.datas."+fnames{i}+".Marker ='s'");
                    app.UIAxes.XTick = [app.UIAxes.XTick,length(app.UIAxes.XTick)];
                    app.UIAxes.XTickLabel{end+1} = length(app.UIAxes.XTickLabel);
                    %create a new + 
                    eval( "app.datas.Subelement"+num2str(double(fnames{end}(end))+1)+".Name ='None'");
                    eval( "app.datas.Subelement"+num2str(double(fnames{end}(end))+1)+".Marker ='+'");
                    eval( "app.datas.Subelement"+num2str(double(fnames{end}(end))+1)+".Position = [coordinates(1)+1 coordinates(2)]");
                    %Add a new + under to create a new element if wanted
                    if coordinates(1) == 1 
                        eval( "app.datas.Subelement"+num2str(double(fnames{end}(end))+1)+".Name ='None'");
                        eval( "app.datas.Subelement"+num2str(double(fnames{end}(end))+1)+".Marker ='+'");
                        eval( "app.datas.Subelement"+num2str(double(fnames{end}(end))+1)+".Position = [coordinates(1) coordinates(2)-1]");
                        plot(app.UIAxes,coordinates(1),coordinates(2)-1,'k','marker','+','MarkerSize',15,'LineWidth',2)
                        app.UIAxes.YTick = [app.UIAxes.YTick,length(app.UIAxes.YTick)];
                        app.UIAxes.YTickLabel{end+1} = char(length(app.UIAxes.XTickLabel)+65);
                        set(app.UIAxes,'YLim',[coordinates(2)-2 0])
                    end
                    %add the last + 
                    plot(app.UIAxes,coordinates(1)+1,coordinates(2),'k','marker','+','MarkerSize',15,'LineWidth',2)
                    
                end
                
                if all(coordinates == current_position)% si curseur sur cet élément
                    output={getfield(app.datas,fnames{i}).Name};%mouse text

                end
            end

            
%             draw the subelements
            fnames = fieldnames(app.datas);%a new subelement has been added
            for i = 1:length(fnames)%parcourt notre strucutre
                fi_coordinates = getfield(app.datas,fnames{i}).Position;
                for j = 1:length(app.UIAxes.Children)%parcourt les points du graph
                    plot_coordinates = [app.UIAxes.Children(j).XData app.UIAxes.Children(j).YData];
                    if all(fi_coordinates==plot_coordinates)%on regarde si ça correspond
                        marker = getfield(app.datas,fnames{i}).Marker;
                        set(app.UIAxes.Children(j),'Marker',marker)
                    end
                end
            end
     

        end


        function deletesubelement(app,current_position) 
             %delete the selected element
            
            
            fnames = fieldnames(app.datas);      
            for i = 1:length(fnames)
                subelement = getfield(app.datas,fnames{i});
                coordinates =  subelement.Position;
               if all(coordinates == current_position) && subelement.Name ~="None"                    
                    app.datas=rmfield(app.datas,fnames{i});%delete the subelement
               elseif coordinates(1) > current_position(1) && coordinates(2) == current_position(2)%sur la même ligne mais plus loin, il faut décaler les éléments
                   eval("app.datas."+fnames{i}+".Position(1) = subelement.Position(1)-1;");
               end

            end


            %             draw the subelements

            fnames = fieldnames(app.datas);      
            for i = 1:length(fnames)
                if i==1
                    hold(app.UIAxes,'off')
                elseif i==2
                    hold(app.UIAxes,'on')
                end
                coordinates = getfield(app.datas,fnames{i}).Position;
                marker = getfield(app.datas,fnames{i}).Marker;
                plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2)           
            end

        end
        
        function param_panel = plotParametersPanel(app,number_of_param,name_of_param,unit_of_param)
            %Create parameters panel under the subelement type selection 
            app.UIComponents.Parameters.ParametersPanel = uipanel(app.Panel);
            app.UIComponents.Parameters.ParametersPanel.Visible = 'off';
            app.UIComponents.Parameters.ParametersPanel.Position = [11 10 417 325];
    
            app.UIComponents.Parameters.PanelTitle = uilabel(app.UIComponents.Parameters.ParametersPanel);
            app.UIComponents.Parameters.PanelTitle.BackgroundColor = [0.8 0.8 0.8];
            app.UIComponents.Parameters.PanelTitle.HorizontalAlignment = 'center';
            app.UIComponents.Parameters.PanelTitle.FontWeight = 'bold';
            app.UIComponents.Parameters.PanelTitle.Position = [10 292 394 22];
            app.UIComponents.Parameters.PanelTitle.Text = 'PARAMETERS';

            if number_of_param >= 1
                for i = 1:number_of_param
                    %Parameter label
                    app.UIComponents.Parameters.ParameterLabel{i}=uilabel(app.UIComponents.Parameters.ParametersPanel);
                    app.UIComponents.Parameters.ParameterLabel{i}.HorizontalAlignment = 'right';
%                     app.UIComponents.Parameters.ParameterLabel{i}.VerticalAlignment = 'center';
                    app.UIComponents.Parameters.ParameterLabel{i}.Position = [48 250-32*(i-1) 126 32];
                    app.UIComponents.Parameters.ParameterLabel{i}.Text = name_of_param(i);
                    app.UIComponents.Parameters.ParameterLabel{i}.WordWrap = "on";

                    %Parameter Edit Field
                    app.UIComponents.Parameters.ParameterEditField{i} = uieditfield(app.UIComponents.Parameters.ParametersPanel,'numeric');
                    app.UIComponents.Parameters.ParameterEditField{i}.Position = [180 256-32*(i-1) 100 22];

                    %Parameter unit
                    app.UIComponents.Parameters.ParameterUnit{i} = uilabel(app.UIComponents.Parameters.ParametersPanel);
                    app.UIComponents.Parameters.ParameterUnit{i}.Position = [285 256-32*(i-1) 50 22];
                    app.UIComponents.Parameters.ParameterUnit{i}.Text = unit_of_param(i);
                    
                end
            end
            app.UIComponents.Parameters.SaveButton = uibutton(app.UIComponents.Parameters.ParametersPanel,'push');
            app.UIComponents.Parameters.SaveButton.ButtonPushedFcn = createCallbackFcn(app,@SaveButtonPushed,true);
            app.UIComponents.Parameters.SaveButton.Position = [335 9 75 22];
            app.UIComponents.Parameters.SaveButton.Text = "Save";

            
            param_panel = app.UIComponents.Parameters;
            

            %Create


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %plot initialisation
            app.datas.Subelement1.Name = "None";
            app.datas.Subelement1.Marker = '+';
            app.datas.Subelement1.Position = [1 -1];

%             app.datas.Subelement2.Name = "None";
%             app.datas.Subelement2.Marker = '+';
%             app.datas.Subelement2.Position = [1 -2];


%             draw the subelements

            hold( app.UIAxes, 'on' )
            fn = fieldnames(app.datas);      
            for i = 1:length(fn)
                coordinates = eval( "app.datas."+fn{i}+".Position");
                marker = eval( "app.datas."+fn{i}+".Marker");
                plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2)
            end

            % active datacursormode (selection mode)
            d = datacursormode(app.UIFigure);
            d.Enable = 'on';
            d.UpdateFcn = @app.addsubelement;

            % Set every Parameters Panel :
            % - Porous Material
            app.UIComponents.PorousMaterial = plotParametersPanel(app,6,["Porosity","Airflow Resistivity","Tortuosity","Viscous Characteristic Length","Thermal Characteristic Length","Thickness"],["%","Pa.s/m2","(no unit)","m","m","m"]);
                %create Frame
            app.UIComponents.PorousMaterial.Frame = uibuttongroup(app.UIComponents.PorousMaterial.ParametersPanel);
            app.UIComponents.PorousMaterial.Frame.SelectionChangedFcn = createCallbackFcn(app, @FrameSelectionChanged, true); % If Frame = 'Limp' : Create a Material density editfield + name label and unit label
            app.UIComponents.PorousMaterial.Frame.BorderType = 'none';
            app.UIComponents.PorousMaterial.Frame.Position = [115 47 195 46];
                % Create RigidButton
            app.UIComponents.PorousMaterial.RigidButton = uitogglebutton(app.UIComponents.PorousMaterial.Frame);
            app.UIComponents.PorousMaterial.RigidButton.Text = 'Rigid';
            app.UIComponents.PorousMaterial.RigidButton.Position = [69 13 62 22];
            app.UIComponents.PorousMaterial.RigidButton.Value = true;
                % Create LimpButton
            app.UIComponents.PorousMaterial.LimpButton = uitogglebutton(app.UIComponents.PorousMaterial.Frame);
            app.UIComponents.PorousMaterial.LimpButton.Text = 'Limp';
            app.UIComponents.PorousMaterial.LimpButton.Position = [134 13 62 22];
                % Create FrameLabel
            app.UIComponents.PorousMaterial.FrameLabel = uilabel(app.UIComponents.PorousMaterial.Frame);
            app.UIComponents.PorousMaterial.FrameLabel.HorizontalAlignment = 'right';
            app.UIComponents.PorousMaterial.FrameLabel.Position = [18 13 40 22];
            app.UIComponents.PorousMaterial.FrameLabel.Text = 'Frame';
                % Create Material Density editfield
            app.UIComponents.PorousMaterial.MaterialDensityLabel = uilabel(app.UIComponents.PorousMaterial.ParametersPanel);
            app.UIComponents.PorousMaterial.MaterialDensityLabel.Visible = "off";
            app.UIComponents.PorousMaterial.MaterialDensityLabel.Position = [48 20 126 32];
            app.UIComponents.PorousMaterial.MaterialDensityLabel.Text = "Material Density";
            app.UIComponents.PorousMaterial.MaterialDensityLabel.HorizontalAlignment = "right";
                %
            app.UIComponents.PorousMaterial.MaterialDensityEditField = uieditfield(app.UIComponents.PorousMaterial.ParametersPanel,"numeric");
            app.UIComponents.PorousMaterial.MaterialDensityEditField.Visible = "off";
            app.UIComponents.PorousMaterial.MaterialDensityEditField.Position = [180 26 100 22];
                %
            app.UIComponents.PorousMaterial.MaterialDensityUnitLabel = uilabel(app.UIComponents.PorousMaterial.ParametersPanel);
            app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Visible = "off";
            app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Position = [285 26 50 22];
            app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Text = "kg/m3";


            % - MPP
            app.UIComponents.MPP = plotParametersPanel(app,2,["Porosity","Thickness"],["%","m"]);
            % Create Shape
                %create Shape
            app.UIComponents.MPP.Shape = uibuttongroup(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.Shape.SelectionChangedFcn = createCallbackFcn(app, @ShapeSelectionChanged, true); 
            app.UIComponents.MPP.Shape.BorderType = 'none';
            app.UIComponents.MPP.Shape.Position = [67 167 300 46];
                % Create CircleButton
            app.UIComponents.MPP.CircleButton = uitogglebutton(app.UIComponents.MPP.Shape);
            app.UIComponents.MPP.CircleButton.Text = 'Circle';
            app.UIComponents.MPP.CircleButton.Position = [69 13 62 22];
            app.UIComponents.MPP.CircleButton.Value = true;
                % Create RectangleButton
            app.UIComponents.MPP.RectangleButton = uitogglebutton(app.UIComponents.MPP.Shape);
            app.UIComponents.MPP.RectangleButton.Text = 'Rectangle';
            app.UIComponents.MPP.RectangleButton.Position = [134 13 62 22];
                % Create SlitButton
            app.UIComponents.MPP.SlitButton = uitogglebutton(app.UIComponents.MPP.Shape);
            app.UIComponents.MPP.SlitButton.Text = 'Slit';
            app.UIComponents.MPP.SlitButton.Position = [199 13 62 22];
                % Create ShapeLabel
            app.UIComponents.MPP.ShapeLabel = uilabel(app.UIComponents.MPP.Shape);
            app.UIComponents.MPP.ShapeLabel.HorizontalAlignment = 'right';
            app.UIComponents.MPP.ShapeLabel.Position = [18 13 40 22];
            app.UIComponents.MPP.ShapeLabel.Text = 'Shape';
                % Create Dimensions Editfield
            app.UIComponents.MPP.RadiusLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.RadiusLabel.Position = [48 140 126 22];
            app.UIComponents.MPP.RadiusLabel.Text = "Radius";
            app.UIComponents.MPP.RadiusLabel.HorizontalAlignment = "right";

            app.UIComponents.MPP.WidthLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.WidthLabel.Position = [48 140 126 22];
            app.UIComponents.MPP.WidthLabel.Text = "Width";
            app.UIComponents.MPP.WidthLabel.Visible = "off";
            app.UIComponents.MPP.WidthLabel.HorizontalAlignment = "right";

            app.UIComponents.MPP.LengthLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.LengthLabel.Position = [48 140-32 126 22];
            app.UIComponents.MPP.LengthLabel.Text = "Length";
            app.UIComponents.MPP.LengthLabel.Visible = "off";
            app.UIComponents.MPP.LengthLabel.HorizontalAlignment = "right";            

            app.UIComponents.MPP.DimensionsEditField = uieditfield(app.UIComponents.MPP.ParametersPanel,"numeric");
            app.UIComponents.MPP.DimensionsEditField.Position = [180 140 100 22];

            app.UIComponents.MPP.LengthEditField = uieditfield(app.UIComponents.MPP.ParametersPanel,"numeric");
            app.UIComponents.MPP.LengthEditField.Position = [180 140-32 100 22];
            app.UIComponents.MPP.LengthEditField.Visible = "off";

            app.UIComponents.MPP.DimensionsUnitLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.DimensionsUnitLabel.Position = [285 140 50 22];
            app.UIComponents.MPP.DimensionsUnitLabel.Text = "m";

            app.UIComponents.MPP.LengthUnitLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.LengthUnitLabel.Position = [285 140-32 50 22];
            app.UIComponents.MPP.LengthUnitLabel.Visible = 'off';
            app.UIComponents.MPP.LengthUnitLabel.Text = "m";

            % - Helmholtz Resonator
            app.UIComponents.HR = plotParametersPanel(app,4,["Neck Radius","Neck Depth","Cavity Radius","Cavity Depth"],["m","m","m","m"]);

            % - Resistive Screen
            app.UIComponents.Screen = plotParametersPanel(app,2,["Screen Density","Thickness"],["kg/m3","m"]);
        end

        % Callback function
        function UIAxesButtonDown(app, event)
    
        end

        % Callback function
        function ButtonPushed(app, event)
% 

        end

        % Button pushed function: DeleteSubelementButton
        function DeleteSubelementButtonPushed(app, event)
            d = datacursormode(app.UIFigure);
            app.deletesubelement(d.getCursorInfo.Position);

        end

        % Callback function
        function UIAxesButtonDown2(app, event)
            
        end

        % Callback function
        function FrameSelectionChanged(app, event)
            if app.UIComponents.PorousMaterial.LimpButton.Value == 1
                app.UIComponents.PorousMaterial.MaterialDensityLabel.Visible = "on";
                app.UIComponents.PorousMaterial.MaterialDensityEditField.Visible = "on";
                app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Visible = "on";

            else
                app.UIComponents.PorousMaterial.MaterialDensityLabel.Visible = "off";
                app.UIComponents.PorousMaterial.MaterialDensityEditField.Visible = "off";
                app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Visible = "off";                
            end
        end

        % Close request function: UIFigure
        function ShapeSelectionChanged(app, event)
            app.UIComponents.MPP.DimensionsEditField.Value = 0;
            app.UIComponents.MPP.LengthEditField.Value = 0;
            app.UIComponents.MPP.RadiusLabel.Visible = "off";
            app.UIComponents.MPP.WidthLabel.Visible = "off";
            app.UIComponents.MPP.LengthLabel.Visible = "off";
            app.UIComponents.MPP.LengthEditField.Visible = "off";
            app.UIComponents.MPP.LengthUnitLabel.Visible = "off";
            app.UIComponents.MPP.LengthEditField.Visible = "off";
            if app.UIComponents.MPP.CircleButton.Value == 1
                app.UIComponents.MPP.RadiusLabel.Visible = "on";
            elseif app.UIComponents.MPP.RectangleButton.Value == 1
                app.UIComponents.MPP.LengthLabel.Visible = "on";
                app.UIComponents.MPP.WidthLabel.Visible = "on";
                app.UIComponents.MPP.LengthEditField.Visible = "on";
                app.UIComponents.MPP.LengthUnitLabel.Visible = "on";
            elseif app.UIComponents.MPP.SlitButton.Value == 1
                app.UIComponents.MPP.WidthLabel.Visible = "on";
            end

            
        end

        % Value changed function: SublementtypeDropDown
        function SublementtypeDropDownValueChanged(app, event)
            value = app.SublementtypeDropDown.Value;
            if strcmp(value,'Porous Material')
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "on";
                app.UIComponents.MPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
            elseif strcmp(value,'MPP')
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.MPP.ParametersPanel.Visible = "on";
                app.UIComponents.HR.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
            elseif strcmp(value,'Helmholtz Resonator')
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.MPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR.ParametersPanel.Visible = "on";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
            elseif strcmp(value,'Resistive Screen')
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.MPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "on";
            else
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.MPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
            end
        end

        % Button down function: UIFigure
        function SaveButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @ShapeSelectionChanged, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, {'  /\'; '  / || \'; '  ||'; ' Wave'})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-3 0];
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.XTick = [0 1];
            app.UIAxes.XTickLabel = {''; '1'};
            app.UIAxes.YTick = -1;
            app.UIAxes.YTickLabel = {'A'};
            app.DeleteSubelementButton = uibutton(app.UIFigure, 'push');
            app.DeleteSubelementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubelementButtonPushed, true);
            app.DeleteSubelementButton.Text = 'Delete Subelement';
            app.Panel = uipanel(app.UIFigure);
            app.SublementtypeDropDownLabel = uilabel(app.Panel);
            app.SublementtypeDropDownLabel.Text = 'Sublement type';
            app.SublementtypeDropDown = uidropdown(app.Panel);
            app.SublementtypeDropDown.Items = {'Type', 'Porous Material', 'MPP', 'Helmholtz Resonator', 'Resistive Screen'};
            app.SublementtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SublementtypeDropDownValueChanged, true);
            app.SublementtypeDropDown.Value = 'Type';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.Text = 'Compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appwithfigure_clement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end