classdef Appwithfigure_clement_2023_06_18 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ManageSettingsMenu          matlab.ui.container.Menu
        PresettingsMenu             matlab.ui.container.Menu
        InputSurfaceAreasMenu       matlab.ui.container.Menu
        ComputeButton               matlab.ui.control.Button
        DeleteSubelementButton      matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        SublementtypeDropDown       matlab.ui.control.DropDown
        SublementtypeDropDownLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SubelementDatas % structure with all the subelements
        UIComponents % Visual components of the app
        ElementInputSurfacesApp % Pop up app when a new element is created (get the input surface area of the element)
        ElementDatas
        PresettingsApp 
        FairingWidth
        EPCSurface
        AirParameters % structure composed of air tem
        Computing = 0 % used to know if the EPC is completed (if Compute button was pushed)

    end
    methods (Access = private)
        
%%%%%%%%%%%%%%%%%% IF YOU LEFT CLICK ON A SUBELEMENT %%%%%%%%%%%%%%%%%%%%%%   
        function output = subelementLeftClick(app,~,event_obj) 
             % ~         Currently not used (empty)
             % event_obj Object containing event SubelementDatas structure
             % output    SubelementDatas cursor output
            
            
            current_position = event_obj.Position; %return the position [x, y]

            fnames = fieldnames(app.SubelementDatas);      
            for i = 1:length(fnames)
                
                subelement_name = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement_name.Position;


        %%%%%%%%%%%%%%%%% Add a new subelement %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if all(coordinates == current_position) && subelement_name.Name =="None"
                    %modified + -> s
                    eval( "app.SubelementDatas."+fnames{i}+".Name ='Undefined'");
                    eval( "app.SubelementDatas."+fnames{i}+".Marker ='s'");
                    eval( "app.SubelementDatas."+fnames{i}+".Color ='#000000'");
                    app.UIAxes.XTick = [app.UIAxes.XTick,length(app.UIAxes.XTick)];
                    app.UIAxes.XTickLabel{end+1} = char(length(app.UIAxes.XTickLabel)+64);

                    %create a new + 
                    eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+1)+".Name ='None'");
                    eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+1)+".Marker ='+'");
                    eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+1)+".Position = [coordinates(1)+1 coordinates(2)]");
                    eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+1)+".Color ='#000000'");
                     %add the last + on the right
                    plot(app.UIAxes,coordinates(1)+1,coordinates(2),'k','Marker','+','MarkerSize',15,'LineWidth',2,'Color','#000000')
                    %Add a new + under to create a new element if wanted 
                    if coordinates(1) == 1 
                        app.ElementDatas.NumberOfElements = app.ElementDatas.NumberOfElements+1;
                        app.ElementDatas.ListOfInputSurfaces = [app.ElementDatas.ListOfInputSurfaces, 0];
                        eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+2)+".Name ='None'");
                        eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+2)+".Marker ='+'");
                        eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+2)+".Position = [coordinates(1) coordinates(2)-1]");
                        eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+2)+".Color = '#000000'");
                        app.UIAxes.YTick = [-(length(app.UIAxes.YTick)+1),app.UIAxes.YTick];
                        app.UIAxes.YTickLabel = {-app.UIAxes.YTick};
                    
                        set(app.UIAxes,'YLim',[coordinates(2)-2 0])
                        plot(app.UIAxes,coordinates(1),coordinates(2)-1,'k','Marker','+','MarkerSize',15,'LineWidth',2,'Color','#000000')
                    end
                    
                end
        %%%%%%%%%%%%% Clicking on a subelement make its name appear on the graph %%%%%%%%%%   
                if all(coordinates == current_position)% si curseur sur cet élément
                    output={getfield(app.SubelementDatas,fnames{i}).Name};%mouse text

                end
            end


        %%%%%%%%%%%%% draw the subelements on the graph %%%%%%%%%%%%%%%%%%%%%%%%%%%
            fnames = fieldnames(app.SubelementDatas);%a new subelement has been added
            xmax = 0;
            ymax = 0;
            for i = 1:length(fnames)%parcourt notre strucutre
                fi_coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                for j = 1:length(app.UIAxes.Children)%parcourt les points du graph
                    plot_coordinates = [app.UIAxes.Children(j).XData app.UIAxes.Children(j).YData];
                    if all(fi_coordinates==plot_coordinates)%on regarde si ça correspond
                        marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                        set(app.UIAxes.Children(j),'Marker',marker)
                    end
                end
                xmax = max(xmax,coordinates(1))+0.3;
                ymax = min(ymax,coordinates(2))-0.3;
            end
        
        %%%%%%%%%%%%% Make the corresponding panel appear with actual values on the right %%%%%%%%%
            fnames = fieldnames(app.SubelementDatas);
            for i = 1:length(fnames)
                
                subelement_name = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement_name.Position;
                subelement_name = getfield(app.SubelementDatas,fnames{i}).Name;
                if all(coordinates == current_position)     
                    if subelement_name =="None" || subelement_name == "Undefinied"
                    app.SublementtypeDropDown.Value = 'Type';
                    else
                    app.SublementtypeDropDown.Value = getfield(app.SubelementDatas,fnames{i}).Name;
                    end
                    app.SublementtypeDropDownValueChanged(NaN);
                end
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%% DELETE SUBELEMENT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function deletesubelement(app,current_position) 
             %delete the selected element
            
            
            fnames = fieldnames(app.SubelementDatas);      
            for i = 1:length(fnames)
                 % make sure the subelement called exists because some are deleted in the loop
                if isfield(app.SubelementDatas,fnames{i})
                    subelement = getfield(app.SubelementDatas,fnames{i});
                    coordinates =  subelement.Position;
                    if all(coordinates == current_position)
                        if subelement.Name ~= "None"% Make sure no + can be deleted
                            app.SubelementDatas = rmfield(app.SubelementDatas,fnames{i});% Delete the selected subelement
                            new_fnames = fieldnames(app.SubelementDatas);
                            
                            go_to_other_case = false;
                            if coordinates(1) == 1  %if a single-subelement element is deleted, delete the subelement, and both the + around. then create a +
                                % make sure the case is the right one
                                for j = 1 : length(new_fnames)
                                    
                                    other_subelements = getfield(app.SubelementDatas,new_fnames{j});
                                    if ismember(other_subelements.Position, [[coordinates(1) coordinates(2)+1] [coordinates(1)-1 coordinates(2)]])%delete subelement under and on the right 
                                        if other_subelements.Name ~= "None" %if the second subelement on the line isn't a +, we're not in the rigt case
                                            go_to_other_case = true; % We are in the other case if the subelement is NOT the only one on the line
                                        end
                                    end
                                end
                                % if the case is the actual one described before
                                if not(go_to_other_case)
                                    for j = 1 : length(new_fnames)
                                        other_subelements = getfield(app.SubelementDatas,new_fnames{j});
                                        if ismember(other_subelements.Position, [[coordinates(1)+1 coordinates(2)] [coordinates(1) coordinates(2)-1]])%delete subelement under and on the right 
                                            app.SubelementDatas = rmfield(app.SubelementDatas,new_fnames{j});
                                        end
                                    end
                                    %create a new + 
                                    eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+1)+".Name ='None'");
                                    eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+1)+".Marker ='+'");
                                    eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+1)+".Position = [coordinates(1) coordinates(2)]");
                                    eval( "app.SubelementDatas.Subelement"+num2str(str2double(fnames{end}(end))+1)+".Color ='#000000'");
                                    plot(app.UIAxes,coordinates(1),coordinates(2),'+','MarkerSize',15,'LineWidth',2,"Color",'#000000')
    
                                else % the general case
                                    new_fnames = fieldnames(app.SubelementDatas);
                                    for j = 1 : length(new_fnames) %all the subelements on the right of the deleted subelement go 1 to the left
                                        right_subelement = getfield(app.SubelementDatas,new_fnames{j});
                                        if right_subelement.Position(1) > subelement.Position(1)
                                            eval("app.SubelementDatas."+new_fnames{j}+".Position(1)=app.SubelementDatas."+new_fnames{j}+".Position(1) - 1;");
                                            
                                        end
                                    end
                                    
                                end
        
                            else % case when the subelement isn't the first one on the line
                                for j = 1 : length(new_fnames)%all the subelements on the right of the deleted subelement go 1 to the left
                                    right_subelement = getfield(app.SubelementDatas,new_fnames{j});
                                    if right_subelement.Position(1) > subelement.Position(1)
                                        eval("app.SubelementDatas."+new_fnames{j}+".Position(1)=app.SubelementDatas."+new_fnames{j}+".Position(1) - 1;");
                                    end
                                end
                            end
                        end
                    end

                end
            end
            xmax = 0;
            ymax = 0;
            fnames = fieldnames(app.SubelementDatas);      
            for i = 1:length(fnames)
                if i==1
                    hold(app.UIAxes,'off')
                elseif i==2
                    hold(app.UIAxes,'on')
                end
                coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2)
                xmax = max(xmax,coordinates(1))+0.3;
                ymax = min(ymax,coordinates(2))-0.3;
            end
            % resize the axis
            set(app.UIAxes,'XLim',[0 xmax]) 
            set(app.UIAxes,'YLim',[ymax 0])
            

        end


            %             draw the subelements

%%%%%%%%%%%%%%%% CREATE PARAMETERS PANNELS MORE EASILY %%%%%%%%%%%%%%%%%%%%       
        function param_panel = plotParametersPanel(app,number_of_param,name_of_param,unit_of_param)
            %Create parameters panel under the subelement type selection
            Parameters.ParametersPanel = uipanel(app.Panel);
            Parameters.ParametersPanel.Visible = 'off';
            Parameters.ParametersPanel.Position = [11 10 417 325];
            %Parameters Title
            Parameters.PanelTitle = uilabel(Parameters.ParametersPanel);
            Parameters.PanelTitle.BackgroundColor = [0.8 0.8 0.8];
            Parameters.PanelTitle.HorizontalAlignment = 'center';
            Parameters.PanelTitle.FontWeight = 'bold';
            Parameters.PanelTitle.Position = [10 292 394 22];
            Parameters.PanelTitle.Text = 'PARAMETERS';

            if number_of_param >= 1
                for i = 1:number_of_param
                    %Parameter label
                    Parameters.ParameterLabel{i}=uilabel(Parameters.ParametersPanel);
                    Parameters.ParameterLabel{i}.HorizontalAlignment = 'right';
                    Parameters.ParameterLabel{i}.Position = [48 250-32*(i-1) 126 32];
                    Parameters.ParameterLabel{i}.Text = name_of_param(i);
                    Parameters.ParameterLabel{i}.WordWrap = "on";

                    %Parameter Edit Field
                    Parameters.ParameterEditField{i} = uieditfield(Parameters.ParametersPanel,'numeric');
                    Parameters.ParameterEditField{i}.Position = [180 256-32*(i-1) 100 22];

                    %Parameter unit
                    Parameters.ParameterUnit{i} = uilabel(Parameters.ParametersPanel);
                    Parameters.ParameterUnit{i}.Position = [285 256-32*(i-1) 50 22];
                    Parameters.ParameterUnit{i}.Text = unit_of_param(i);
                    
                end
            end
            Parameters.SaveButton = uibutton(Parameters.ParametersPanel,'push');
            Parameters.SaveButton.ButtonPushedFcn = createCallbackFcn(app,@SaveButtonPushed,true);
            Parameters.SaveButton.Position = [335 9 75 22];
            Parameters.SaveButton.Text = "Save";

            
            param_panel = Parameters;
        end
        
    end
    
    methods (Access = public)
        
        function importElementInputSurfaces(app,input_surface)
            app.ElementDatas.Element.InputSurfaces = input_surface;
        end
        
        function updateInputSurfaces(app,list_of_input_surfaces)
            app.ElementDatas.ListOfInputSurfaces = list_of_input_surfaces;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, presettings_app, fairing_width, EPC_surface, temperature, static_pressure, relative_humidity)
            %Import object classes
            addpath("D:\ETS\Acoustique\Blocs\Classes")
            app.PresettingsApp = presettings_app;
            app.EPCSurface = EPC_surface;
            app.FairingWidth = fairing_width;
            
            %air parameters
            app.AirParameters.Temperature = temperature;
            app.AirParameters.StaticPressure = static_pressure;
            app.AirParameters.RelativeHumidity = relative_humidity;

            app.ElementDatas.NumberOfElements = 0; % No Element at the beginnig 
            app.ElementDatas.ListOfInputSurfaces = [];
            %plot initialisation
            app.SubelementDatas.Subelement1.Name = "None";
            app.SubelementDatas.Subelement1.Marker = '+';
            app.SubelementDatas.Subelement1.Position = [1 -1];

%             app.SubelementDatas.Subelement2.Name = "None";
%             app.SubelementDatas.Subelement2.Marker = '+';
%             app.SubelementDatas.Subelement2.Position = [1 -2];


%             draw the subelements

            hold( app.UIAxes, 'on' )
            fn = fieldnames(app.SubelementDatas);      
            for i = 1:length(fn)
                coordinates = eval( "app.SubelementDatas."+fn{i}+".Position");
                marker = eval( "app.SubelementDatas."+fn{i}+".Marker");
                plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2)
            end

            % active datacursormode (selection mode)
            d = datacursormode(app.UIFigure);
            d.Enable = 'on';
            d.UpdateFcn = @app.subelementLeftClick;


            % Set every Parameters Panel :
            % - Porous Material
            app.UIComponents.PorousMaterial = plotParametersPanel(app,6,["Porosity","Airflow Resistivity","Tortuosity","Viscous Characteristic Length","Thermal Characteristic Length","Thickness"],["%","Pa.s/m2","(no unit)","m","m","m"]);
                %create Frame
            app.UIComponents.PorousMaterial.Frame = uibuttongroup(app.UIComponents.PorousMaterial.ParametersPanel);
            app.UIComponents.PorousMaterial.Frame.SelectionChangedFcn = createCallbackFcn(app, @FrameSelectionChanged, true); % If Frame = 'Limp' : Create a Material density editfield + name label and unit label
            app.UIComponents.PorousMaterial.Frame.BorderType = 'none';
            app.UIComponents.PorousMaterial.Frame.Position = [115 47 195 46];
                % Create RigidButton
            app.UIComponents.PorousMaterial.RigidButton = uitogglebutton(app.UIComponents.PorousMaterial.Frame);
            app.UIComponents.PorousMaterial.RigidButton.Text = 'Rigid';
            app.UIComponents.PorousMaterial.RigidButton.Position = [69 13 62 22];
            app.UIComponents.PorousMaterial.RigidButton.Value = true;
                % Create LimpButton
            app.UIComponents.PorousMaterial.LimpButton = uitogglebutton(app.UIComponents.PorousMaterial.Frame);
            app.UIComponents.PorousMaterial.LimpButton.Text = 'Limp';
            app.UIComponents.PorousMaterial.LimpButton.Position = [134 13 62 22];
                % Create FrameLabel
            app.UIComponents.PorousMaterial.FrameLabel = uilabel(app.UIComponents.PorousMaterial.Frame);
            app.UIComponents.PorousMaterial.FrameLabel.HorizontalAlignment = 'right';
            app.UIComponents.PorousMaterial.FrameLabel.Position = [18 13 40 22];
            app.UIComponents.PorousMaterial.FrameLabel.Text = 'Frame';
                % Create Material Density editfield
            app.UIComponents.PorousMaterial.MaterialDensityLabel = uilabel(app.UIComponents.PorousMaterial.ParametersPanel);
            app.UIComponents.PorousMaterial.MaterialDensityLabel.Visible = "off";
            app.UIComponents.PorousMaterial.MaterialDensityLabel.Position = [48 20 126 32];
            app.UIComponents.PorousMaterial.MaterialDensityLabel.Text = "Material Density";
            app.UIComponents.PorousMaterial.MaterialDensityLabel.HorizontalAlignment = "right";
                %
            app.UIComponents.PorousMaterial.MaterialDensityEditField = uieditfield(app.UIComponents.PorousMaterial.ParametersPanel,"numeric");
            app.UIComponents.PorousMaterial.MaterialDensityEditField.Visible = "off";
            app.UIComponents.PorousMaterial.MaterialDensityEditField.Position = [180 26 100 22];
                %
            app.UIComponents.PorousMaterial.MaterialDensityUnitLabel = uilabel(app.UIComponents.PorousMaterial.ParametersPanel);
            app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Visible = "off";
            app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Position = [285 26 50 22];
            app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Text = "kg/m3";


            % - MPP
            app.UIComponents.MPP = plotParametersPanel(app,2,["Porosity","Thickness"],["%","m"]);
            % Create Shape
                %create Shape
            app.UIComponents.MPP.Shape = uibuttongroup(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.Shape.SelectionChangedFcn = createCallbackFcn(app, @ShapeSelectionChanged, true); 
            app.UIComponents.MPP.Shape.BorderType = 'none';
            app.UIComponents.MPP.Shape.Position = [67 167 300 46];
                % Create CircleButton
            app.UIComponents.MPP.CircleButton = uitogglebutton(app.UIComponents.MPP.Shape);
            app.UIComponents.MPP.CircleButton.Text = 'Circle';
            app.UIComponents.MPP.CircleButton.Position = [69 13 62 22];
            app.UIComponents.MPP.CircleButton.Value = true;
                % Create RectangleButton
            app.UIComponents.MPP.RectangleButton = uitogglebutton(app.UIComponents.MPP.Shape);
            app.UIComponents.MPP.RectangleButton.Text = 'Rectangle';
            app.UIComponents.MPP.RectangleButton.Position = [134 13 62 22];
                % Create SlitButton
            app.UIComponents.MPP.SlitButton = uitogglebutton(app.UIComponents.MPP.Shape);
            app.UIComponents.MPP.SlitButton.Text = 'Slit';
            app.UIComponents.MPP.SlitButton.Position = [199 13 62 22];
                % Create ShapeLabel
            app.UIComponents.MPP.ShapeLabel = uilabel(app.UIComponents.MPP.Shape);
            app.UIComponents.MPP.ShapeLabel.HorizontalAlignment = 'right';
            app.UIComponents.MPP.ShapeLabel.Position = [18 13 40 22];
            app.UIComponents.MPP.ShapeLabel.Text = 'Shape';
                % Create Dimensions Editfield
            app.UIComponents.MPP.RadiusLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.RadiusLabel.Position = [48 140 126 22];
            app.UIComponents.MPP.RadiusLabel.Text = "Radius";
            app.UIComponents.MPP.RadiusLabel.HorizontalAlignment = "right";

            app.UIComponents.MPP.WidthLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.WidthLabel.Position = [48 140 126 22];
            app.UIComponents.MPP.WidthLabel.Text = "Width";
            app.UIComponents.MPP.WidthLabel.Visible = "off";
            app.UIComponents.MPP.WidthLabel.HorizontalAlignment = "right";

            app.UIComponents.MPP.LengthLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.LengthLabel.Position = [48 140-32 126 22];
            app.UIComponents.MPP.LengthLabel.Text = "Length";
            app.UIComponents.MPP.LengthLabel.Visible = "off";
            app.UIComponents.MPP.LengthLabel.HorizontalAlignment = "right";            

            app.UIComponents.MPP.DimensionsEditField = uieditfield(app.UIComponents.MPP.ParametersPanel,"numeric");
            app.UIComponents.MPP.DimensionsEditField.Position = [180 140 100 22];

            app.UIComponents.MPP.LengthEditField = uieditfield(app.UIComponents.MPP.ParametersPanel,"numeric");
            app.UIComponents.MPP.LengthEditField.Position = [180 140-32 100 22];
            app.UIComponents.MPP.LengthEditField.Visible = "off";

            app.UIComponents.MPP.DimensionsUnitLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.DimensionsUnitLabel.Position = [285 140 50 22];
            app.UIComponents.MPP.DimensionsUnitLabel.Text = "m";

            app.UIComponents.MPP.LengthUnitLabel = uilabel(app.UIComponents.MPP.ParametersPanel);
            app.UIComponents.MPP.LengthUnitLabel.Position = [285 140-32 50 22];
            app.UIComponents.MPP.LengthUnitLabel.Visible = 'off';
            app.UIComponents.MPP.LengthUnitLabel.Text = "m";

            % - Helmholtz Resonator
            app.UIComponents.HR = plotParametersPanel(app,4,["Neck Radius","Neck Depth","Cavity Radius","Cavity Depth"],["m","m","m","m"]);

            % - Resistive Screen
            app.UIComponents.Screen = plotParametersPanel(app,2,["Screen Density","Thickness"],["kg/m3","m"]);
        end

        % Callback function
        function ButtonPushed(app, event)
% 

        end

        % Button pushed function: DeleteSubelementButton
        function DeleteSubelementButtonPushed(app, event)
            d = datacursormode(app.UIFigure);
            app.deletesubelement(d.getCursorInfo.Position);

        end

        % Callback function
        function FrameSelectionChanged(app, event)
            if app.UIComponents.PorousMaterial.LimpButton.Value == 1
                app.UIComponents.PorousMaterial.MaterialDensityLabel.Visible = "on";
                app.UIComponents.PorousMaterial.MaterialDensityEditField.Visible = "on";
                app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Visible = "on";

            else
                app.UIComponents.PorousMaterial.MaterialDensityLabel.Visible = "off";
                app.UIComponents.PorousMaterial.MaterialDensityEditField.Visible = "off";
                app.UIComponents.PorousMaterial.MaterialDensityUnitLabel.Visible = "off";                
            end
        end

        % Callback function
        function ShapeSelectionChanged(app, event)
            app.UIComponents.MPP.DimensionsEditField.Value = 0;
            app.UIComponents.MPP.LengthEditField.Value = 0;
            app.UIComponents.MPP.RadiusLabel.Visible = "off";
            app.UIComponents.MPP.WidthLabel.Visible = "off";
            app.UIComponents.MPP.LengthLabel.Visible = "off";
            app.UIComponents.MPP.LengthEditField.Visible = "off";
            app.UIComponents.MPP.LengthUnitLabel.Visible = "off";
            app.UIComponents.MPP.LengthEditField.Visible = "off";
            if app.UIComponents.MPP.CircleButton.Value == 1
                app.UIComponents.MPP.RadiusLabel.Visible = "on";
            elseif app.UIComponents.MPP.RectangleButton.Value == 1
                app.UIComponents.MPP.LengthLabel.Visible = "on";
                app.UIComponents.MPP.WidthLabel.Visible = "on";
                app.UIComponents.MPP.LengthEditField.Visible = "on";
                app.UIComponents.MPP.LengthUnitLabel.Visible = "on";
            elseif app.UIComponents.MPP.SlitButton.Value == 1
                app.UIComponents.MPP.WidthLabel.Visible = "on";
            end

            
        end

        % Value changed function: SublementtypeDropDown
        function SublementtypeDropDownValueChanged(app, event)
            value = app.SublementtypeDropDown.Value;
            if strcmp(value,'Porous Material')
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "on";
                app.UIComponents.MPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
            elseif strcmp(value,'MPP')
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.MPP.ParametersPanel.Visible = "on";
                app.UIComponents.HR.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
            elseif strcmp(value,'Helmholtz Resonator')
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.MPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR.ParametersPanel.Visible = "on";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
            elseif strcmp(value,'Resistive Screen')
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.MPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "on";
            else
                app.UIComponents.PorousMaterial.ParametersPanel.Visible = "off";
                app.UIComponents.MPP.ParametersPanel.Visible = "off";
                app.UIComponents.HR.ParametersPanel.Visible = "off";
                app.UIComponents.Screen.ParametersPanel.Visible = "off";
            end
        end

        % Button down function: UIFigure
        function SaveButtonPushed(app, event)
        %create corresponding objects
        
             % Case of MPP
            if app.SublementtypeDropDown.Value == "Porous Material"
                eval_text = "classJCA(";
                for i=1:length(app.UIComponents.PorousMaterial.ParameterLabel)
                    eval_text = eval_text+num2str(app.UIComponents.PorousMaterial.ParameterEditField{i}.Value)+","; 
                end
                if app.UIComponents.PorousMaterial.RigidButton.Value == 1
                    eval_text = eval_text+");";
                else
                    eval_text = eval_text+"'limp',app.UIComponents.PorousMaterial.MaterialDensityEditField.Value);";
                end
                color = '#7E2F8E'; % Actually used but in an 'eval' function
            end


            % Case of Helmholtz Resonator
            if app.SublementtypeDropDown.Value == "Helmholtz Resonator"
                eval_text = "classHR2(";
                for i=1:length(app.UIComponents.HR.ParameterLabel)-1
                    eval_text = eval_text+num2str(app.UIComponents.HR.ParameterEditField{i}.Value)+","; 
                end
                eval_text = eval_text+num2str(app.UIComponents.HR.ParameterEditField{end}.Value)+");";
                color = '#0072BD'; % Actually used but in an 'eval' function
            end


            % Case of Resistive Screen
            if app.SublementtypeDropDown.Value == "Resistive Screen"
                eval_text = "classscreen(";
                for i=1:length(app.UIComponents.Screen.ParameterLabel)-1
                    eval_text = eval_text+num2str(app.UIComponents.Screen.ParameterEditField{i}.Value)+","; 
                end
                eval_text = eval_text+num2str(app.UIComponents.Screen.ParameterEditField{end}.Value)+");";
                color = '#EDB120'; % Actually used but in an 'eval' function
            end


        %add the object to the list of subelements
            d = datacursormode(app.UIFigure);
            current_position = d.getCursorInfo.Position; 

            fnames = fieldnames(app.SubelementDatas);      
            for i = 1:length(fnames)
                subelement = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement.Position;

                if all(coordinates == current_position) && subelement.Name ~="None"
                    eval( "app.SubelementDatas."+fnames{i}+".Name ="+"app.SublementtypeDropDown.Value");
                    eval( "app.SubelementDatas."+fnames{i}+".Marker ='o'");
                    eval( "app.SubelementDatas."+fnames{i}+".Color ="+"color");
                    eval( "app.SubelementDatas."+fnames{i}+".Object ="+eval_text);
                    
                end
            end

%             draw the subelements
            fnames = fieldnames(app.SubelementDatas);%a new subelement has been added
            for i = 1:length(fnames)%parcourt notre strucutre
                fi_coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                for j = 1:length(app.UIAxes.Children)%parcourt les points du graph
                    plot_coordinates = [app.UIAxes.Children(j).XData app.UIAxes.Children(j).YData];
                    if all(fi_coordinates==plot_coordinates)%on regarde si ça correspond
                        marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                        color = getfield(app.SubelementDatas,fnames{i}).Color;
                        set(app.UIAxes.Children(j),'Marker',marker,'Color',color)
%                         d = datacursormode(app.UIFigure);
%                         d.UpdateFcn = @app.addsubelement;
                    end
                end
            end
        end

        % Menu selected function: InputSurfaceAreasMenu
        function InputSurfaceAreasMenuSelected(app, event)
            app.InputSurfaceAreasMenu.Enable = 'off';
            app.ElementInputSurfacesApp = Element_Input_Surface_v2(app,app.ElementDatas.NumberOfElements,app.ElementDatas.ListOfInputSurfaces); % Set input surface of the new element

        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % Opens the Input Surface Area app to set up the input settings
            error = 0;
            fnames = fieldnames(app.SubelementDatas);
            for i = 1:length(fnames) % return error message as soon as one subelement is undefined
                name = getfield(app.SubelementDatas, fnames{i}).Name;
                
                if name == "Undefined" && error == 0

                    error = 1;
                end
            end
            if error == 1
                msgbox("One or several subelements are undefined","Error","error");
            else
                app.InputSurfaceAreasMenu.Enable = "off";
                app.Panel.Enable = "off";
                app.DeleteSubelementButton.Enable = "off";
                app.Computing = 1;
                app.ElementInputSurfacesApp = Element_Input_Surface_v2(app,app.ElementDatas.NumberOfElements,app.ElementDatas.ListOfInputSurfaces); % Set input surface of the new element
            end


        end

        % Menu selected function: PresettingsMenu
        function PresettingsMenuSelected(app, event)
            app.PresettingsMenu.Enable = 'off';
            app.ElementInputSurfacesApp = Presettings(app,app.EPCSurface,app.FairingWidth,app.AirParameters.Temperature,app.AirParameters.StaticPressure,app.AirParameters.RelativeHumidity); % Modify presettings

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.ElementInputSurfacesApp)
            delete(app.PresettingsApp)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.ManageSettingsMenu = uimenu(app.UIFigure);
            app.ManageSettingsMenu.Text = 'Manage Settings';
            app.PresettingsMenu = uimenu(app.ManageSettingsMenu);
            app.PresettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @PresettingsMenuSelected, true);
            app.PresettingsMenu.Text = 'Presettings';
            app.InputSurfaceAreasMenu = uimenu(app.ManageSettingsMenu);
            app.InputSurfaceAreasMenu.MenuSelectedFcn = createCallbackFcn(app, @InputSurfaceAreasMenuSelected, true);
            app.InputSurfaceAreasMenu.Enable = 'off';
            app.InputSurfaceAreasMenu.Text = 'Input Surface Areas';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, {'Wave'; '||'; '\ || /'; '\/'})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-3 0];
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.XTick = [0 1];
            app.UIAxes.XTickLabel = {''; 'A'};
            app.UIAxes.YTick = -1;
            app.UIAxes.YTickLabel = {'1'};
            app.Panel = uipanel(app.UIFigure);
            app.SublementtypeDropDownLabel = uilabel(app.Panel);
            app.SublementtypeDropDownLabel.Text = 'Sublement type';
            app.SublementtypeDropDown = uidropdown(app.Panel);
            app.SublementtypeDropDown.Items = {'Type', 'Porous Material', 'MPP', 'Helmholtz Resonator', 'Resistive Screen'};
            app.SublementtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SublementtypeDropDownValueChanged, true);
            app.SublementtypeDropDown.Value = 'Type';
            app.DeleteSubelementButton = uibutton(app.UIFigure, 'push');
            app.DeleteSubelementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubelementButtonPushed, true);
            app.DeleteSubelementButton.Text = 'Delete Subelement';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appwithfigure_clement_2023_06_18(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end