classdef Appwithfigure_clement_2023_07_05 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        ManageSettingsMenu          matlab.ui.container.Menu
        PresettingsMenu             matlab.ui.container.Menu
        InputSurfaceAreasMenu       matlab.ui.container.Menu
        ConfigurationMenu           matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        ImportMenu                  matlab.ui.container.Menu
        ManageMenu                  matlab.ui.container.Menu
        ContinueButton              matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        DeleteSubelementButton      matlab.ui.control.Button
        SublementtypeDropDown       matlab.ui.control.DropDown
        SublementtypeDropDownLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SubelementDatas % structure with all the subelements
        UIComponents % Visual components of the app
        ElementInputSurfacesApp % Pop up app when a new element is created (get the input surface area of the element)
        ElementDatas % structure with all the elements
        PresettingsApp % cf. "Presettings.mlapp"
        FairingWidth
        EPCSurface
        Air % classair object
        Computing = 0 % used to know if the EPC is completed (if Continue button has been pushed)
        SubelementChosen % name of the  class of the subelement chosen in the Drop Down (defined in SubelementtypeDropDownValueChanged, useful for SaveButtonPushed)
    end
    methods (Access = public)
        
%%%%%%%%%%%%%%%%%% IF YOU LEFT CLICK ON A SUBELEMENT %%%%%%%%%%%%%%%%%%%%%%   
        function output = subelementLeftClick(app,~,event_obj) 
             % ~         Currently not used (empty)
             % event_obj Object containing event SubelementDatas structure
             % output    SubelementDatas cursor output
            
            hold(app.UIAxes,"on");
            current_position = event_obj.Position; %return the position [x, y]
                
            fnames = fieldnames(app.SubelementDatas);
            
            for i = 1:length(fnames)
                
                subelement_name = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement_name.Position;


        %%%%%%%%%%%%%%%%% Add a new subelement %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if all(coordinates == current_position) && subelement_name.Name =="None"
                    %modified + -> s
                    app.SubelementDatas.(fnames{i}).Name ="Undefined";
                    app.SubelementDatas.(fnames{i}).Marker ="s";
                    app.SubelementDatas.(fnames{i}).Color ="#000000";
                    app.UIAxes.XTick = [app.UIAxes.XTick,length(app.UIAxes.XTick)];
                    app.UIAxes.XTickLabel{end+1} = char(length(app.UIAxes.XTickLabel)+64);

                    %create a new + 
                    newsubelementnumber=num2str(str2double(regexp(fnames{end},'\d*','Match'))+1);
                    app.SubelementDatas.("Subelement"+newsubelementnumber).Name ="None";
                    app.SubelementDatas.("Subelement"+newsubelementnumber).Marker ="+";
                    app.SubelementDatas.("Subelement"+newsubelementnumber).Position = [coordinates(1)+1 coordinates(2)];
                    app.SubelementDatas.("Subelement"+newsubelementnumber).Color ="#000000";
                    

                     %add the last + on the right
                    plot(app.UIAxes,coordinates(1)+1,coordinates(2),'k','Marker','+','MarkerSize',15,'LineWidth',2,'Color','#000000');
                    %Add a new + under to create a new element if wanted 
                    if coordinates(1) == 1 
                        
                        app.ElementDatas.NumberOfElements = app.ElementDatas.NumberOfElements+1;
                        app.ElementDatas.ListOfInputSurfaces = [app.ElementDatas.ListOfInputSurfaces, 0];
                        newsubelementnumber=num2str(str2double(regexp(fnames{end},'\d*','Match'))+2);
                        app.SubelementDatas.("Subelement"+newsubelementnumber).Name ="None";
                        app.SubelementDatas.("Subelement"+newsubelementnumber).Marker ="+";
                        app.SubelementDatas.("Subelement"+newsubelementnumber).Position = [coordinates(1) coordinates(2)-1];
                        app.SubelementDatas.("Subelement"+newsubelementnumber).Color ="#000000";
                        app.UIAxes.YTick = [-(length(app.UIAxes.YTick)+1),app.UIAxes.YTick];
                        app.UIAxes.YTickLabel = {-app.UIAxes.YTick};
                    
                        set(app.UIAxes,'YLim',[coordinates(2)-2 0]);
                        plot(app.UIAxes,coordinates(1),coordinates(2)-1,'k','Marker','+','MarkerSize',15,'LineWidth',2,'Color','#000000');
                    end
                    
                end
            end


        %%%%%%%%%%%%% draw the subelements on the graph %%%%%%%%%%%%%%%%%%%%%%%%%%%
            % fnames_old = fnames;%the names of the previous item
            
            fnames = fieldnames(app.SubelementDatas);%a new subelement has been added
            xmax = 0;
            ymax = 0;
            for i = 1:length(fnames)%parcourt notre nouvelle strucutre 
                fi_coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                for j = 1:length(app.UIAxes.Children)%parcourt les points du graph
                    plot_coordinates = [app.UIAxes.Children(j).XData app.UIAxes.Children(j).YData];
                    if all(fi_coordinates==plot_coordinates)%on regarde si ça correspond
                        marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                        set(app.UIAxes.Children(j),'Marker',marker)
                    end      
                end
                xmax = max(xmax,fi_coordinates(1));
                ymax = min(ymax,fi_coordinates(2));
            end
        
        %%%%%%%%%%%%% Make the corresponding panel appear with actual values on the right %%%%%%%%%
            fnames = fieldnames(app.SubelementDatas);
           
            for i = 1:length(fnames)
                subelement = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement.Position;
                subelement_name = getfield(app.SubelementDatas,fnames{i}).Name;
                param_panels = fieldnames(app.UIComponents);

                if all(coordinates == current_position)  
                    
                    if subelement_name =="None" || subelement_name == "Undefined"
                        app.SublementtypeDropDown.Value = 'Type';
                        app.SublementtypeDropDownValueChanged(NaN)
                        for j = 1:length(param_panels) % Reset value of each parameter
                            for k = 1:length(app.UIComponents.(param_panels{j}).ParameterEditField)
                                app.UIComponents.(param_panels{j}).ParameterEditField{k}.Value = 0;
                            end
                            app.UIComponents.(param_panels{j}).ConfigDropDown.Value = 'Manual';

                        end
                    else
                        app.SublementtypeDropDown.Value = getfield(app.SubelementDatas,fnames{i}).Name;
                        app.SublementtypeDropDownValueChanged(NaN);
                        for j = 1:length(param_panels)
                            if app.UIComponents.(param_panels{j}).ParametersPanel.Visible == "on"
                                for k = 1:length(app.UIComponents.(param_panels{j}).ParameterEditField)
                                    app.UIComponents.(param_panels{j}).ParameterEditField{k}.Value = app.SubelementDatas.(fnames{i}).ParamList{k+1};
                                end
                                app.UIComponents.(param_panels{j}).ConfigDropDown.Value = app.SubelementDatas.(fnames{i}).ConfigName;

                            end
                        end

                    end
                    
                end
            end
            
            if length(fnames)==1
                app.InputSurfaceAreasMenu.Enable = "off";
                app.SublementtypeDropDown.Enable = "off";
            else
                app.InputSurfaceAreasMenu.Enable = "on";
                app.SublementtypeDropDown.Enable = "on";
            end
            
%%%%%%%%%%%%% Clicking on a subelement make its name appear on the graph %%%%%%%%%%
            for i = 1:length(fnames)
                coordinates = app.SubelementDatas.(fnames{i}).Position;
                if all(coordinates == current_position)% si curseur sur cet élément
                    if app.SubelementDatas.(fnames{i}).Name ~= "Undefined"
                        for j = 1:length(param_panels)
                            if app.UIComponents.(param_panels{j}).ParametersPanel.Visible == "on"
                                switch app.UIComponents.(param_panels{j}).ConfigDropDown.Value
                                    case 'Manual'
                                        output={app.SubelementDatas.(fnames{i}).Name};
                                    otherwise
                                        output = {app.UIComponents.(param_panels{j}).ConfigDropDown.Value};
                                end
                            end
                        end
                    else
                            output={app.SubelementDatas.(fnames{i}).Name};
                    end
                    %                     output={app.SubelementDatas.(fnames{i}).Name};%mouse text
                end 
            end

            set(app.UIAxes,'XLim',[0.7   xmax+0.3]);
            set(app.UIAxes,'YLim',[ymax-0.3  -0.7]);

        end
        function SaveAsConfig(app,event)
            SaveButtonPushed(app,event)
        end


%%%%%%%%%%%%%%%%%%%%%%%% DELETE SUBELEMENT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Now in 'E:\ETS\Acoustique\AppBell\Functions'
%         function deletesubelement(app,current_position) 
%              %delete the selected element
%             
% 
%             fnames = fieldnames(app.SubelementDatas);
%             
%             issubelementdeleted = false;
%             for i = 1:length(fnames)
%                  % make sure the subelement called exists because some are deleted in the loop
%                 if isfield(app.SubelementDatas,fnames{i}) && not(issubelementdeleted)
%                     subelement = getfield(app.SubelementDatas,fnames{i});
%                     coordinates =  subelement.Position;
%                     if all(coordinates == current_position)
%                         issubelementdeleted = true;%the selected subelementdeleted is going to be deleted
%                         if subelement.Name ~= "None"% Make sure no + can be deleted
%                             app.SubelementDatas = rmfield(app.SubelementDatas,fnames{i});% Delete the selected subelement
%                             app.SubelementDatas
%                             new_fnames = fieldnames(app.SubelementDatas);
%                             
%                             go_to_other_case = false;
%                             if coordinates(1) == 1  %if a single-subelement element is deleted, delete the subelement, and both the + around. then create a +
%                                 % make sure the case is the right one
% 
%                                     for j = 1 : length(new_fnames)
%                                         other_subelements = getfield(app.SubelementDatas,new_fnames{j});
%                                         if all(other_subelements.Position ==[coordinates(1)+1 coordinates(2)]) && other_subelements.Name=="None"%delete subelement  on the right if +
%                                             app.SubelementDatas = rmfield(app.SubelementDatas,new_fnames{j});
%                                             % go_to_other_case = true;
%                                         elseif all(other_subelements.Position==[coordinates(1) coordinates(2)-1]) && other_subelements.Name=="None"%delete subelement under if it is a none
%                                             app.SubelementDatas = rmfield(app.SubelementDatas,new_fnames{j});
%                                         end       
%                                         if all(other_subelements.Position==[coordinates(1) coordinates(2)-1]) && other_subelements.Name~="None"
%                                             %create a new + except if the subelement is in the first column and between two other subelemnts
%                                             go_to_other_case = true;
%                                         end
% 
%                                     end
% 
%                                     %create a new + except if the subelement is in the first column and between two other subelemnts
%                                     if not(go_to_other_case)
%                                         newsubelementnumber=num2str(str2double(regexp(fnames{end},'\d*','Match'))+1);
%                                         app.SubelementDatas.("Subelement"+newsubelementnumber).Name ='None';
%                                         app.SubelementDatas.("Subelement"+newsubelementnumber).Marker ='+';
%                                         app.SubelementDatas.("Subelement"+newsubelementnumber).Position = [coordinates(1) coordinates(2)];
%                                         app.SubelementDatas.("Subelement"+newsubelementnumber).Color ='#000000';                                    
%                                         plot(app.UIAxes,coordinates(1),coordinates(2),'+','MarkerSize',15,'LineWidth',2,"Color",'#000000');
%                                     else %supress the empty line
%                                         new_fnames = fieldnames(app.SubelementDatas);
%                                         for j = 1 : length(new_fnames)%all the subelements on the right of the deleted subelement go 1 to the left
%                                             under_subelement = getfield(app.SubelementDatas,new_fnames{j});
%                                             if under_subelement.Position(2) < subelement.Position(2)
%                                                 % eval("app.SubelementDatas."+new_fnames{j}+".Position(2)=app.SubelementDatas."+new_fnames{j}+".Position(2) + 1;");
%                                                 % app.SubelementDatas."+new_fnames{j}+".Position(2)=app.SubelementDatas."+new_fnames{j}+".Position(2) + 1;
%                                                 app.SubelementDatas.(new_fnames{j}).Position(2) = app.SubelementDatas.(new_fnames{j}).Position(2) +1;
%                                             end
%                                         end
%                                     end
%         
%                             else % case when the subelement isn't the first one on the line
%                                 for j = 1 : length(new_fnames)%all the subelements on the right of the deleted subelement go 1 to the left
%                                     right_subelement = getfield(app.SubelementDatas,new_fnames{j});
%                                     if right_subelement.Position(1) > subelement.Position(1)
%                                         % eval("app.SubelementDatas."+new_fnames{j}+".Position(1)=app.SubelementDatas."+new_fnames{j}+".Position(1) - 1;");
%                                         app.SubelementDatas.(new_fnames{j}).Position(1) = app.SubelementDatas.(new_fnames{j}).Position(1) - 1;
%                                     end
%                                 end
%                             end
%                         end
%                     end
% 
%                 end
%             end
%             
%             % Reset axis
%             xmax = 0;
%             ymax = 0;
%             fnames = fieldnames(app.SubelementDatas);      
%             for i = 1:length(fnames)
%                 if i==1
%                     hold(app.UIAxes,'off')
%                 elseif i==2
%                     hold(app.UIAxes,'on')
%                 end
%                 coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
%                 marker = getfield(app.SubelementDatas,fnames{i}).Marker;
%                 plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2)
%                 xmax = max(xmax,coordinates(1));
%                 ymax = min(ymax,coordinates(2));
%             end
%             % resize the axis
%             set(app.UIAxes,'XLim',[0.7 xmax+0.3]) 
%             set(app.UIAxes,'YLim',[ymax-0.3 -0.7])
% 
%             %If no element yet (only a +)
%             if length(fnames)==1
%                 app.InputSurfaceAreasMenu.Enable = "off";
%                 app.SublementtypeDropDown.Enable = "off";
%             else
%                 app.InputSurfaceAreasMenu.Enable = "on";
%                 app.SublementtypeDropDown.Enable = "on";
%             end
% 
%         end


%%%%%%%%%%%%%%%% CREATE PARAMETERS PANNELS MORE EASILY %%%%%%%%%%%%%%%%%%%%       
        function param_panel = plotParametersPanel(app,number_of_param,name_of_param,unit_of_param)
            %Create parameters panel under the subelement type selection
            Parameters.ParametersPanel = uipanel(app.Panel);
            Parameters.ParametersPanel.Visible = 'off';
            Parameters.ParametersPanel.Position = [11 10 417 325];
            %Parameters Title
            Parameters.PanelTitle = uilabel(Parameters.ParametersPanel);
            Parameters.PanelTitle.BackgroundColor = [0.8 0.8 0.8];
            Parameters.PanelTitle.HorizontalAlignment = 'center';
            Parameters.PanelTitle.FontWeight = 'bold';
            Parameters.PanelTitle.Position = [10 292 394 22];
            Parameters.PanelTitle.Text = 'PARAMETERS';

            if number_of_param >= 1
                for i = 1:number_of_param
                    %Parameter label
                    Parameters.ParameterLabel{i}=uilabel(Parameters.ParametersPanel);
                    Parameters.ParameterLabel{i}.HorizontalAlignment = 'right';
                    Parameters.ParameterLabel{i}.Position = [48 250-32*(i-1) 126 32];
                    Parameters.ParameterLabel{i}.Text = name_of_param(i);
                    Parameters.ParameterLabel{i}.WordWrap = "on";

                    %Parameter Edit Field
                    Parameters.ParameterEditField{i} = uieditfield(Parameters.ParametersPanel,'numeric');
                    Parameters.ParameterEditField{i}.Position = [180 256-32*(i-1) 100 22];

                    %Parameter unit
                    Parameters.ParameterUnit{i} = uilabel(Parameters.ParametersPanel);
                    Parameters.ParameterUnit{i}.Position = [285 256-32*(i-1) 50 22];
                    Parameters.ParameterUnit{i}.Text = unit_of_param(i);
                    
                    if i == number_of_param
                        Parameters.ConfigDropDown = uidropdown(Parameters.ParametersPanel);
                        Parameters.ConfigDropDown.Position = [180 256-32*(i) 100 22];
                        Parameters.ConfigDropDown.Items = {'Manual'};
                        Parameters.ConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigDropDownValueChanged, true);
                        Parameters.ConfigDropDown.Value = 'Manual';
                        Parameters.Configurations.Config1.Name = 'Manual';
                        Parameters.Configurations.Config1.Value = zeros(1,number_of_param);
                    end
                    
                end
            end
            Parameters.SaveButton = uibutton(Parameters.ParametersPanel,'push');
            Parameters.SaveButton.ButtonPushedFcn = createCallbackFcn(app,@SaveButtonPushed,true);
            Parameters.SaveButton.Position = [335 9 75 22];
            Parameters.SaveButton.Text = "Save";

            Parameters.SaveAsConfigButton = uibutton(Parameters.ParametersPanel,'push');
            Parameters.SaveAsConfigButton.ButtonPushedFcn = createCallbackFcn(app,@SaveAsConfigButtonPushed,true);
            Parameters.SaveAsConfigButton.Position = [9 9 100 22];
            Parameters.SaveAsConfigButton.Text = "Save as Config";

            
            param_panel = Parameters;
        end
    end
        
    methods (Access = public)


        function updateInputSurfaces(app,list_of_input_surfaces)
            app.ElementDatas.ListOfInputSurfaces = list_of_input_surfaces;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, presettings_app, fairing_width, EPC_surface, temperature, static_pressure, relative_humidity)
            %Import object classes
            addpath("E:\ETS\Acoustique\Blocs\Classes")
            addpath("E:\ETS\Acoustique\AppBell\Callbacks")
            addpath("E:\ETS\Acoustique\AppBell\Functions")
            app.PresettingsApp = presettings_app;
            app.EPCSurface = EPC_surface;
            app.FairingWidth = fairing_width;
            
            %air parameters
            app.Air = classair(temperature,static_pressure,relative_humidity);

            app.ElementDatas.NumberOfElements = 0; % No Element at the beginnig 
            app.ElementDatas.ListOfInputSurfaces = [];
            %plot initialisation
            app.SubelementDatas.Subelement1.Name = "None";
            app.SubelementDatas.Subelement1.Marker = '+';
            app.SubelementDatas.Subelement1.Position = [1 -1];

%             app.SubelementDatas.Subelement2.Name = "None";
%             app.SubelementDatas.Subelement2.Marker = '+';
%             app.SubelementDatas.Subelement2.Position = [1 -2];


%             draw the subelements

            hold( app.UIAxes, 'on' )
            fn = fieldnames(app.SubelementDatas);      
            for i = 1:length(fn)
                coordinates = eval( "app.SubelementDatas."+fn{i}+".Position");
                marker = eval( "app.SubelementDatas."+fn{i}+".Marker");
                plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2)
            end
            set(app.UIAxes,'XLim',[0.7 1.3]) 
            set(app.UIAxes,'YLim',[-1.3 -0.7])

            % active datacursormode (selection mode)
            d = datacursormode(app.UIFigure);
            d.Enable = 'on';
            d.UpdateFcn = @app.subelementLeftClick;


            % Set every Parameters Panel :
            % - Porous Material (Rigid)
            app.UIComponents.RigidPorousMaterial = plotParametersPanel(app,6,["Porosity","Airflow Resistivity","Tortuosity","Viscous Characteristic Length","Thermal Characteristic Length","Thickness"],["%","Pa.s/m2","(no unit)","m","m","m"]);
            app.UIComponents.RigidPorousMaterial.Color = "#335987";
            % - Porous Material (Limp)
            app.UIComponents.LimpPorousMaterial = plotParametersPanel(app,7,["Porosity","Airflow Resistivity","Tortuosity","Viscous Characteristic Length","Thermal Characteristic Length","Thickness","Material Density"],...
                                                                            ["%","Pa.s/m2","(no unit)","m","m","m","kg/m3"]);
            app.UIComponents.LimpPorousMaterial.Color = "#548FAB";

            % - MPP (Circular)
            app.UIComponents.CircularMPP = plotParametersPanel(app,3,["Porosity","Thickness","Radius"],["%","m","m"]);
            app.UIComponents.CircularMPP.Color = "#094F29";
            
            % - MPP (Slit)
            app.UIComponents.SlitMPP = plotParametersPanel(app,3,["Porosity","Thickness","Width"],["%","m","m"]);
            app.UIComponents.SlitMPP.Color = "#429B46";
            
            % - MPP (Rectangular)
            app.UIComponents.RectangularMPP = plotParametersPanel(app,4,["Porosity","Thickness","Length","Width"],["%","m","m","m"]);
            app.UIComponents.RectangularMPP.Color = "#94C58C"; 

            % - Helmholtz Resonator (Free geometry)
            app.UIComponents.HR1 = plotParametersPanel(app,3,["Neck Surface","Neck Length","Cavity Volume"],["m2","m","m3"]);
            app.UIComponents.HR1.Color = "#8B0001";

            % - Helmholtz Resonator (Cylindrical)
            app.UIComponents.HR2 = plotParametersPanel(app,4,["Neck Radius","Neck Depth","Cavity Radius","Cavity Depth"],["m","m","m","m"]);
            app.UIComponents.HR2.Color = "#C92020";

            % - Resistive Screen
            app.UIComponents.Screen = plotParametersPanel(app,2,["Screen Density","Thickness"],["kg/m3","m"]);
            app.UIComponents.Screen.Color = "#FFA500";

            % - Grid
            app.UIComponents.Grid = plotParametersPanel(app,4,["Mesh Length","Mesh Width","Thickness","Porosity"],["m","m","m","%"]);
            app.UIComponents.Grid.Color = "#6600A1";

            % - Quarter Wavelength Resonator
            app.UIComponents.QWL = plotParametersPanel(app,3,["Main Radius","Resonator Radius","Resonator Length"],["m","m","m"]);
            app.UIComponents.QWL.Color = "#C0428A";

            % - Cavity
            app.UIComponents.Cavity = plotParametersPanel(app,1,["Length"],["m"]);
            app.UIComponents.Cavity.Color = "#30D5C8";
            
            % - Section Change
            app.UIComponents.SectionChange = plotParametersPanel(app,2,["Input Surface","Output Surface"],["m2","m2"]);
            app.UIComponents.SectionChange.Color = "#6F4E37";


        end

        % Button pushed function: DeleteSubelementButton
        function DeleteSubelementButtonPushed(app, event)
%             try
                d = datacursormode(app.UIFigure);
                deleteSubelement(app,d.getCursorInfo.Position);
%             catch
%                  warndlg("Select a valid subelement")   
%             end
           

        end

        % Value changed function: SublementtypeDropDown
        function SublementtypeDropDownValueChanged(app, event)
            value = app.SublementtypeDropDown.Value;
            switch value
                case 'Porous Material (Rigid)'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "on";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classJCA('rigid',";
                case 'Porous Material (Limp)'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "on";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classJCA('limp',";

                case 'MPP (Circular)'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "on";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classMPP('circle',";

                case 'MPP (Slits)'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "on";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classMPP('slit',";

                case 'MPP (Rectangular)'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "on";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classMPP('rectangle',";

                case 'Helmholtz Resonator (Free Geometry)'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "on";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classHR1(";

                case 'Helmholtz Resonator (Cylindrical)'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "on";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classHR2(";

                case 'Resistive Screen'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "on";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classscreen(";

                case 'Protective Grid'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "on";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classgrid(";

                case 'Quarter Wavelength Resonator'

                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "on";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classQWL(";

                case 'Cavity'
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "on";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "classcavity(";

                case 'Section Change'

                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "on";

                    app.SubelementChosen = "classsectionchange(";

                otherwise
                    app.UIComponents.RigidPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.LimpPorousMaterial.ParametersPanel.Visible = "off";
                    app.UIComponents.CircularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.SlitMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.RectangularMPP.ParametersPanel.Visible = "off";
                    app.UIComponents.HR1.ParametersPanel.Visible = "off";
                    app.UIComponents.HR2.ParametersPanel.Visible = "off";
                    app.UIComponents.Screen.ParametersPanel.Visible = "off";
                    app.UIComponents.Grid.ParametersPanel.Visible = "off";
                    app.UIComponents.QWL.ParametersPanel.Visible = "off";
                    app.UIComponents.Cavity.ParametersPanel.Visible = "off";
                    app.UIComponents.SectionChange.ParametersPanel.Visible = "off";

                    app.SubelementChosen = "";
            end

        end

        % Button down function: UIFigure
        function SaveButtonPushed(app, event)
            %create corresponding objects
            
            % create list of selected parameters
            param_list = {app.SubelementChosen};
            parameters_pannels = fieldnames(app.UIComponents);


            for i = 1:length(parameters_pannels)
                if app.UIComponents.(parameters_pannels{i}).ParametersPanel.Visible == "on"
                    for j = 1:length(app.UIComponents.(parameters_pannels{i}).ParameterEditField)
                        param_list{end+1} = app.UIComponents.(parameters_pannels{i}).ParameterEditField{j}.Value; % {type of subelement}{Param 1}{...}
                    end
                    eval_text = param_list{1};
                    for j = 2: length(param_list)
                        if j > 2
                            eval_text = eval_text +","+ num2str(param_list{j});
                        else
                            eval_text = eval_text + num2str(param_list{j});
                        end
                    end
                    eval_text= eval_text+");";
                    color = app.UIComponents.(parameters_pannels{i}).Color;
                    config_name = app.UIComponents.(parameters_pannels{i}).ConfigDropDown.Value;
                end
            end


        %add the object to the list of subelements
            d = datacursormode(app.UIFigure);
            current_position = d.getCursorInfo.Position; 

            fnames = fieldnames(app.SubelementDatas);      
            for i = 1:length(fnames)
                subelement = getfield(app.SubelementDatas,fnames{i});
                coordinates = subelement.Position;

                if all(coordinates == current_position) && subelement.Name ~="None"
                    app.SubelementDatas.(fnames{i}).Name = app.SublementtypeDropDown.Value;
                    app.SubelementDatas.(fnames{i}).Marker ='o';
                    app.SubelementDatas.(fnames{i}).Color =color;
                    eval( "app.SubelementDatas."+fnames{i}+".Object ="+eval_text);% {type of subelement}{Param 1}{...}
                    app.SubelementDatas.(fnames{i}).ParamList = param_list; 
                    app.SubelementDatas.(fnames{i}).ConfigName = config_name;
                    
                    
                end
            end

%             draw the subelements
            fnames = fieldnames(app.SubelementDatas);%a new subelement has been added
            for i = 1:length(fnames)%parcourt notre strucutre
                fi_coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                for j = 1:length(app.UIAxes.Children)%parcourt les points du graph
                    plot_coordinates = [app.UIAxes.Children(j).XData app.UIAxes.Children(j).YData];
                    if all(fi_coordinates==plot_coordinates)%on regarde si ça correspond
                        marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                        color = getfield(app.SubelementDatas,fnames{i}).Color;
                        set(app.UIAxes.Children(j),'Marker',marker,'Color',color)
%                         d = datacursormode(app.UIFigure);
%                         d.UpdateFcn = @app.addsubelement;
                    end
                end
            end
        end

        % Menu selected function: InputSurfaceAreasMenu
        function InputSurfaceAreasMenuSelected(app, event)
            app.InputSurfaceAreasMenu.Enable = 'off';
            app.Computing = 0;
            app.ElementInputSurfacesApp = Element_Input_Surface_v2(app,app.ElementDatas.NumberOfElements,app.ElementDatas.ListOfInputSurfaces); % Set input surface of the new element

        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Opens the Input Surface Area app to set up the input settings
            error = 0;
            fnames = fieldnames(app.SubelementDatas);
            for i = 1:length(fnames) % return error message as soon as one subelement is undefined
                name = getfield(app.SubelementDatas, fnames{i}).Name;
                
                if name == "Undefined" && error == 0

                    error = 1;
                end
            end
            if error == 1
                msgbox("One or several subelements are undefined","Error","error");
            else
                app.InputSurfaceAreasMenu.Enable = "off";
                app.Panel.Enable = "off";
                app.DeleteSubelementButton.Enable = "off";
                app.ContinueButton.Enable = "off";
                app.Computing = 1;
                app.ElementInputSurfacesApp = Element_Input_Surface_v2(app,app.ElementDatas.NumberOfElements,app.ElementDatas.ListOfInputSurfaces); % Set input surface of the new element
            end


        end

        % Menu selected function: PresettingsMenu
        function PresettingsMenuSelected(app, event)
            app.PresettingsMenu.Enable = 'off';
            app.ElementInputSurfacesApp = Presettings(app,app.EPCSurface,app.FairingWidth,app.Air.Temperature,app.Air.StaticPressure,app.Air.RelativeHumidity); % Modify presettings

        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            [file,path] = uiputfile('*.EPC');
            filename = fullfile(path,file);
            datas.SubelementDatas = app.SubelementDatas;
            datas.FairingWidth = app.FairingWidth;
            datas.EPCSurface = app.EPCSurface;
            datas.ElementDatas = app.ElementDatas;
            parameters_panel = fieldnames(app.UIComponents);
            datas.ConfigDropDown = {};
            datas.Configurations = {};
            datas.TickLabels.X = app.UIAxes.XTickLabel;
            datas.TickLabels.Y = app.UIAxes.YTickLabel;
            for i = 1 : length(parameters_panel)
                datas.ConfigDropDown{end+1} = app.UIComponents.(parameters_panel{i}).ConfigDropDown.Items;
                datas.Configurations{end+1}=app.UIComponents.(parameters_panel{i}).Configurations;
            end

            save(filename,"-struct","datas","SubelementDatas","FairingWidth","EPCSurface","ElementDatas","ConfigDropDown","Configurations","TickLabels");
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [file,path] = uigetfile('*.EPC');
            selectedfile = fullfile(path,file);
            datas = importdata(selectedfile);
            app.SubelementDatas = datas.SubelementDatas;
            app.FairingWidth = datas.FairingWidth;
            app.EPCSurface = datas.EPCSurface;
            app.ElementDatas = datas.ElementDatas;
            app.UIAxes.XTickLabel = datas.TickLabels.X;
            app.UIAxes.YTickLabel = datas.TickLabels.Y;
            parameters_panel = fieldnames(app.UIComponents);
            for i = 1:length(datas.ConfigDropDown)
                app.UIComponents.(parameters_panel{i}).ConfigDropDown.Items = datas.ConfigDropDown{i};
                app.UIComponents.(parameters_panel{i}).Configurations = datas.Configurations{i};
            end


            xmax = 0;
            ymax = 0;
            fnames = fieldnames(app.SubelementDatas);
            for i = 1:length(fnames)
                if i==1
                    hold(app.UIAxes,'off')
                elseif i==2
                    hold(app.UIAxes,'on')
                end
                coordinates = getfield(app.SubelementDatas,fnames{i}).Position;
                marker = getfield(app.SubelementDatas,fnames{i}).Marker;
                color = getfield(app.SubelementDatas,fnames{i}).Color;
                plot(app.UIAxes,coordinates(1),coordinates(2),'k','marker',marker,'MarkerSize',15,'LineWidth',2,'Color',color)
                xmax = max(xmax,coordinates(1));
                ymax = min(ymax,coordinates(2));
            end
            app.UIAxes.XTick = 0 :xmax;
            app.UIAxes.YTick = ymax:-1;
            
            % resize the axis
            set(app.UIAxes,'XLim',[0.7 xmax+0.3])
            set(app.UIAxes,'YLim',[ymax-0.3 -0.7])
     
            %If no element yet (only a +)
            if length(fnames)==1
                app.InputSurfaceAreasMenu.Enable = "off";
                app.SublementtypeDropDown.Enable = "off";
            else
                app.InputSurfaceAreasMenu.Enable = "on";
                app.SublementtypeDropDown.Enable = "on";
            end
            app.SublementtypeDropDown.Value = 'Type';
            app.SublementtypeDropDownValueChanged(NaN)

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [file,path] = uiputfile('*.config');
            filename = fullfile(path,file);
            parameters_panel = fieldnames(app.UIComponents);


            for i = 1 : length(parameters_panel)
                if app.UIComponents.(parameters_panel{i}).ParametersPanel.Visible == "on" %from the visible subelement type ...
                    datas.ParametersPanel = parameters_panel{i};
                    datas.ConfigDropDownValue = app.UIComponents.(parameters_panel{i}).ConfigDropDown.Value; % ... save name of the visible configuration ...
                    for j = 1:length(app.UIComponents.(parameters_panel{i}).ConfigDropDown.Items) 
                        if strcmp(app.UIComponents.(parameters_panel{i}).ConfigDropDown.Items{j}, datas.ConfigDropDownValue) 
                            config_names = fieldnames(app.UIComponents.(parameters_panel{i}).Configurations);
                            datas.ConfigParameters = app.UIComponents.(parameters_panel{i}).Configurations.(config_names{j}); % ... and the corresponding parameters
                        end
                    end
                end
            end

            save(filename,"-struct","datas","ConfigDropDownValue","ConfigParameters","ParametersPanel");
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            [file,path] = uigetfile('*.config');
            selectedfile = fullfile(path,file);
            datas = importdata(selectedfile);

            config_names = fieldnames(app.UIComponents.(datas.ParametersPanel).Configurations);
            new_config_number=num2str(str2double(regexp(config_names{end},'\d*','Match'))+1);
            app.UIComponents.(datas.ParametersPanel).Configurations.("Config"+num2str(new_config_number)) = datas.ConfigParameters;
            app.UIComponents.(datas.ParametersPanel).ConfigDropDown.Items{end+1} = datas.ConfigDropDownValue;
        end

        % Menu selected function: ManageMenu
        function ManageMenuSelected(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.ElementInputSurfacesApp)
            delete(app.PresettingsApp)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ManageSettingsMenu = uimenu(app.UIFigure);
            app.ManageSettingsMenu.Text = 'Manage Settings';
            app.PresettingsMenu = uimenu(app.ManageSettingsMenu);
            app.PresettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @PresettingsMenuSelected, true);
            app.PresettingsMenu.Text = 'Presettings';
            app.InputSurfaceAreasMenu = uimenu(app.ManageSettingsMenu);
            app.InputSurfaceAreasMenu.MenuSelectedFcn = createCallbackFcn(app, @InputSurfaceAreasMenuSelected, true);
            app.InputSurfaceAreasMenu.Enable = 'off';
            app.InputSurfaceAreasMenu.Text = 'Input Surface Areas';
            app.ConfigurationMenu = uimenu(app.UIFigure);
            app.ConfigurationMenu.Text = 'Configuration';
            app.SaveMenu = uimenu(app.ConfigurationMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ImportMenu = uimenu(app.ConfigurationMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.ManageMenu = uimenu(app.ConfigurationMenu);
            app.ManageMenu.MenuSelectedFcn = createCallbackFcn(app, @ManageMenuSelected, true);
            app.ManageMenu.Text = 'Manage';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, {'Wave'; '||'; '\ || /'; '\/'})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-3 0];
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.XTick = [0 1];
            app.UIAxes.XTickLabel = {''; 'A'};
            app.UIAxes.YTick = -1;
            app.UIAxes.YTickLabel = {'1'};
            app.Panel = uipanel(app.UIFigure);
            app.SublementtypeDropDownLabel = uilabel(app.Panel);
            app.SublementtypeDropDownLabel.Text = 'Sublement type';
            app.SublementtypeDropDown = uidropdown(app.Panel);
            app.SublementtypeDropDown.Items = {'Type', 'Porous Material (Rigid)', 'Porous Material (Limp)', 'MPP (Circular)', 'MPP (Slits)', 'MPP (Rectangular)', 'Helmholtz Resonator (Free Geometry)', 'Helmholtz Resonator (Cylindrical)', 'Resistive Screen', 'Protective Grid', 'Quarter Wavelength Resonator', 'Cavity', 'Section Change'};
            app.SublementtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SublementtypeDropDownValueChanged, true);
            app.SublementtypeDropDown.Enable = 'off';
            app.SublementtypeDropDown.Value = 'Type';
            app.DeleteSubelementButton = uibutton(app.Panel, 'push');
            app.DeleteSubelementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubelementButtonPushed, true);
            app.DeleteSubelementButton.Text = 'Delete Subelement';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appwithfigure_clement_2023_07_05(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end