classdef Element_Input_Surface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BackButton          matlab.ui.control.Button
        ContinueButton      matlab.ui.control.Button
        m2Label             matlab.ui.control.Label
        ElementInputSurfaceAreaEditField  matlab.ui.control.NumericEditField
        ElementInputSurfaceAreaEditFieldLabel  matlab.ui.control.Label
        INPUTSETTINGSLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        CallingApp % testapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, testapp, input_surface)
            app.CallingApp = testapp;
            app.ElementInputSurfaceAreaEditField.Value = input_surface;
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            updateEditField(app.CallingApp,app.ElementInputSurfaceAreaEditField.Value)
            app.CallingApp.ContinueButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.CallingApp.ContinueButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ContinueButton.Enable = 'on';
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.INPUTSETTINGSLabel = uilabel(app.UIFigure);
            app.INPUTSETTINGSLabel.Text = 'INPUT SETTINGS';
            app.ElementInputSurfaceAreaEditFieldLabel = uilabel(app.UIFigure);
            app.ElementInputSurfaceAreaEditFieldLabel.Text = 'Element Input Surface Area';
            app.ElementInputSurfaceAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.m2Label = uilabel(app.UIFigure);
            app.m2Label.Text = 'm^2';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Element_Input_Surface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end