classdef parametersComponent < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ParametersLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        Components
    end
    
    properties (Access = public)
        Name string = "Parameter ";
        NumberOfParameters double = 0;
        Unit string = '(no unit)';
    end
    
    
    methods (Access = private)
        
    end
    

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            delete(parameters.Component)
            % Use this function to update the underlying components
            num_param = comp.NumberOfParameters;
            name = split(comp.Name);
            unit = split(comp.Unit);
            if num_param >= 1
                for i = 1:num_param
                    %Create edit field label
                    comp.Components.ParameterEditFieldLabel = uilabel(comp);
                    comp.Components.ParameterEditFieldLabel.HorizontalAlignment = 'right';
                    comp.Components.ParameterEditFieldLabel.Position = [88 223-(i-1)*32 55 22];
                    comp.Components.ParameterEditFieldLabel.Text = name(i);
                    %Create edit field
                    comp.Components.ParameterEditField = uieditfield(comp,'numeric');
                    comp.Components.ParameterEditField.Position = [158 223-(i-1)*32 100 22];
                    %Create unit label
                    comp.Components.ParameterEditFieldLabel = uilabel(comp);
                    comp.Components.ParameterEditFieldLabel.HorizontalAlignment = 'right';
                    comp.Components.ParameterEditFieldLabel.Position = [263 223-(i-1)*32 55 22];
                    comp.Components.ParameterEditFieldLabel.Text = unit(i);
                end
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 417 325];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Visible = true;
            comp.ParametersLabel = uilabel(comp);
            comp.ParametersLabel.Tooltip = {''};
            comp.ParametersLabel.Text = 'PARAMETERS';
        end
    end
end