classdef testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu_2          matlab.ui.container.Menu
        Menu2_2         matlab.ui.container.Menu
        Toolbar         matlab.ui.container.Toolbar
        PushTool        matlab.ui.container.toolbar.PushTool
        PushTool2       matlab.ui.container.toolbar.PushTool
        ToggleTool      matlab.ui.container.toolbar.ToggleTool
        PushTool3       matlab.ui.container.toolbar.PushTool
        DropDown        matlab.ui.control.DropDown
        DropDownLabel   matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
        EditFieldLabel  matlab.ui.control.Label
        ContinueButton  matlab.ui.control.Button
        ContextMenu     matlab.ui.container.ContextMenu
        Menu            matlab.ui.container.Menu
        Menu2           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        ElementInputSurfaceApp
    end
    
    methods (Access = public)


        function updateEditField(app,input_surface)
            app.EditField.Value = input_surface;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.ContinueButton.Enable = 'off'; % Prevent from opening multiple dialogue box

            app.ElementInputSurfaceApp = Element_Input_Surface_v2(app,2,[]);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.ElementInputSurfaceApp)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.UIFigure);
            app.Menu2_2.Text = 'Menu2';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.PushTool3 = uipushtool(app.Toolbar);
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.DropDown.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end