classdef optimisation_multi_QWL_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FichiersMenu                  matlab.ui.container.Menu
        chargerconfigurationMenu      matlab.ui.container.Menu
        enregistrerconfigurationMenu  matlab.ui.container.Menu
        chargergabaritMenu            matlab.ui.container.Menu
        enregistrergabaritMenu        matlab.ui.container.Menu
        ActionsMenu                   matlab.ui.container.Menu
        AjouterunQWLMenu              matlab.ui.container.Menu
        SupprimerunQWLMenu            matlab.ui.container.Menu
        Menu                          matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        ListBox                       matlab.ui.control.ListBox
        ListBoxLabel                  matlab.ui.control.Label
        GridLayout12                  matlab.ui.container.GridLayout
        GridLayout11                  matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        ConfigurationTab              matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        ConfigurationexterneTab       matlab.ui.container.Tab
        UITable2                      matlab.ui.control.Table
        GridLayout5                   matlab.ui.container.GridLayout
        CalcButton_2                  matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        GridLayout6                   matlab.ui.container.GridLayout
        GridLayout8                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        frquenceminEditField          matlab.ui.control.NumericEditField
        frquenceminEditFieldLabel     matlab.ui.control.Label
        pasEditField                  matlab.ui.control.NumericEditField
        pasEditFieldLabel             matlab.ui.control.Label
        frquencemaxEditField          matlab.ui.control.NumericEditField
        frquencemaxEditFieldLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        CalcButton                    matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        UITable3                      matlab.ui.control.Table
    end

    
    properties (Access = public)
        Air
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: chargerconfigurationMenu
        function chargerconfigurationMenuSelected(app, event)
            % Définir le répertoire initial
            initialDirectory = 'C:\Users\Utilisateur\Documents\Maitrise_ETS\code_calcul_clément\Bell_EPC_App\Files\Optimisation\configurations';

            % Ouvrir la boîte de dialogue pour sélectionner un fichier
            [filename, path] = uigetfile('*.mat', 'Sélectionner un fichier de configuration',initialDirectory);
            
            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(filename, 0)
                return; % Sortir si l'utilisateur a annulé
            end
            
            
            % Charger les paramètres à partir du fichier sélectionné
            fullFilePath = fullfile(path, filename);
            QWL = load(fullFilePath).QWL;
            
            % Extraire les données de configuration de la structure
            table_configuration = QWL.Constructeur.Configuration;
            table_configuration_externe = QWL.Constructeur.Configuration_externe;
            w_vect = QWL.W_vect;
            f_min = w_vect(1)/(2*pi);
            f_max = w_vect(end)/(2*pi);
            pas = (w_vect(2)-w_vect(1))/(2*pi);
            app.Air = QWL.Air;
                
            % Afficher des données dans les tableaux
            app.UITable.Data = table_configuration; 
            app.UITable2.Data = table_configuration_externe;

            % Activer le mode de superposition
            hold(app.UIAxes, 'on');
        
            % Trouver tous les tracés actuels sur l'axe
            all_lines = findobj(app.UIAxes, 'Type', 'line');
        
            % Parcourir tous les tracés actuels
            for i = 1:numel(all_lines)
                current_line = all_lines(i);
                
                % Vérifier si le tracé actuel est nommé 'configuration'
                if strcmp(get(current_line, 'DisplayName'), 'configuration')
                    % Le tracé actuel est nommé 'gabarit', le supprimer
                    delete(current_line);
                end
            end
        
            % Afficher le nouveau tracé du gabarit dans app.UIAxes
            plot(app.UIAxes, w_vect/(2*pi), QWL.alpha(), 'DisplayName', 'configuration');
        
            % Désactiver le mode de superposition
            hold(app.UIAxes, 'off');

            % Metre à jour le support fréquentiel de l'affichage
            app.frquenceminEditField.Value = f_min;
            app.frquencemaxEditField.Value = f_max;
            app.pasEditField.Value = pas; 

            % Obtenir les noms de variables du tableau
            noms_variables_configuration = table_configuration.Properties.VariableNames;
            noms_variables_configuration_externe = table_configuration_externe.Properties.VariableNames;

            % Mettre à jour la UITable avec les données de configuration et les noms de colonne
            app.UITable.ColumnName = noms_variables_configuration;
            app.UITable2.ColumnName = noms_variables_configuration_externe;
        end

        % Menu selected function: enregistrerconfigurationMenu
        function enregistrerconfigurationMenuSelected(app, event)
            % Définir le répertoire initial
            initialDirectory = 'C:\Users\Utilisateur\Documents\Maitrise_ETS\code_calcul_clément\Bell_EPC_App\Files\Optimisation\configurations';
            
            % Obtenir les données des UITables depuis votre application
            configuration = app.UITable.Data;
            configuration_externe = app.UITable2.Data;
        
            % Créer la structure constructeur
            constructeur = struct('Configuration', configuration, 'Configuration_externe', configuration_externe);

            % Créer le vecteur support en fréquence
            f_min = app.frquenceminEditField.Value;
            f_max = app.frquencemaxEditField.Value;
            pas = app.pasEditField.Value; 
            w_vect = linspace(f_min,f_max,floor((f_max-f_min)/pas) + 1)*(2*pi);
            
       
            % Instancier un objet multiQWL avec la structure constructeur
            QWL = multiQWL(constructeur,w_vect,app.Air);
        
            % Ouvrir la boîte de dialogue pour spécifier le nom du fichier de sauvegarde
            [nomFichier, cheminFichier] = uiputfile('*.mat', 'Enregistrer la configuration',initialDirectory);
        
            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(nomFichier, 0)
                return; % Sortir si l'utilisateur a annulé
            end
        
            % Créer le chemin complet du fichier
            cheminCompletFichier = fullfile(cheminFichier, nomFichier);
        
            try
                % Sauvegarder l'objet QWL dans le fichier spécifié
                save(cheminCompletFichier, 'QWL');
        
            catch
                % Afficher un message d'erreur en cas d'échec de la sauvegarde
                errordlg("Erreur lors de l'enregistrement de la configuration.", "Erreur d'enregistrement");
            end
        end

        % Menu selected function: AjouterunQWLMenu
        function AjouterunQWLMenuSelected(app, event)
            % Récupérer le tableau de données de votre interface utilisateur
            myTableData = app.UITable.Data;
        
            % Vérifier si le tableau est vide pour déterminer comment initialiser la nouvelle ligne
            if isempty(myTableData)
                % Si le tableau est vide, initialiser la nouvelle ligne avec des valeurs vides
                nouvelleLigne = {[], [], []}; % Remplacez cela par le nombre et le type de colonnes de votre tableau
            else
                % Si le tableau n'est pas vide, copier les valeurs de la première ligne pour initialiser la nouvelle ligne
                premiereLigne = myTableData(1, :);
                nouvelleLigne = repmat(premiereLigne, 1, 1); % Reproduire la première ligne
            end
        
            % Ajouter la nouvelle ligne au tableau de données
            myTableData = [myTableData; nouvelleLigne];
        
            % Mettre à jour la UITable avec les données modifiées
            app.UITable.Data = myTableData;
        
            % Incrémenter Nb_res dans la colonne 1 de UITable2
            app.UITable2.Data(:, 1) = app.UITable2.Data(:, 1) + 1;
        end

        % Menu selected function: SupprimerunQWLMenu
        function SupprimerunQWLMenuSelected(app, event)
            % Récupérer le tableau de données de votre interface utilisateur
            myTableData = app.UITable.Data;
        
            % Vérifier si le tableau est vide
            if isempty(myTableData)
                msgbox('Le tableau est vide. Aucun résonateur à supprimer.', 'Avertissement', 'warn');
                return;
            end
        
            % Demander à l'utilisateur quelle ligne (résonateur) supprimer
            prompt = 'Entrez le numéro du résonateur à supprimer :';
            userInput = inputdlg(prompt, 'Supprimer un résonateur', [1 50]);
            
            % Vérifier si l'utilisateur a cliqué sur "Annuler" ou n'a rien saisi
            if isempty(userInput)
                return;
            end
        
            % Convertir l'entrée de l'utilisateur en nombre entier
            resonateurASupprimer = str2double(userInput);
        
            % Vérifier si l'entrée est un nombre valide
            if isnan(resonateurASupprimer) || resonateurASupprimer < 1 || resonateurASupprimer > size(myTableData, 1)
                msgbox('Numéro de résonateur invalide.', 'Erreur', 'error');
                return;
            end
        
            % Supprimer la ligne correspondante (résonateur) du tableau de données
            myTableData(resonateurASupprimer, :) = [];
        
            % Mettre à jour la UITable avec les données modifiées
            app.UITable.Data = myTableData;
            
            % Mettre à jour la donnée NB_res dans la colonne 1 en faisant Nb_res - 1
            app.UITable2.Data(:,1) = app.UITable2.Data(:,1) - 1;
        end

        % Menu selected function: chargergabaritMenu
        function chargergabaritMenuSelected(app, event)

            % Définir le répertoire initial
            initialDirectory_gabarit = 'C:\Users\Utilisateur\Documents\Maitrise_ETS\code_calcul_clément\Bell_EPC_App\Files\Optimisation\gabarits';
        
            % Ouvrir la boîte de dialogue pour sélectionner un fichier
            [filename, path] = uigetfile('*.mat', 'Sélectionner un fichier de configuration', initialDirectory_gabarit);
        
    
            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(filename, 0)
                return; % Sortir si l'utilisateur a annulé
            end
            
            % Charger les paramètres à partir du fichier sélectionné
            fullFilePath = fullfile(path, filename);
            gabarit = load(fullFilePath).gabarit;
            disp(gabarit);
            % Charger le vecteur support en fréquence
            w_vect = gabarit.W_vect;
            f_min = w_vect(1)/(2*pi);
            f_max = w_vect(end)/(2*pi);
            pas = (w_vect(2)-w_vect(1))/(2*pi);
            
            % Activer le mode de superposition
            hold(app.UIAxes, 'on');
        
            % Trouver tous les tracés actuels sur l'axe
            all_lines = findobj(app.UIAxes, 'Type', 'line');
        
            % Parcourir tous les tracés actuels
            for i = 1:numel(all_lines)
                current_line = all_lines(i);
                
                % Vérifier si le tracé actuel est nommé 'gabarit'
                if strcmp(get(current_line, 'DisplayName'), 'gabarit')
                    % Le tracé actuel est nommé 'gabarit', le supprimer
                    delete(current_line);
                end
            end
        
            % Afficher le nouveau tracé du gabarit dans app.UIAxes
            plot(app.UIAxes, w_vect/(2*pi), gabarit.build_Gabarit(), 'DisplayName', 'gabarit');
        
            % Désactiver le mode de superposition
            hold(app.UIAxes, 'off');
            
            % Afficher les paramètres dans app.UITable3
            app.UITable3.Data = gabarit.Parametres_gabarits;

            % Metre à jour le support fréquentiel de l'affichage
            app.frquenceminEditField.Value = f_min;
            app.frquencemaxEditField.Value = f_max;
            app.pasEditField.Value = pas;          

            % Obtenir les noms de variables du tableau
            noms_variables_gabarit = gabarit.Parametres_gabarits.Properties.VariableNames;
            % Mettre à jour la UITable avec les données de configuration et les noms de colonne
            app.UITable3.ColumnName = noms_variables_gabarit;

        end

        % Menu selected function: enregistrergabaritMenu
        function enregistrergabaritMenuSelected(app, event)
            % Définir le répertoire initial
            initialDirectory_gabarit = 'C:\Users\Utilisateur\Documents\Maitrise_ETS\code_calcul_clément\Bell_EPC_App\Files\Optimisation\gabarits';
            
            % Récupérer les informations depuis app.UITable3 et autres composants
            donnees_gabarit = app.UITable3.Data;
        
            % Construire le vecteur support en fréquence
            f_min = app.frquenceminEditField.Value;
            f_max = app.frquencemaxEditField.Value;
            pas = app.pasEditField.Value;
            w_vect = linspace(f_min,f_max, (f_max - f_min) / pas + 1) * (2*pi);
        
            % Créer une structure ou une classe gabarit avec les données récupérées
            gabarit = classgabarit(donnees_gabarit,w_vect);
                       
        
            % Ouvrir la boîte de dialogue pour spécifier le nom du fichier de sauvegarde
            [nomFichier, cheminFichier] = uiputfile('*.mat', 'Enregistrer le gabarit',initialDirectory_gabarit);
        
            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(nomFichier, 0)
                return; % Sortir si l'utilisateur a annulé
            end
        
            % Créer le chemin complet du fichier
            cheminCompletFichier = fullfile(cheminFichier, nomFichier);
            try
                % Sauvegarder l'objet QWL dans le fichier spécifié
                save(cheminCompletFichier, 'gabarit');
            catch
                % Afficher un message d'erreur en cas d'échec de la sauvegarde
                errordlg("Erreur lors de l'enregistrement de la configuration.", "Erreur d'enregistrement");
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Récupérer le tableau de données de votre interface utilisateur
            myTableData_gabarit = app.UITable3.Data;
        
            % Vérifier si le tableau est vide pour déterminer comment initialiser la nouvelle ligne
            if isempty(myTableData_gabarit)
                % Si le tableau est vide, initialiser la nouvelle ligne avec des valeurs vides
                nouvelleLigne = {[], [], [], []}; % Remplacez cela par le nombre et le type de colonnes de votre tableau
            else
                % Si le tableau n'est pas vide, copier les valeurs de la première ligne pour initialiser la nouvelle ligne
                premiereLigne = myTableData_gabarit(1, :);
                nouvelleLigne = repmat(premiereLigne, 1, 1); % Reproduire la première ligne
            end
        
            % Ajouter la nouvelle ligne au tableau de données
            myTableData_gabarit = [myTableData_gabarit; nouvelleLigne];
        
            % Mettre à jour la UITable avec les données modifiées
            app.UITable3.Data = myTableData_gabarit;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Récupérer le tableau de données de votre interface utilisateur
            myTableData_gabarit = app.UITable3.Data;
        
            % Vérifier si le tableau est vide
            if isempty(myTableData_gabarit)
                msgbox('Le tableau est vide. Aucun gabarit à supprimer.', 'Avertissement', 'warn');
                return;
            end
        
            % Demander à l'utilisateur quelle ligne (résonateur) supprimer
            prompt = 'Entrez le numéro du gabarit à supprimer :';
            userInput = inputdlg(prompt, 'Supprimer un résonateur', [1 50]);
            
            % Vérifier si l'utilisateur a cliqué sur "Annuler" ou n'a rien saisi
            if isempty(userInput)
                return;
            end
        
            % Convertir l'entrée de l'utilisateur en nombre entier
            gabaritASupprimer = str2double(userInput);
        
            % Vérifier si l'entrée est un nombre valide
            if isnan(gabaritASupprimer) || gabaritASupprimer < 1 || gabaritASupprimer > size(myTableData_gabarit, 1)
                msgbox('Numéro de gabarit invalide.', 'Erreur', 'error');
                return;
            end
        
            % Supprimer la ligne correspondante (résonateur) du tableau de données
            myTableData_gabarit(gabaritASupprimer, :) = [];
        
            % Mettre à jour la UITable avec les données modifiées
            app.UITable3.Data = myTableData_gabarit;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % Récupérer le tableau de données de votre interface utilisateur
            myTableData = app.UITable.Data;
        
            % Vérifier si le tableau est vide pour déterminer comment initialiser la nouvelle ligne
            if isempty(myTableData)
                % Si le tableau est vide, initialiser la nouvelle ligne avec des valeurs vides
                nouvelleLigne = {[], [], []}; % Remplacez cela par le nombre et le type de colonnes de votre tableau
            else
                % Si le tableau n'est pas vide, copier les valeurs de la première ligne pour initialiser la nouvelle ligne
                premiereLigne = myTableData(1, :);
                nouvelleLigne = repmat(premiereLigne, 1, 1); % Reproduire la première ligne
            end
        
            % Ajouter la nouvelle ligne au tableau de données
            myTableData = [myTableData; nouvelleLigne];
        
            % Mettre à jour la UITable avec les données modifiées
            app.UITable.Data = myTableData;
        
            % Incrémenter Nb_res dans la colonne 1 de UITable2
            app.UITable2.Data(:, 1) = app.UITable2.Data(:, 1) + 1;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % Récupérer le tableau de données de votre interface utilisateur
            myTableData = app.UITable.Data;
        
            % Vérifier si le tableau est vide
            if isempty(myTableData)
                msgbox('Le tableau est vide. Aucun résonateur à supprimer.', 'Avertissement', 'warn');
                return;
            end
        
            % Demander à l'utilisateur quelle ligne (résonateur) supprimer
            prompt = 'Entrez le numéro du résonateur à supprimer :';
            userInput = inputdlg(prompt, 'Supprimer un résonateur', [1 50]);
            
            % Vérifier si l'utilisateur a cliqué sur "Annuler" ou n'a rien saisi
            if isempty(userInput)
                return;
            end
        
            % Convertir l'entrée de l'utilisateur en nombre entier
            resonateurASupprimer = str2double(userInput);
        
            % Vérifier si l'entrée est un nombre valide
            if isnan(resonateurASupprimer) || resonateurASupprimer < 1 || resonateurASupprimer > size(myTableData, 1)
                msgbox('Numéro de résonateur invalide.', 'Erreur', 'error');
                return;
            end
        
            % Supprimer la ligne correspondante (résonateur) du tableau de données
            myTableData(resonateurASupprimer, :) = [];
        
            % Mettre à jour la UITable avec les données modifiées
            app.UITable.Data = myTableData;
            
            % Mettre à jour la donnée NB_res dans la colonne 1 en faisant Nb_res - 1
            app.UITable2.Data(:,1) = app.UITable2.Data(:,1) - 1;
        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            % Récupérer les informations depuis app.UITable3 et autres composants
            donnees_gabarit = app.UITable3.Data;
        
            % Construire le vecteur support en fréquence
            f_min = app.frquenceminEditField.Value;
            f_max = app.frquencemaxEditField.Value;
            pas = app.pasEditField.Value;
            w_vect = linspace(f_min,f_max, (f_max - f_min) / pas + 1) * (2*pi);
        
            % Créer une structure ou une classe gabarit avec les données récupérées
            gabarit = classgabarit(donnees_gabarit,w_vect);

            % Activer le mode de superposition
            hold(app.UIAxes, 'on');
        
            % Trouver tous les tracés actuels sur l'axe
            all_lines = findobj(app.UIAxes, 'Type', 'line');
        
            % Parcourir tous les tracés actuels
            for i = 1:numel(all_lines)
                current_line = all_lines(i);
                
                % Vérifier si le tracé actuel est nommé 'gabarit'
                if strcmp(get(current_line, 'DisplayName'), 'gabarit')
                    % Le tracé actuel est nommé 'gabarit', le supprimer
                    delete(current_line);
                end
            end
        
            % Afficher le nouveau tracé du gabarit dans app.UIAxes
            plot(app.UIAxes, w_vect/(2*pi), gabarit.build_Gabarit(), 'DisplayName', 'gabarit');
        
            % Désactiver le mode de superposition
            hold(app.UIAxes, 'off');
            
            % Afficher les paramètres dans app.UITable3
            app.UITable3.Data = gabarit.Parametres_gabarits;

            % Metre à jour le support fréquentiel de l'affichage
            app.frquenceminEditField.Value = f_min;
            app.frquencemaxEditField.Value = f_max;
            app.pasEditField.Value = pas;          
        end

        % Button pushed function: CalcButton_2
        function CalcButton_2Pushed(app, event)
            % Obtenir les données des UITables depuis votre application
            configuration = app.UITable.Data;
            configuration_externe = app.UITable2.Data;
        
            % Créer la structure constructeur
            constructeur = struct('Configuration', configuration, 'Configuration_externe', configuration_externe);

            % Créer le vecteur support en fréquence
            f_min = app.frquenceminEditField.Value;
            f_max = app.frquencemaxEditField.Value;
            pas = app.pasEditField.Value; 
            w_vect = linspace(f_min,f_max,floor((f_max-f_min)/pas) + 1)*(2*pi);
       
            % Instancier un objet multiQWL avec la structure constructeur
            QWL = multiQWL(constructeur,w_vect,app.Air);
            disp(QWL);
            % Activer le mode de superposition
            hold(app.UIAxes, 'on');
        
            % Trouver tous les tracés actuels sur l'axe
            all_lines = findobj(app.UIAxes, 'Type', 'line');
        
            % Parcourir tous les tracés actuels
            for i = 1:numel(all_lines)
                current_line = all_lines(i);
                
                % Vérifier si le tracé actuel est nommé 'configuration'
                if strcmp(get(current_line, 'DisplayName'), 'configuration')
                    % Le tracé actuel est nommé 'gabarit', le supprimer
                    delete(current_line);
                end
            end
        
            % Afficher le nouveau tracé du gabarit dans app.UIAxes
            plot(app.UIAxes, w_vect/(2*pi), QWL.alpha(), 'DisplayName', 'configuration');
        
            % Désactiver le mode de superposition
            hold(app.UIAxes, 'off');

            % Metre à jour le support fréquentiel de l'affichage
            app.frquenceminEditField.Value = f_min;
            app.frquencemaxEditField.Value = f_max;
            app.pasEditField.Value = pas;    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FichiersMenu = uimenu(app.UIFigure);
            app.FichiersMenu.Text = 'Fichiers';
            app.chargerconfigurationMenu = uimenu(app.FichiersMenu);
            app.chargerconfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @chargerconfigurationMenuSelected, true);
            app.chargerconfigurationMenu.Text = 'charger configuration';
            app.enregistrerconfigurationMenu = uimenu(app.FichiersMenu);
            app.enregistrerconfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @enregistrerconfigurationMenuSelected, true);
            app.enregistrerconfigurationMenu.Text = 'enregistrer configuration';
            app.chargergabaritMenu = uimenu(app.FichiersMenu);
            app.chargergabaritMenu.MenuSelectedFcn = createCallbackFcn(app, @chargergabaritMenuSelected, true);
            app.chargergabaritMenu.Text = 'charger gabarit';
            app.enregistrergabaritMenu = uimenu(app.FichiersMenu);
            app.enregistrergabaritMenu.MenuSelectedFcn = createCallbackFcn(app, @enregistrergabaritMenuSelected, true);
            app.enregistrergabaritMenu.Text = 'enregistrer gabarit';
            app.ActionsMenu = uimenu(app.UIFigure);
            app.ActionsMenu.Text = 'Actions';
            app.AjouterunQWLMenu = uimenu(app.ActionsMenu);
            app.AjouterunQWLMenu.MenuSelectedFcn = createCallbackFcn(app, @AjouterunQWLMenuSelected, true);
            app.AjouterunQWLMenu.Text = 'Ajouter un QWL';
            app.SupprimerunQWLMenu = uimenu(app.ActionsMenu);
            app.SupprimerunQWLMenu.MenuSelectedFcn = createCallbackFcn(app, @SupprimerunQWLMenuSelected, true);
            app.SupprimerunQWLMenu.Text = 'Supprimer un QWL';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UITable3 = uitable(app.GridLayout2);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.CalcButton = uibutton(app.GridLayout3, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calc';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.UIAxes = uiaxes(app.GridLayout8);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.frquencemaxEditFieldLabel = uilabel(app.GridLayout9);
            app.frquencemaxEditFieldLabel.Text = 'fréquence max';
            app.frquencemaxEditField = uieditfield(app.GridLayout9, 'numeric');
            app.frquencemaxEditField.Value = 1000;
            app.pasEditFieldLabel = uilabel(app.GridLayout9);
            app.pasEditFieldLabel.Text = 'pas';
            app.pasEditField = uieditfield(app.GridLayout9, 'numeric');
            app.pasEditField.Value = 1;
            app.frquenceminEditFieldLabel = uilabel(app.GridLayout9);
            app.frquenceminEditFieldLabel.Text = 'fréquence min';
            app.frquenceminEditField = uieditfield(app.GridLayout9, 'numeric');
            app.frquenceminEditField.Value = 1;
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout12);
            app.Button_3 = uibutton(app.GridLayout5, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.GridLayout5, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.CalcButton_2 = uibutton(app.GridLayout5, 'push');
            app.CalcButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcButton_2Pushed, true);
            app.CalcButton_2.Text = 'Calc';
            app.GridLayout11 = uigridlayout(app.GridLayout12);
            app.TabGroup = uitabgroup(app.GridLayout11);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.UITable = uitable(app.ConfigurationTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ConfigurationexterneTab = uitab(app.TabGroup);
            app.ConfigurationexterneTab.Title = 'Configuration externe';
            app.UITable2 = uitable(app.ConfigurationexterneTab);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.ListBoxLabel = uilabel(app.GridLayout);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {'Item 1', 'Item 5', 'Item 4', 'Item 2', 'Item 3'};
            app.ListBox.Value = 'Item 4';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimisation_multi_QWL_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end